classdef App_automatische_Analyse_eines_Regelkreises < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        TabGroup_2                 matlab.ui.container.TabGroup
        StabilityTab_2             matlab.ui.container.Tab
        nyquist_UIAxes2            matlab.ui.control.UIAxes
        pzplotUIAxes               matlab.ui.control.UIAxes
        ToughnessTab_2             matlab.ui.container.Tab
        Bandwidth_UIAxes           matlab.ui.control.UIAxes
        Bode_UIAxes                matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        StabilityTab               matlab.ui.container.Tab
        controlloopstabilityLabel  matlab.ui.control.Label
        InfomationofTFsLabel       matlab.ui.control.Label
        pazcancel_label            matlab.ui.control.Label
        PolesandZeroesLabel        matlab.ui.control.Label
        CharacteristicequationsofthecontrolloopLabel  matlab.ui.control.Label
        KS_InfoUIAxes              matlab.ui.control.UIAxes
        GS_InfoUIAxes              matlab.ui.control.UIAxes
        S_InfoUIAxes               matlab.ui.control.UIAxes
        T_InfoUIAxes               matlab.ui.control.UIAxes
        L_InfoUIAxes               matlab.ui.control.UIAxes
        K_InfoUIAxes               matlab.ui.control.UIAxes
        G_InfoUIAxes               matlab.ui.control.UIAxes
        poles_and_zeroes_K_UIAxes  matlab.ui.control.UIAxes
        poles_and_zeroes_G_UIAxes  matlab.ui.control.UIAxes
        CharForm_UIAxes            matlab.ui.control.UIAxes
        ToughnessTab               matlab.ui.container.Tab
        testaxis                   matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        AnalyseButton              matlab.ui.control.Button
        Updated2Button             matlab.ui.control.Button
        Updated1Button             matlab.ui.control.Button
        UpdaterButton              matlab.ui.control.Button
        UpdateKButton              matlab.ui.control.Button
        UpdateGButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        d_2_UIAxes                 matlab.ui.control.UIAxes
        d_1_UIAxes                 matlab.ui.control.UIAxes
        r_UIAxes                   matlab.ui.control.UIAxes
        K_UIAxes                   matlab.ui.control.UIAxes
        G_UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
    end
    
    methods (Access = private)
function displayTransferFunctionInApp(app, G, label)

    % Extract numerator and denominator coefficients
    [num, den] = tfdata(G, 'v');
    
    % Convert num and den to latex
    num_str = polyToString(num);
    den_str = polyToString(den);
    
    % Combine numerator and denominator into LaTeX format
    formula = ['$$' label '(s) = \frac{' num_str '}{' den_str '}$$'];
    
    % Find the appropriate UIAxes based on the label
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    
    % Display the formula in the app using LaTeX interpreter
    cla(uiAxesHandle);
    text(uiAxesHandle, 0.5, 0.5, formula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
    
end

        
function displayPlaceholder(app, label, variable)

    % Placeholder formula to be displayed
    placeholderFormula = ['$$' label '(' variable ') = ???$$'];
    
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);

    % Display the placeholder formula in the app using LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
        
function displayTimeFunction(app, target_value, label)

  syms t s;
    
    % Check if the target_value is a function handle or a constant
    if isa(target_value, 'function_handle')
        % Handle time-dependent function
        timeFormula = target_value(t);
        latexTimeStr = latex(timeFormula);
        
        % Convert time function to transfer function (Laplace Transform)
        transferFormula = laplace(timeFormula, t, s);

        appName = [label '_s'];
        appHandle = app.(appName);

        appHandle= transferFormula;
        latexTransferStr = latex(transferFormula);
    else
        % Handle constant value
        latexTimeStr = num2str(target_value);
        
        % Convert constant to transfer function
        transferFormula = target_value;
        latexTransferStr = num2str(transferFormula);
    end
    
    % Combine label and LaTeX formula
    combinedFormula = ['$$' label '(t) = ' latexTimeStr ' \quad \Rightarrow \quad ' label '(s) = ' latexTransferStr '$$'];

    % Find the appropriate UIAxes based on the label
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
	cla(uiAxesHandle);


    % Display the combined formula in the app using LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, combinedFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');

end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];

    cla(app.CharForm_UIAxes);

    % Display in the specified axes
    text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end
     
        
function plot_poles(app)

    pzplot(app.pzplotUIAxes,app.G, app.K);
    
end

        
function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
     if control_loop_stability(app.G,app.K,app.S) == true  
        message = 'The control loop is stable';
    else
        message = 'The control loop is unstable';
     end
    % Display the message in the label
    app.controlloopstabilityLabel.Text = message;
            
        end
        

 function plot_TS_bandwidth(app, T, S)
    % Get magnitude response
    [mag_T, w_T] = bode(T);
    [mag_S, w_S] = bode(S);
    
    % Find bandwidth of T (where |T| crosses -3 dB)
    mag_T_dB = 20 * log10(mag_T(:));
    idx = find(mag_T_dB <= -3, 1);
    bandwidth_T = w_T(idx);
    
    % Set up the axis for displaying the plots
    uiAxesHandle = app.Bandwidth_UIAxes;
    cla(uiAxesHandle); % Clear existing plots

    % Plot Bode diagram for T and S
    h = bodeplot(uiAxesHandle, T, S);
    hold(uiAxesHandle, 'on');
    
    % Customize the plot options to disable phase plot
    opts = getoptions(h);
    opts.PhaseVisible = 'off';
    opts.FreqUnits = 'Hz';
    opts.Grid = 'on';
    opts.XLabel.Interpreter = 'latex';
    opts.YLabel.Interpreter = 'latex';
    opts.Title.Interpreter = 'latex';
    setoptions(h, opts);
    
    % Plot bandwidth dot on |T|
    plot(uiAxesHandle, bandwidth_T, -3, 'ko', 'MarkerFaceColor', 'k', 'DisplayName', 'Bandwidth');
    
    % Add horizontal line at -3 dB
    yline(uiAxesHandle, -3, '--k', 'LabelHorizontalAlignment', 'left', 'DisplayName', '-3 dB');

    % Set y-axis limits and ticks
    ylim(uiAxesHandle, [-40, 10]); % Example limits, adjust as needed
    yticks(uiAxesHandle, -40:1:10); % Set y-axis steps to 1 dB

    % Add labels and title
    xlabel(uiAxesHandle, 'Frequency (Hz)');
    ylabel(uiAxesHandle, 'Magnitude (dB)');
    title(uiAxesHandle, 'Bode Magnitude Diagrams of |T| and |S| with Bandwidth');
    legend(uiAxesHandle, '|T|', '|S|', 'Bandwidth', '-3 dB', 'Location', 'best');
    grid(uiAxesHandle, 'on');
    
    hold(uiAxesHandle, 'off');
end


function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Convert gain margin to dB
    Gm_dB = 20 * log10(Gm);
    
    % Set up the axis for displaying the Bode plot
    uiAxesHandle = app.Bode_UIAxes;
    cla(uiAxesHandle); % Clear existing plots
    
    % Plot the Bode diagram using bodeplot with options to show margins
    h = bodeplot(uiAxesHandle, L);
    setoptions(h, 'FreqUnits', 'Hz', 'Grid', 'on');
    
    % Add annotations within the axis
    annotationStr = {
        ['Gain Margin: ', num2str(Gm_dB, '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(uiAxesHandle, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 10, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

    end
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            displayPlaceholder(app, 'G', 's')
            displayPlaceholder(app, 'K', 's')
            displayPlaceholder(app, 'r', 't')
            displayPlaceholder(app, 'd_1', 't')
            displayPlaceholder(app, 'd_2', 't')
        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

    % Get the button text as the function label
    label = 'G'; 
    
    % Get the transfer function from the user
    app.G = get_user_transfer_function();
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.G)
        displayTransferFunctionInApp(app, app.G, label);
    end

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            % Get the button text as the function label
            label = 'K';

            app.K = get_user_controller();

    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
    end
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            % Get the button text as the function label
            label = 'r';

            app.r = get_target_value();

            displayTimeFunction(app, app.r, label)
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
            label = 'd_1';

            app.d_1 = get_disturbance_input();

            displayTimeFunction(app, app.d_1, label)
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
            label = 'd_2';

            app.d_2 = get_disturbance_input();

            displayTimeFunction(app, app.d_2, label)
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
           
            [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

            app.CharacteristicequationsofthecontrolloopLabel.Visible = 'on';


            app.char_eq = get_characteristic_equation(app, app.G, app.K);

            plot_poles(app);

            nyquist(app.nyquist_UIAxes2, app.L);


            generate_latex_string(app, app.G, 'G');
            generate_latex_string(app, app.K, 'K');

            app.PolesandZeroesLabel.Visible = 'on';

            checkAndDisplayUnstableCancellation(app)

            app.InfomationofTFsLabel.Visible = 'on';
            

            displayTransferFunctionInfo(app, app.G, 'G');
            displayTransferFunctionInfo(app, app.K, 'K');
            displayTransferFunctionInfo(app, app.L, 'L');
            displayTransferFunctionInfo(app, app.T, 'T');
            displayTransferFunctionInfo(app, app.GS, 'GS');
            displayTransferFunctionInfo(app, app.KS, 'KS');
            displayTransferFunctionInfo(app, app.S, 'S');

            controlloopstable(app);

            plot_TS_bandwidth(app, app.T, app.S)

            plot_bode_margins(app, app.L)

            plot_stability_table(app, app.L)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standardregelkreis.png');
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyseButton = uibutton(app.Panel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StabilityTab = uitab(app.TabGroup);
            app.StabilityTab.Title = 'Stability';
            app.CharForm_UIAxes = uiaxes(app.StabilityTab);
            app.CharForm_UIAxes.XTick = [];
            app.CharForm_UIAxes.YTick = [];
            app.CharForm_UIAxes.ZTick = [];
            app.CharForm_UIAxes.Visible = 'off';
            app.poles_and_zeroes_G_UIAxes = uiaxes(app.StabilityTab);
            app.poles_and_zeroes_G_UIAxes.Visible = 'off';
            app.poles_and_zeroes_K_UIAxes = uiaxes(app.StabilityTab);
            app.poles_and_zeroes_K_UIAxes.Visible = 'off';
            app.G_InfoUIAxes = uiaxes(app.StabilityTab);
            app.G_InfoUIAxes.XTick = [];
            app.G_InfoUIAxes.YTick = [];
            app.G_InfoUIAxes.ZTick = [];
            app.G_InfoUIAxes.Visible = 'off';
            app.K_InfoUIAxes = uiaxes(app.StabilityTab);
            app.K_InfoUIAxes.XTick = [];
            app.K_InfoUIAxes.YTick = [];
            app.K_InfoUIAxes.ZTick = [];
            app.K_InfoUIAxes.Visible = 'off';
            app.L_InfoUIAxes = uiaxes(app.StabilityTab);
            app.L_InfoUIAxes.XTick = [];
            app.L_InfoUIAxes.YTick = [];
            app.L_InfoUIAxes.ZTick = [];
            app.L_InfoUIAxes.Visible = 'off';
            app.T_InfoUIAxes = uiaxes(app.StabilityTab);
            app.T_InfoUIAxes.XTick = [];
            app.T_InfoUIAxes.YTick = [];
            app.T_InfoUIAxes.ZTick = [];
            app.T_InfoUIAxes.Visible = 'off';
            app.S_InfoUIAxes = uiaxes(app.StabilityTab);
            app.S_InfoUIAxes.XTick = [];
            app.S_InfoUIAxes.YTick = [];
            app.S_InfoUIAxes.ZTick = [];
            app.S_InfoUIAxes.Visible = 'off';
            app.GS_InfoUIAxes = uiaxes(app.StabilityTab);
            app.GS_InfoUIAxes.XTick = [];
            app.GS_InfoUIAxes.YTick = [];
            app.GS_InfoUIAxes.ZTick = [];
            app.GS_InfoUIAxes.Visible = 'off';
            app.KS_InfoUIAxes = uiaxes(app.StabilityTab);
            app.KS_InfoUIAxes.XTick = [];
            app.KS_InfoUIAxes.YTick = [];
            app.KS_InfoUIAxes.ZTick = [];
            app.KS_InfoUIAxes.Visible = 'off';
            app.CharacteristicequationsofthecontrolloopLabel = uilabel(app.StabilityTab);
            app.CharacteristicequationsofthecontrolloopLabel.Visible = 'off';
            app.CharacteristicequationsofthecontrolloopLabel.Text = 'Characteristic equations of the control loop';
            app.PolesandZeroesLabel = uilabel(app.StabilityTab);
            app.PolesandZeroesLabel.Visible = 'off';
            app.PolesandZeroesLabel.Text = 'Poles and Zeroes';
            app.pazcancel_label = uilabel(app.StabilityTab);
            app.pazcancel_label.Text = '';
            app.InfomationofTFsLabel = uilabel(app.StabilityTab);
            app.InfomationofTFsLabel.Visible = 'off';
            app.InfomationofTFsLabel.Text = 'Infomation of TFs';
            app.controlloopstabilityLabel = uilabel(app.StabilityTab);
            app.controlloopstabilityLabel.Text = '';
            app.ToughnessTab = uitab(app.TabGroup);
            app.ToughnessTab.Title = 'Toughness';
            app.testaxis = uiaxes(app.ToughnessTab);
            title(app.testaxis, 'Title')
            xlabel(app.testaxis, 'X')
            ylabel(app.testaxis, 'Y')
            zlabel(app.testaxis, 'Z')
            app.testaxis.Visible = 'off';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.StabilityTab_2 = uitab(app.TabGroup_2);
            app.StabilityTab_2.Title = 'Stability';
            app.pzplotUIAxes = uiaxes(app.StabilityTab_2);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.nyquist_UIAxes2 = uiaxes(app.StabilityTab_2);
            title(app.nyquist_UIAxes2, 'Title')
            xlabel(app.nyquist_UIAxes2, 'X')
            ylabel(app.nyquist_UIAxes2, 'Y')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.ToughnessTab_2 = uitab(app.TabGroup_2);
            app.ToughnessTab_2.Title = 'Toughness';
            app.Bode_UIAxes = uiaxes(app.ToughnessTab_2);
            title(app.Bode_UIAxes, 'Title')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.Bandwidth_UIAxes = uiaxes(app.ToughnessTab_2);
            title(app.Bandwidth_UIAxes, 'Title')
            xlabel(app.Bandwidth_UIAxes, 'X')
            ylabel(app.Bandwidth_UIAxes, 'Y')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end