classdef App_automatische_Analyse_eines_Regelkreises_Kopie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup_2                    matlab.ui.container.TabGroup
        ZeroPoleTab                   matlab.ui.container.Tab
        HTMLPZ                        matlab.ui.control.HTML
        PZButton                      matlab.ui.container.ButtonGroup
        SsButton                      matlab.ui.control.RadioButton
        TsButton                      matlab.ui.control.RadioButton
        KSsButton                     matlab.ui.control.RadioButton
        GSsButton                     matlab.ui.control.RadioButton
        LsButton                      matlab.ui.control.RadioButton
        KsButton                      matlab.ui.control.RadioButton
        GsButton                      matlab.ui.control.RadioButton
        pzplotUIAxes                  matlab.ui.control.UIAxes
        NyquistTab                    matlab.ui.container.Tab
        MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel  matlab.ui.control.Label
        HTMLNyquistInfo               matlab.ui.control.HTML
        nyquist_UIAxes2               matlab.ui.control.UIAxes
        BodeTab                       matlab.ui.container.Tab
        Bode_UIAxes                   matlab.ui.control.UIAxes
        BandwidthTab                  matlab.ui.container.Tab
        Bandwidth_UIAxes              matlab.ui.control.UIAxes
        StepResponsesTab              matlab.ui.container.Tab
        KPButton_2                    matlab.ui.container.ButtonGroup
        SsButton_2                    matlab.ui.control.RadioButton
        TsButton_2                    matlab.ui.control.RadioButton
        KSsButton_2                   matlab.ui.control.RadioButton
        GSsButton_2                   matlab.ui.control.RadioButton
        LsButton_2                    matlab.ui.control.RadioButton
        KsButton_2                    matlab.ui.control.RadioButton
        GsButton_2                    matlab.ui.control.RadioButton
        HTMLkeyparamTable_3           matlab.ui.control.HTML
        Response_UIAxes_2             matlab.ui.control.UIAxes
        RobustnessTab                 matlab.ui.container.Tab
        RobustnessTabGroup            matlab.ui.container.TabGroup
        SensitivityTab                matlab.ui.container.Tab
        SensitivityPointsEdit         matlab.ui.control.NumericEditField
        NumberofPointsLabel           matlab.ui.control.Label
        SensitivityHTML               matlab.ui.control.HTML
        RunSensitivityButton          matlab.ui.control.Button
        SensitivityMetricDropdown     matlab.ui.control.DropDown
        PerformanceMetricLabel        matlab.ui.control.Label
        SensitivityRangeEdit          matlab.ui.control.NumericEditField
        VariationRangeLabel           matlab.ui.control.Label
        SensitivityParamDropdown      matlab.ui.control.DropDown
        SelectParameterLabel          matlab.ui.control.Label
        SensitivityUIAxes             matlab.ui.control.UIAxes
        MonteCarloTab                 matlab.ui.container.Tab
        MonteCarloHTML                matlab.ui.control.HTML
        RunMonteCarloButton           matlab.ui.control.Button
        MonteCarloMetricDropdown      matlab.ui.control.DropDown
        PerformanceMetricLabel_2      matlab.ui.control.Label
        NumSimulationsEdit            matlab.ui.control.NumericEditField
        NumberofSimulationsLabel      matlab.ui.control.Label
        KdParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel_3                 matlab.ui.control.Label
        KdParam1Edit                  matlab.ui.control.NumericEditField
        KdDistDropdown                matlab.ui.control.DropDown
        MeanMinLabel_3                matlab.ui.control.Label
        DistributionLabel_3           matlab.ui.control.Label
        DerivativeGainKdLabel         matlab.ui.control.Label
        KiParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel_2                 matlab.ui.control.Label
        KiParam1Edit                  matlab.ui.control.NumericEditField
        KiDistDropdown                matlab.ui.control.DropDown
        MeanMinLabel_2                matlab.ui.control.Label
        DistributionLabel_2           matlab.ui.control.Label
        IntegralGainKiLabel           matlab.ui.control.Label
        KpParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel                   matlab.ui.control.Label
        KpParam1Edit                  matlab.ui.control.NumericEditField
        MeanMinLabel                  matlab.ui.control.Label
        KpDistDropdown                matlab.ui.control.DropDown
        DistributionLabel             matlab.ui.control.Label
        ControllerGainKpLabel         matlab.ui.control.Label
        MonteCarloResponseUIAxes      matlab.ui.control.UIAxes
        MonteCarloHistUIAxes          matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ControlLoopTab                matlab.ui.container.Tab
        HtmlTable                     matlab.ui.control.HTML
        controlloopstabilityLabel     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ResponseTab                   matlab.ui.container.Tab
        HTMLkeyparamTable             matlab.ui.control.HTML
        ButtonGroup                   matlab.ui.container.ButtonGroup
        r_d12Button                   matlab.ui.control.RadioButton
        SetpointButton                matlab.ui.control.RadioButton
        StepButton                    matlab.ui.control.RadioButton
        Response_UIAxes               matlab.ui.control.UIAxes
        StationaryValuesTab           matlab.ui.container.Tab
        HTMLkeyparamTable_2           matlab.ui.control.HTML
        JumpLabel                     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SetpointandDisturbanceButton  matlab.ui.control.RadioButton
        OutputdisturbanceButton       matlab.ui.control.RadioButton
        SetButton                     matlab.ui.control.RadioButton
        Controllanderrorplo           matlab.ui.control.UIAxes
        StateSpaceTab                 matlab.ui.container.Tab
        StateSpaceControlPanel        matlab.ui.container.Panel
        ConvertToTransferFunctionButton_2  matlab.ui.control.Button
        MIMOInputAndOutputSelectionLabel  matlab.ui.control.Label
        ClearStateSpaceButton         matlab.ui.control.Button
        LoadStateSpaceButton          matlab.ui.control.Button
        SaveStateSpaceButton          matlab.ui.control.Button
        OutputIndexInput              matlab.ui.control.NumericEditField
        OutputIndexLabel              matlab.ui.control.Label
        InputIndexLabel               matlab.ui.control.Label
        InputIndexInput               matlab.ui.control.NumericEditField
        StateSpaceVisualizationPanel  matlab.ui.container.Panel
        AnalysisTypeDropdown          matlab.ui.control.DropDown
        AnalysisTypeLabel             matlab.ui.control.Label
        RunAnalysisButton             matlab.ui.control.Button
        StateSpaceVisHTML             matlab.ui.control.HTML
        StateSpaceAxes                matlab.ui.control.UIAxes
        StateSpaceInfoPanel           matlab.ui.container.Panel
        StateSpaceInfoHTML            matlab.ui.control.HTML
        StateSpaceTypeTabGroup        matlab.ui.container.TabGroup
        LinearModelTab                matlab.ui.container.Tab
        LinearHelpButton              matlab.ui.control.Button
        CreateLinearStateSpaceButton  matlab.ui.control.Button
        LinearDInput                  matlab.ui.control.TextArea
        LinearDLabel                  matlab.ui.control.Label
        LinearCInput                  matlab.ui.control.TextArea
        LinearCLabel                  matlab.ui.control.Label
        LinearBInput                  matlab.ui.control.TextArea
        LinearBLabel                  matlab.ui.control.Label
        LinearAInput                  matlab.ui.control.TextArea
        LinearALabel                  matlab.ui.control.Label
        NonlinearModelTab             matlab.ui.container.Tab
        NonlinearHelpButton           matlab.ui.control.Button
        CreateNonlinearStateSpaceButton  matlab.ui.control.Button
        LinearizationPointInput       matlab.ui.control.EditField
        LinearizationPointLabel       matlab.ui.control.Label
        NonlinearStateEqInput         matlab.ui.control.EditField
        NonlinearOutputEqLabel_2      matlab.ui.control.Label
        NonlinearOutputEqInput        matlab.ui.control.EditField
        NonlinearOutputEqLabel        matlab.ui.control.Label
        NonlinearInputVarsInput       matlab.ui.control.EditField
        NonlinearInputVarsLabel       matlab.ui.control.Label
        NonlinearStateVarsInput       matlab.ui.control.EditField
        NonlinearStateVarsLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        Updated2Button                matlab.ui.control.Button
        Updated1Button                matlab.ui.control.Button
        UpdaterButton                 matlab.ui.control.Button
        UpdateKButton                 matlab.ui.control.Button
        UpdateGButton                 matlab.ui.control.Button
        FunctionsHTML                 matlab.ui.control.HTML
        K_UIAxes                      matlab.ui.control.UIAxes
        r_UIAxes                      matlab.ui.control.UIAxes
        G_UIAxes                      matlab.ui.control.UIAxes
        d_2_UIAxes                    matlab.ui.control.UIAxes
        d_1_UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simulationTime
        AnalysisMode
        StateFeedbackGain
        StateSpaceModel % Linear state space model
        LinearizedModel % Linearized model for nonlinear systems
        IsNonlinearModel % Flag indicating if current model is nonlinear
        NonlinearStateVars % State variables for nonlinear model
        NonlinearInputVars % Input variables for nonlinear model
        NonlinearStateEqs % State equations for nonlinear model
        NonlinearOutputEqs % Output equations for nonlinear model
        LinearizationPoint % Linearization point for nonlinear systems
        nyquist_lReal      % Real part of Nyquist curve points
        nyquist_lImag      % Imaginary part of Nyquist curve points
        nyquist_omega      % Frequency values for Nyquist curve
        nyquist_angles     % Calculated angles from critical point
        required_delta_phi % Required delta phi for stability
        nyquistSlider      % Slider UI component
        nyquistSliderPanel % Panel containing slider
        r_latex_string
        d1_latex_string
        d2_latex_string
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        t
        r_num_sections
        r_start_time
        r_end_time
        d1_num_sections
        d2_num_sections
        d1_start_time
        d1_end_time
        d1_time_steps
        d1_time_vector
        d2_start_time
        d2_end_time
        d2_time_steps
        d2_time_vector
        timeDescription
        cansysjump
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
        G_poles
        G_zeroes
        K_poles
        K_zeroes
        L_poles
        L_zeroes
        T_poles
        T_zeroes
        S_poles
        S_zeroes
        GS_poles
        GS_zeroes
        KS_poles
        KS_zeroes
    end
    
    methods (Access = private)

 function displayTransferFunctionInApp(app, tf_obj, label)
    % DISPLAYTRANSFUNCTIONINAPP - Display transfer function in app with improved error handling
    % 
    % Parameters:
    %   app - App object
    %   tf_obj - Transfer function to display
    %   label - 'G' or 'K' to specify which axes to use
    
    try
        % Debug logging to track progress
        disp(['Displaying transfer function ' label]);
        
        % Select the appropriate axes based on the label
        switch upper(label)
            case 'G'
                ax = app.G_UIAxes;
            case 'K'
                ax = app.K_UIAxes;
            case 'R'
                ax = app.r_UIAxes;
            case 'D_1'
                ax = app.d_1_UIAxes;
            case 'D_2'
                ax = app.d_2_UIAxes;
            otherwise
                error(['Unknown label: ' label]);
        end
        
        % Make sure the axes is visible
        ax.Visible = 'on';
        
        % Clear the axes
        cla(ax);
        
        % Get the numerator and denominator
        [num, den] = tfdata(tf_obj, 'v');
        
        % Debug values
        disp(['Numerator: ' mat2str(num)]);
        disp(['Denominator: ' mat2str(den)]);
        
        % Determine variable ('s' or 'z')
        if tf_obj.Ts > 0
            var_symbol = 'z';
        else
            var_symbol = 's';
        end
        
        % Remove leading zeros and format polynomials
        num = removeLeadingZeros(num);
        den = removeLeadingZeros(den);
        
        % Convert to LaTeX string
        num_str = poly2str(num, var_symbol);
        den_str = poly2str(den, var_symbol);
        
        % Format as LaTeX
        formulaLaTeX = ['$' label '(' var_symbol ') = \frac{' num_str '}{' den_str '}$'];
        
        % Display with LaTeX interpreter
        text(ax, 0.5, 0.5, formulaLaTeX, ...
            'Interpreter', 'latex', ...
            'HorizontalAlignment', 'center', ...
            'VerticalAlignment', 'middle', ...
            'FontSize', 16);
        
        % Format the axes
        axis(ax, 'off');
        ax.XLim = [0 1];
        ax.YLim = [0 1];
        
        disp(['Successfully displayed ' label ' transfer function']);
        
    catch ME
        % Enhanced error handling
        disp(['Error in displayTransferFunctionInApp: ' ME.message]);
        disp(['Stack trace: ' getReport(ME, 'extended')]);
        
        % Try to recover with a very basic display
        try
            if exist('ax', 'var') && ~isempty(ax)
                cla(ax);
                ax.Visible = 'on';
                axis(ax, 'off');
                
                % Show error text
                text(ax, 0.5, 0.5, ['Error displaying ' label '(' var_symbol ')'], ...
                    'HorizontalAlignment', 'center', ...
                    'Color', 'red', ...
                    'FontSize', 20);
                
                % Try to show a basic representation
                if isa(tf_obj, 'tf')
                    [num, den] = tfdata(tf_obj, 'v');
                    text(ax, 0.5, 0.3, ['Num: ' mat2str(num, 3)], ...
                        'HorizontalAlignment', 'center', 'FontSize', 10);
                    text(ax, 0.5, 0.2, ['Den: ' mat2str(den, 3)], ...
                        'HorizontalAlignment', 'center', 'FontSize', 10);
                end
            end
        catch
            % If even recovery fails, don't crash
        end
    end
end

function displayPlaceholder(app, label, variable)
    % DISPLAYPLACEHOLDER
    % ----------------
    % This function displays a placeholder formula when no actual function is defined.
    % The UI is improved with better text styling and centering.
    %
    % Inputs:
    %   app      - The app object containing the UI components
    %   label    - Function label (e.g., 'G', 'K', 'r', etc.)
    %   variable - Variable symbol (e.g., 's' or 't')

    % Create a visually appealing placeholder formula
    placeholderFormula = ['$$' label '(' variable ') = \textrm{not defined}$$'];
    
    % Get the target UIAxes handle
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    
    % Clear the axis and turn off axis lines
    cla(uiAxesHandle);
    axis(uiAxesHandle, 'off');
    
    % Display the placeholder formula with improved styling
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, ...
        'Interpreter', 'latex', 'FontSize', 16, ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'Color', [0.6 0.6 0.6]);  % Gray color to indicate placeholder status
end

function displayTimeFunction(app, target_value, num_sections, label, start_time, end_time)
    % DISPLAYTIMEFUNCTION
    % -----------------
    % Enhanced function to display time-domain functions with better formatting
    % and error handling. Supports continuous and piecewise functions.
    % Automatically converts decimal numbers to fractions for cleaner display.
    %
    % Inputs:
    %   app          - The app object containing UI components
    %   target_value - Function handle or cell array of function handles
    %   num_sections - Number of sections (1 for continuous)
    %   label        - Function label (e.g., 'r', 'd_1', etc.)
    %   start_time   - Vector of section start times
    %   end_time     - Vector of section end times
    
    try
        % Define symbolic variable t for LaTeX representation
        syms t;
        
        % Create the appropriate LaTeX property name based on the label
        latex_prop_name = '';
        switch label
            case 'r'
                latex_prop_name = 'r_latex_string';
            case 'd_1'
                latex_prop_name = 'd1_latex_string';
            case 'd_2'
                latex_prop_name = 'd2_latex_string';
        end
        
        % Handle continuous vs. piecewise cases
        if num_sections == 1
            % Continuous case
            if isa(target_value, 'function_handle')
                % Try to convert function to symbolic for better display
                try
                    % Try to extract the function definition from string
                    funcStr = func2str(target_value);
                    exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                    
                    if ~isempty(exprStr) && ~isempty(exprStr{1})
                        % Convert to symbolic expression
                        timeFormula = str2sym(exprStr{1}{1});
                        % Convert decimal coefficients to fractions
                        timeFormula = convertToFractions(timeFormula);
                        latexTimeStr = latex(timeFormula);
                    else
                        % Fallback: simple string representation
                        latexTimeStr = 'f(t)';
                    end
                catch ME
                    % If conversion fails, use a generic representation
                    disp(['Warning in formula conversion: ' ME.message]);
                    latexTimeStr = 'f(t)';
                end
            else
                % Constant value case
                if isreal(target_value) && mod(target_value, 1) ~= 0
                    % Convert decimal to fraction
                    [n, d] = rat(target_value, 1e-6);
                    if d > 1 && d <= 100  % Reasonable denominator limit
                        latexTimeStr = [num2str(n) '/' num2str(d)];
                    else
                        latexTimeStr = num2str(round(target_value, 5)); % round to 5 decimal digits
                    end
                else
                    latexTimeStr = num2str(target_value);
                end
            end
            
            % Create LaTeX formula
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        else
            % Piecewise case: use \left\{ and array environment
            latexTimeStr = ['\left\{ \begin{array}{ll}'];
            
            % Add each piecewise segment
            for i = 1:num_sections
                if isa(target_value{i}, 'function_handle')
                    % Try to convert function to symbolic for better display
                    try
                        % Try to extract the function definition from string
                        funcStr = func2str(target_value{i});
                        exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                        
                        if ~isempty(exprStr) && ~isempty(exprStr{1})
                            % Convert to symbolic expression
                            timePart = str2sym(exprStr{1}{1});
                            % Convert decimal coefficients to fractions
                            timePart = convertToFractions(timePart);
                            latexTimePart = latex(timePart);
                        else
                            % Fallback: simple string representation
                            latexTimePart = 'f(t)';
                        end
                    catch ME
                        % If conversion fails, use a generic representation
                        disp(['Warning in formula conversion: ' ME.message]);
                        latexTimePart = 'f(t)';
                    end
                else
                    % Constant value case
                    if isreal(target_value{i}) && mod(target_value{i}, 1) ~= 0
                        % Convert decimal to fraction
                        [n, d] = rat(target_value{i}, 1e-6);
                        if d > 1 && d <= 100  % Reasonable denominator limit
                            latexTimePart = [num2str(n) '/' num2str(d)];
                        else
                            latexTimePart = num2str(round(target_value{i}, 5));
                        end
                    else
                        latexTimePart = num2str(target_value{i});
                    end
                end
                
                % Convert time range boundaries to fractions if needed
                start_time_str = formatNumberAsFraction(start_time(i));
                end_time_str = formatNumberAsFraction(end_time(i));
                
                % Add time range condition and formatting
                latexTimeStr = [latexTimeStr, ' ', latexTimePart, ' & ', ...
                    start_time_str, ' \leq t \leq ', end_time_str, ' \\[5pt] '];
            end
            
            % Complete the piecewise function LaTeX
            latexTimeStr = [latexTimeStr, '\end{array}\right.'];
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        end
        
        % Store the LaTeX string representation in the appropriate property
        if ~isempty(latex_prop_name)
            app.(latex_prop_name) = combinedFormula;
        end
        
        % Get the target UIAxes component
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        axis(uiAxesHandle, 'off');
        
        % Display the formula with improved styling
        text(uiAxesHandle, 0.5, 0.5, combinedFormula, ...
            'Interpreter', 'latex', 'FontSize', 16, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    catch ME
        % Handle errors gracefully
        disp(['Error displaying time function: ' ME.message]);
        
        % Get the target UIAxes
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(t)'], ...
            'FontSize', 16, 'HorizontalAlignment', 'center', 'Color', 'red');
    end
end


function render_functions_table_structure(app)
    % RENDER_FUNCTIONS_TABLE_STRUCTURE
    % -------------------------------
    % Creates a two-column HTML table showing control system function names
    % with completely transparent background and dimensions of 660 x 830 pixels.
    % The expressions in the second column are centered.
    %
    % Inputs:
    %   app - App object
    
    % Calculate row heights for uniform distribution in the larger table
    header_height = 40;  % Height for header row
    
    % Calculate uniform row height for all data rows
    data_rows_count = 5; % G(s), K(s), r(t), d₁(t), d₂(t)
    row_height = floor((830 - header_height) / data_rows_count);
    
    % Create HTML with styling similar to render_html_table but with specific dimensions
    htmlContent = sprintf(['<html>'...
    '<head>'...
    '<style>'...
    'body { margin: 0; padding: 0; overflow: hidden; }'...
    'table { border-collapse: collapse; width: 660px; height: 830px; '...
    'font-family: Arial, sans-serif; table-layout: fixed; background-color: transparent; }'...
    'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 15px; }'...
    'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 14px; background-color: transparent; }'...
    'tr:hover { background-color: rgba(232, 244, 248, 0.7); }'... /* Semi-transparent hover effect */
    '.function-name { text-align: center; font-weight: bold; width: 120px; }'... /* Slightly wider column */
    '.function-expr { text-align: center; }'... /* Center the expression text */
    '.data-row td { height: %dpx; }'... % Uniform height for all data rows
    '</style>'...
    '</head>'...
    '<body>'...
    '<table>'...
    '<tr><th class="function-name">Function</th><th class="function-expr">Expression</th></tr>'...
    '<tr class="data-row"><td class="function-name">G(s)</td><td class="function-expr" id="g-expr"></td></tr>'...
    '<tr class="data-row"><td class="function-name">K(s)</td><td class="function-expr" id="k-expr"></td></tr>'...
    '<tr class="data-row"><td class="function-name">r(t)</td><td class="function-expr" id="r-expr"></td></tr>'...
    '<tr class="data-row"><td class="function-name">d₁(t)</td><td class="function-expr" id="d1-expr"></td></tr>'...
    '<tr class="data-row"><td class="function-name">d₂(t)</td><td class="function-expr" id="d2-expr"></td></tr>'...
    '</table>'...
    '</body>'...
    '</html>'], row_height);
    
    % Update the HTML component
    try
        app.FunctionsHTML.HTMLSource = htmlContent;
    catch
        % Create component if it doesn't exist
        app.FunctionsHTML = uihtml(app.Panel, 'HTMLSource', htmlContent);
        app.FunctionsHTML.Position = [0, 0, 660, 830]; % Updated dimensions to match requirements
    end
end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];


    % Display in the specified axes
    %text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end


function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
    % CONTROLLOOPSTABLE
    % ---------------
    % Enhanced stability check with more visual feedback
    
    try
        % Check stability
        if control_loop_stability(app.G, app.K) == true  
            message = 'The control loop is stable';
            color = [0.2 0.7 0.3]; % Green for stable
        else
            message = 'The control loop is unstable';
            color = [0.7 0.3 0.2]; % Red for unstable
        end
        
        % Update the label with improved styling
        app.controlloopstabilityLabel.Text = message;
        app.controlloopstabilityLabel.FontColor = color;
        app.controlloopstabilityLabel.FontWeight = 'bold';
        app.controlloopstabilityLabel.FontSize = 16;
    catch ME
        % Handle errors gracefully
        disp(['Error checking control loop stability: ' ME.message]);
        app.controlloopstabilityLabel.Text = 'Could not determine control loop stability';
        app.controlloopstabilityLabel.FontColor = [0.7 0.7 0.2]; % Yellow for warning
    end
end
        

function plot_TS_bandwidth(app, T, S)
    % Clear existing plots and disable legend
    cla(app.Bandwidth_UIAxes);
    legend(app.Bandwidth_UIAxes, 'off');
    
    % Calculate bandwidth of T
    try
        bandwidth_T = bandwidth(T);
        if isnan(bandwidth_T)
            disp('Warning: Standard bandwidth calculation failed, searching for -3dB bandwidth.');
            [mag, ~, freq] = bode(T);
            mag = squeeze(mag);
            bandwidth_idx = find(20*log10(mag) <= -3, 1, 'first');
            if isempty(bandwidth_idx)
                disp('No frequency crosses -3 dB. Using alternative approach.');
                % Alternative: search frequency range
                [mag, ~, freq] = bode(T, {0.01, 1000});
                mag = squeeze(mag);
                mag_db = 20*log10(mag);
                [peak_mag_db, peak_idx] = max(mag_db);
                cutoff_db = peak_mag_db - 3;
                bandwidth_idx = find(mag_db <= cutoff_db & freq > freq(peak_idx), 1, 'first');
                if ~isempty(bandwidth_idx)
                    bandwidth_T = freq(bandwidth_idx);
                else
                    bandwidth_T = NaN;
                    disp('Bandwidth could not be determined.');
                end
            else
                bandwidth_T = freq(bandwidth_idx);
            end
        end
    catch ME
        disp(['Error calculating bandwidth: ', ME.message]);
        bandwidth_T = NaN;
    end
    
    % Calculate frequency responses with better resolution
    try
        omega = logspace(-2, 2, 1000);
        
        [magT, ~, ~] = bode(T, omega);
        magT = squeeze(magT);
        magT_dB = 20 * log10(magT);
        
        [magS, ~, ~] = bode(S, omega);
        magS = squeeze(magS);
        magS_dB = 20 * log10(magS);
        
        % Plot T(s) (blue, solid)
        h1 = semilogx(app.Bandwidth_UIAxes, omega, magT_dB, 'b-', 'LineWidth', 2);
        hold(app.Bandwidth_UIAxes, 'on');
        
        % Plot S(s) (red, dashed)
        h2 = semilogx(app.Bandwidth_UIAxes, omega, magS_dB, 'r--', 'LineWidth', 1.5);
        
        % Add -3dB line (helpful for bandwidth determination)
        h3 = yline(app.Bandwidth_UIAxes, -3, 'k:', 'LineWidth', 1, 'Alpha', 0.7);
        
        % If bandwidth was calculated, mark it
        if ~isnan(bandwidth_T)
            % Get magnitude at bandwidth frequency
            [magAtBW, ~, ~] = bode(T, bandwidth_T);
            magAtBW_dB = 20 * log10(squeeze(magAtBW));
            
            % Draw vertical line at bandwidth frequency
            h4 = xline(app.Bandwidth_UIAxes, bandwidth_T, 'g-', 'LineWidth', 1.5);
            
            % Mark the bandwidth point on the T(s) curve
            h5 = plot(app.Bandwidth_UIAxes, bandwidth_T, magAtBW_dB, 'go', ...
                'MarkerSize', 10, 'MarkerFaceColor', 'g', 'LineWidth', 1.5);
            
            % Legend labels with bandwidth info
            legendLabels = {'T(s)', 'S(s)', '-3dB Line', ...
                ['Bandwidth: ', num2str(bandwidth_T, '%.3f'), ' rad/s'], ...
                ['Magnitude at BW: ', num2str(magAtBW_dB, '%.2f'), ' dB']};
            
            % Create legend
            legend(app.Bandwidth_UIAxes, [h1, h2, h3, h4, h5], legendLabels, 'Location', 'best');
        else
            % Legend without bandwidth info
            legend(app.Bandwidth_UIAxes, [h1, h2, h3], {'T(s)', 'S(s)', '-3dB Line'}, 'Location', 'best');
        end
        
        % Enhance the chart
        grid(app.Bandwidth_UIAxes, 'on');
        title(app.Bandwidth_UIAxes, 'Bandwidth Analysis');
        xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)');
        ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)');
        
        % Set chart limits for better visibility
        ylim(app.Bandwidth_UIAxes, [-40, 20]);
        
        hold(app.Bandwidth_UIAxes, 'off');
    catch ME
        disp(['Error plotting bandwidth: ', ME.message]);
        % Minimal error display in the chart
        text(app.Bandwidth_UIAxes, 0.5, 0.5, ['Error: ', ME.message], ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end

        
function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Clear the existing plots
    cla(app.Bode_UIAxes);
    
    opts = bodeoptions;
    opts.Title.FontSize = 12;
    opts.Title.String = 'Bode Plot of L(s)';
    opts.XLabel.FontSize = 12;
    opts.YLabel.FontSize = 12;
    opts.Grid = 'on';

    % Plot the Bode diagram using bodeplot
    bodeplot(app.Bode_UIAxes, L, opts);
    
    % Add annotations within the axis for margins
    annotationStr = {
        ['Gain Margin: ', num2str(20*log10(Gm), '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(app.Bode_UIAxes, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 12, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

function render_html_table(app)
    % RENDER_HTML_TABLE
    % ---------------
    % Enhanced HTML table rendering with improved styling and better organization
    
    % Generate string representations of transfer functions
    G_table = createHtmlStringfortransfer(app.G, 'G'); 
    K_table = createHtmlStringfortransfer(app.K, 'K'); 
    L_table = createHtmlStringfortransfer(app.L, 'L'); 
    T_table = createHtmlStringfortransfer(app.T, 'T'); 
    S_table = createHtmlStringfortransfer(app.S, 'S'); 
    GS_table = createHtmlStringfortransfer(app.GS, 'GS'); 
    KS_table = createHtmlStringfortransfer(app.KS, 'KS'); 
    
    % Check conditions for each transfer function
    [G_stable, G_proper, G_sternly] = check_tf_conditions(app.G);
    [K_stable, K_proper, K_sternly] = check_tf_conditions(app.K);
    [L_stable, L_proper, L_sternly] = check_tf_conditions(app.L);
    [T_stable, T_proper, T_sternly] = check_tf_conditions(app.T);
    [S_stable, S_proper, S_sternly] = check_tf_conditions(app.S);
    [GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
    [KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);
    
    % Create an enhanced HTML table with improved styling
    htmlContent = sprintf(['<html>'...
        '<head>'...
        '<style>'...
        'table { border-collapse: collapse; width: 100%%; font-family: Arial, sans-serif; }'...
        'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }'...
        'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }'...
        'tr:nth-child(odd) { background-color: #F9F9F9; }'...
        'tr:hover { background-color: #E8F4F8; }'...
        '.check { color: #2ECC71; font-size: 18px; }'... % Green checkmark
        '.cross { color: #E74C3C; font-size: 18px; }'... % Red cross
        '.tf-cell { text-align: left; padding-left: 15px; }'...
        '</style>'...
        '</head>'...
        '<body>'...
        '<table>'...
        '<tr><th>Transfer Function</th><th>Stable</th><th>Proper</th><th>Strictly Proper</th></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '</table>'...
        '</body>'...
        '</html>'], ...
        G_table,  boolToSymbol(G_stable),  boolToSymbol(G_proper),  boolToSymbol(G_sternly), ...
        K_table,  boolToSymbol(K_stable),  boolToSymbol(K_proper),  boolToSymbol(K_sternly), ...
        L_table,  boolToSymbol(L_stable),  boolToSymbol(L_proper),  boolToSymbol(L_sternly), ...
        T_table,  boolToSymbol(T_stable),  boolToSymbol(T_proper),  boolToSymbol(T_sternly), ...
        S_table,  boolToSymbol(S_stable),  boolToSymbol(S_proper),  boolToSymbol(S_sternly), ...
        GS_table, boolToSymbol(GS_stable), boolToSymbol(GS_proper), boolToSymbol(GS_sternly), ...
        KS_table, boolToSymbol(KS_stable), boolToSymbol(KS_proper), boolToSymbol(KS_sternly));
    
    % Update the HTML component
    try
        app.HtmlTable.HTMLSource = htmlContent;
    catch
        % Create component if it doesn't exist
        app.HtmlTable = uihtml(app.Panel, 'HTMLSource', htmlContent);
        app.HtmlTable.Position = [10 10 app.Panel.Position(3)-20 app.Panel.Position(4)-20];
    end
end

        
function plotnyquist(app)
    % PLOTNYQUIST - Enhanced and optimized Nyquist diagram visualization
    % with improved visual elements, performance optimizations, and proper legend
    
    try
        % Get the Nyquist diagram data
        [lReal, lImag, omega] = nyquistSiso(app.L);
        
        % Store data in App properties
        app.nyquist_lReal = lReal;
        app.nyquist_lImag = lImag;
        app.nyquist_omega = omega;
        
        % Calculate angle changes
        app.nyquist_angles = calculateAngles(lReal, lImag);
        
        % Calculate poles, zeros and DeltaPhi
        [m_0, n_0, deltaphi] = countPolesforNyquist(app.L);
        app.required_delta_phi = deltaphi; % Store required DeltaPhi
        
        % NEW: Calculate minimum distance to the critical point
        criticalPoint = [-1, 0];
        distances = sqrt((lReal - criticalPoint(1)).^2 + (lImag - criticalPoint(2)).^2);
        [minDistance, minDistanceIdx] = min(distances);
        minDistFreq = omega(minDistanceIdx);
        
        % Check pole-zero cancellation
        if checkUnstablePoleZeroCancellation(app.G, app.K)
            pzcancel = 'with';
            pzColor = '#E74C3C'; % Red for warning
        else
            pzcancel = 'without';
            pzColor = '#2ECC71'; % Green for good
        end
        
        % Check if L(s) is proper
        if ~proper_tf(app.L)
            lprop = 'not';
            lpropColor = '#E74C3C'; % Red for warning
        else
            lprop = '';
            lpropColor = '#2ECC71'; % Green for good
        end
        
        % Determine stability color based on minimum distance
        if minDistance < 0.25
            minDistColor = '#E74C3C'; % Red for close to instability
        elseif minDistance < 0.5
            minDistColor = '#F39C12'; % Orange for moderate margin
        else
            minDistColor = '#2ECC71'; % Green for good margin
        end
        
        % Prepare the axis
        cla(app.nyquist_UIAxes2);
        
        % 1. NEW FEATURE: Background with colored regions for stability analysis
        % Draw a circle around the critical point with radius based on number of RHP poles
        criticalPoint = [-1, 0];
        hold(app.nyquist_UIAxes2, 'on');
        
        % Add unit point line for better orientation
        theta = linspace(0, 2*pi, 100);
        xunit = cos(theta);
        yunit = sin(theta);
        unitCircle = plot(app.nyquist_UIAxes2, xunit-1, yunit, 'k:', 'LineWidth', 1);
        
        % 2. OPTIMIZATION: Plot with custom line properties for better visibility
        mainCurve = plot(app.nyquist_UIAxes2, lReal, lImag, 'LineWidth', 2.5, 'Color', [0, 0.447, 0.741]);
        
        % 3. BETTER MARKERS: Make special points more distinct
        zeroPoint = plot(app.nyquist_UIAxes2, lReal(1), lImag(1), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', [0.85, 0.325, 0.098], ...
            'MarkerFaceColor', [0.929, 0.694, 0.125], 'LineWidth', 2);
        
        infPoint = plot(app.nyquist_UIAxes2, lReal(end), lImag(end), 'p', 'MarkerSize', 10, 'MarkerEdgeColor', [0.494, 0.184, 0.556], ...
            'MarkerFaceColor', [0.494, 0.184, 0.556], 'LineWidth', 2);
        
        critPoint = plot(app.nyquist_UIAxes2, criticalPoint(1), criticalPoint(2), '+', 'MarkerSize', 15, 'MarkerEdgeColor', 'r', ...
            'LineWidth', 3);
        
        % NEW: Highlight the minimum distance point
        minDistPoint = plot(app.nyquist_UIAxes2, lReal(minDistanceIdx), lImag(minDistanceIdx), 'x', ...
            'MarkerSize', 12, 'MarkerEdgeColor', 'g', 'LineWidth', 2, 'MarkerFaceColor', 'g');
        
        % NEW: Draw a line from critical point to minimum distance point
        minDistLine = plot(app.nyquist_UIAxes2, [criticalPoint(1), lReal(minDistanceIdx)], ...
            [criticalPoint(2), lImag(minDistanceIdx)], 'g--', 'LineWidth', 1.5);
        
        % 4. IMPROVED AXIS LABELS
        xlabel(app.nyquist_UIAxes2, 'Real Axis', 'FontSize', 12, 'FontWeight', 'bold');
        ylabel(app.nyquist_UIAxes2, 'Imaginary Axis', 'FontSize', 12, 'FontWeight', 'bold');
        title(app.nyquist_UIAxes2, 'Enhanced Nyquist Diagram', 'FontSize', 14, 'FontWeight', 'bold');
        
        % 5. IMPROVED AXIS PROPERTIES: Finer grid and better visibility
        grid(app.nyquist_UIAxes2, 'on');
        app.nyquist_UIAxes2.GridAlpha = 0.3;
        app.nyquist_UIAxes2.MinorGridAlpha = 0.2;
        app.nyquist_UIAxes2.XMinorGrid = 'on';
        app.nyquist_UIAxes2.YMinorGrid = 'on';
        
        % 6. FEATURE: Highlight the critical point with concentric circles
        radius = 0.1;
        critCircle = plot(app.nyquist_UIAxes2, criticalPoint(1) + radius*cos(theta), criticalPoint(2) + radius*sin(theta), ...
            'r--', 'LineWidth', 1.5);
        
        % KEY FIX: Set the DisplayName property for each plot element to create proper legend
        unitCircle.DisplayName = 'Unit Circle at (-1,0)';
        mainCurve.DisplayName = 'Nyquist Curve L(jω)';
        zeroPoint.DisplayName = 'ω = 0';
        infPoint.DisplayName = 'ω = ∞';
        critPoint.DisplayName = 'Critical Point (-1,0)';
        critCircle.DisplayName = 'Critical Region';
        minDistPoint.DisplayName = 'Minimum Distance Point';
        minDistLine.DisplayName = sprintf('Min Distance: %.3f', minDistance);
        
        % 7. BETTER LEGEND with proper labels
        legend(app.nyquist_UIAxes2, 'Location', 'northwest', 'FontSize', 10, 'Box', 'on', 'EdgeColor', [0.7 0.7 0.7]);
        
        % Axis limits automatically adjust or fix for better display
        xlim_vals = [min(lReal)-0.5, max(lReal)+0.5];
        ylim_vals = [min(lImag)-0.5, max(lImag)+0.5];
        xlim(app.nyquist_UIAxes2, xlim_vals);
        ylim(app.nyquist_UIAxes2, ylim_vals);
        
        % Clearly mark origin and coordinate axes
        plot(app.nyquist_UIAxes2, [xlim_vals(1), xlim_vals(2)], [0, 0], 'k-', 'LineWidth', 1, 'HandleVisibility', 'off');
        plot(app.nyquist_UIAxes2, [0, 0], [ylim_vals(1), ylim_vals(2)], 'k-', 'LineWidth', 1, 'HandleVisibility', 'off');
        
        % HTML table with improved design
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; margin-bottom: 15px; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 60%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.warning { color: ', pzColor, '; font-weight: bold; }', ...
                '.proper { color: ', lpropColor, '; font-weight: bold; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.angle-value { font-weight: bold; }', ...
                '.info-label { font-style: italic; color: #7F8C8D; font-size: 12px; }', ...
                '.distance { color: ', minDistColor, '; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="1">Nyquist Stability Analysis</th></tr>', ...
                    '<tr><td class="warning">G(s) and K(s) ', pzcancel, ' pole/zero cancellation</td></tr>', ...
                    '<tr><td class="proper">L(s) is ', lprop, ' proper</td></tr>', ...
                    '<tr><td>m = 180°; <span class="angle-value">m₀ = ', num2str(m_0), '</span></td></tr>', ...
                    '<tr><td>n = 90°; <span class="angle-value">n₀ = ', num2str(n_0), '</span></td></tr>', ...
                    '<tr><td>Required encirclements: <span class="angle-value">Δφ = ', num2str(deltaphi), '°</span></td></tr>', ...
                    '<tr><td class="distance">Minimum distance to critical point: <span class="angle-value">', sprintf('%.3f', minDistance), '</span> at ω = ', sprintf('%.3g', minDistFreq), ' rad/s</td></tr>', ...
                    '<tr><td id="currentFreq"><span class="info-label">Move slider to analyze →</span></td></tr>', ...
                    '<tr><td id="currentAngle">Current angle from critical point: <span class="angle-value">0°</span></td></tr>', ...
                    '<tr><td id="deltaPhi" class="unstable">Current Δφ: <span class="angle-value">0°</span> (required: ', num2str(deltaphi), '°)</td></tr>', ...
                    '<tr><td id="stabilityStatus" class="unstable">Control loop stability: <span class="angle-value">Analyzing...</span></td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLNyquistInfo.HTMLSource = htmlTableStr;

       % Create slider panel if it doesn't exist
    if ~isfield(app, 'nyquistSlider') || ~isvalid(app.nyquistSlider)
        
         % Improved slider labels
         frequencyLabels = {'ω=0', 'Low ω', 'Mid ω', 'High ω', 'ω=∞'};

        % Add slider
        app.nyquistSlider = uislider(app.NyquistTab, ...
            'Position', [674,60,469,3], ...
            'Limits', [1, length(omega)], ...
            'Value', 1, ...
            'MajorTicks', linspace(1, length(omega), 5), ...
            'MajorTickLabels', frequencyLabels, ...
            'FontSize', 10);
        
        % CRITICAL CHANGE - Add callback for continuous slider movement
        app.nyquistSlider.ValueChangingFcn = @(slider, event) updateNyquistVisualization(app, event.Value);
    end
        
     % Initialize visualization with the first point (ω=0)
        updateNyquistVisualization(app, 1);
        
        hold(app.nyquist_UIAxes2, 'off');
    catch ME
        % Improved error handling with more details
        disp(['Error plotting Nyquist diagram: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        % Show error message in plot
        cla(app.nyquist_UIAxes2);
        text(app.nyquist_UIAxes2, 0.5, 0.5, {'Error plotting Nyquist diagram', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red', 'FontWeight', 'bold');
        
        % HTML error output
        app.HTMLNyquistInfo.HTMLSource = ['<html><body><div style="color:red; padding:20px; ', ...
            'text-align:center; font-family:Arial; font-weight:bold; border:1px solid red; ', ...
            'border-radius:5px; margin:10px;">', ...
            'Error generating Nyquist analysis:<br>', ME.message, ...
            '</div></body></html>'];
    end
end


function updateNyquistVisualization(app, sliderValue)
    % UPDATENYQUISTVISUALIZATION - Optimized version with performance improvements
    % and proper legend maintenance
    % Line is used instead of arrow (quiver) to connect critical point to current point
    
    try
        % PERFORMANCE: Store precalculations instead of recalculating each time
        persistent lastPlotObjects;
        
        % Calculate index only once
        currentIdx = max(1, min(round(sliderValue), length(app.nyquist_omega)));
        
        % Critical point
        criticalPoint = [-1, 0];
        
        % Current point
        currentPoint = [app.nyquist_lReal(currentIdx), app.nyquist_lImag(currentIdx)];
        
        % PERFORMANCE OPTIMIZATION: Check if plot already exists
        if isempty(lastPlotObjects) || ~isfield(lastPlotObjects, 'mainCurve') || ~isvalid(lastPlotObjects.mainCurve)
            % Complete redraw if plot doesn't exist yet
            cla(app.nyquist_UIAxes2);
            hold(app.nyquist_UIAxes2, 'on');
            
            % Unit circle around -1
            theta = linspace(0, 2*pi, 100);
            xunit = cos(theta);
            yunit = sin(theta);
            unitCircle = plot(app.nyquist_UIAxes2, xunit-1, yunit, 'k:', 'LineWidth', 1);
            
            % Full curve with proper DisplayName
            mainCurve = plot(app.nyquist_UIAxes2, app.nyquist_lReal, app.nyquist_lImag, ...
                'Color', [0.8, 0.8, 0.8], 'LineWidth', 2);
            
            % Special points with proper DisplayName
            zeroPoint = plot(app.nyquist_UIAxes2, app.nyquist_lReal(1), app.nyquist_lImag(1), 'o', ...
                'MarkerSize', 10, 'MarkerEdgeColor', [0.85, 0.325, 0.098], ...
                'MarkerFaceColor', [0.929, 0.694, 0.125], 'LineWidth', 2);
            
            infPoint = plot(app.nyquist_UIAxes2, app.nyquist_lReal(end), app.nyquist_lImag(end), 'p', ...
                'MarkerSize', 10, 'MarkerEdgeColor', [0.494, 0.184, 0.556], ...
                'MarkerFaceColor', [0.494, 0.184, 0.556], 'LineWidth', 2);
            
            critPoint = plot(app.nyquist_UIAxes2, criticalPoint(1), criticalPoint(2), '+', ...
                'MarkerSize', 15, 'MarkerEdgeColor', 'r', 'LineWidth', 3);
            
            % KEY FIX: Set DisplayName properties for legend 
            unitCircle.DisplayName = 'Unit Circle at (-1,0)';
            mainCurve.DisplayName = 'Full Nyquist Curve';
            zeroPoint.DisplayName = 'ω = 0';
            infPoint.DisplayName = 'ω = ∞';
            critPoint.DisplayName = 'Critical Point (-1,0)';
            
            % Save objects for later
            lastPlotObjects = struct('mainCurve', mainCurve, 'zeroPoint', zeroPoint, ...
                'infPoint', infPoint, 'critPoint', critPoint, 'unitCircle', unitCircle);
            lastPlotObjects.axesConfigured = false;
        end
        
        % OPTIMIZATION: Only update elements that change
        % This significantly improves performance
        
        % 1. Active curve up to current point (with proper DisplayName)
        activePart = plot(app.nyquist_UIAxes2, app.nyquist_lReal(1:currentIdx), app.nyquist_lImag(1:currentIdx), ...
            'Color', [0, 0.447, 0.741], 'LineWidth', 3);
        activePart.DisplayName = 'Active Portion L(jω)';
        
        % 2. Highlight current point (with proper DisplayName)
        currentDot = plot(app.nyquist_UIAxes2, currentPoint(1), currentPoint(2), 'o', ...
            'MarkerSize', 10, 'LineWidth', 2, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
        currentDot.DisplayName = sprintf('L(jω) at ω = %.3g rad/s', app.nyquist_omega(currentIdx));
        
        % 3. CHANGE: Line from critical point to current point (replacing arrow/quiver)
        % Use line instead of quiver to draw without arrowhead
        connectLine = line(app.nyquist_UIAxes2, [criticalPoint(1), currentPoint(1)], ...
                         [criticalPoint(2), currentPoint(2)], ...
                         'Color', 'r', 'LineWidth', 2);
        connectLine.DisplayName = 'Vector from (-1,0)';
        
        % OPTIMIZATION: Don't reconfigure axes on every update
        % Only update axes properties when necessary
        if ~isfield(lastPlotObjects, 'axesConfigured') || ~lastPlotObjects.axesConfigured
            grid(app.nyquist_UIAxes2, 'on');
            app.nyquist_UIAxes2.GridAlpha = 0.3;
            app.nyquist_UIAxes2.MinorGridAlpha = 0.2;
            app.nyquist_UIAxes2.XMinorGrid = 'on';
            app.nyquist_UIAxes2.YMinorGrid = 'on';
            xlabel(app.nyquist_UIAxes2, 'Real Axis', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis', 'FontSize', 12, 'FontWeight', 'bold');
            title(app.nyquist_UIAxes2, 'Nyquist Diagram of L(jω)', 'FontSize', 14, 'FontWeight', 'bold');
            
            % Only set axis limits the first time
            xlim_vals = [min(app.nyquist_lReal)-0.5, max(app.nyquist_lReal)+0.5];
            ylim_vals = [min(app.nyquist_lImag)-0.5, max(app.nyquist_lImag)+0.5];
            xlim(app.nyquist_UIAxes2, xlim_vals);
            ylim(app.nyquist_UIAxes2, ylim_vals);
            
            % Draw coordinate axes
            xline(app.nyquist_UIAxes2, 0, 'k-', 'LineWidth', 1, 'Alpha', 0.5, 'HandleVisibility', 'off');
            yline(app.nyquist_UIAxes2, 0, 'k-', 'LineWidth', 1, 'Alpha', 0.5, 'HandleVisibility', 'off');
            
            lastPlotObjects.axesConfigured = true;
        end
        
        % Update legend to show newest elements
        legend(app.nyquist_UIAxes2, 'Location', 'northwest');
        
        % Calculate current angles (in degrees)
        currentAngle = atan2(currentPoint(2) - criticalPoint(2), currentPoint(1) - criticalPoint(1)) * 180/pi;
        
        % Cumulative DeltaPhi based on precalculation
        deltaPhi = app.nyquist_angles(currentIdx) * 180/pi;
        
        % Round to multiples of 90° for stability display
        deltaPhi_display = round(deltaPhi / 90) * 90;
        
        % Determine stability for HTML display
        if abs(deltaPhi_display) >= abs(app.required_delta_phi)
            colorClass = 'stable';
            stabilityText = 'STABLE';
        else
            colorClass = 'unstable';
            stabilityText = 'UNSTABLE';
        end
        
        % Current frequency for display
        currentFreq = app.nyquist_omega(currentIdx);
        
        % Update HTML table with improved formatting
        htmlContent = app.HTMLNyquistInfo.HTMLSource;
        
        % OPTIMIZATION: More efficient replacement of HTML elements
        % Fix the parse error by using proper regex patterns
        
        % 1. Update frequency display
        htmlContent = regexprep(htmlContent, '<td id="currentFreq"[^>]*>.*?</td>', ...
            ['<td id="currentFreq">Current frequency: <span style="font-weight:bold;">\omega = ' num2str(currentFreq, '%.3g') ' rad/s</span></td>']);
        
        % 2. Update angle display
        htmlContent = regexprep(htmlContent, '<td id="currentAngle"[^>]*>.*?</td>', ...
            ['<td id="currentAngle">Angle from critical point: <span style="font-weight:bold;">' num2str(currentAngle, '%.1f') '°</span></td>']);
        
        % 3. Update DeltaPhi with color coding
        htmlContent = regexprep(htmlContent, '<td id="deltaPhi"[^>]*>.*?</td>', ...
            ['<td id="deltaPhi" class="' colorClass '">Encirclement angle: <span style="font-weight:bold;">' num2str(deltaPhi_display) '°</span> (required: ' num2str(app.required_delta_phi) '°)</td>']);
        
        % 4. Add stability status if it doesn't exist yet, otherwise update it
        if ~contains(htmlContent, 'stabilityStatus')
            % Find the table end tag
            tableEndPos = strfind(htmlContent, '</table>');
            if ~isempty(tableEndPos)
                % Insert stability status row before table end
                insertPos = tableEndPos(1);
                beforeInsert = htmlContent(1:insertPos-1);
                afterInsert = htmlContent(insertPos:end);
                
                htmlContent = [beforeInsert, ...
                    '<tr><td id="stabilityStatus" class="' colorClass '">Control loop stability: <span style="font-weight:bold;">' stabilityText '</span></td></tr>', ...
                    afterInsert];
            end
        else
            % Update existing stability status
            htmlContent = regexprep(htmlContent, '<td id="stabilityStatus"[^>]*>.*?</td>', ...
                ['<td id="stabilityStatus" class="' colorClass '">Control loop stability: <span style="font-weight:bold;">' stabilityText '</span></td>']);
        end
        
        % Update HTML content
        app.HTMLNyquistInfo.HTMLSource = htmlContent;
        
        % Cleanup for next iteration - Performance optimization
        if isfield(lastPlotObjects, 'activePart') && isvalid(lastPlotObjects.activePart)
            delete(lastPlotObjects.activePart);
        end
        if isfield(lastPlotObjects, 'currentDot') && isvalid(lastPlotObjects.currentDot)
            delete(lastPlotObjects.currentDot);
        end
        if isfield(lastPlotObjects, 'connectLine') && isvalid(lastPlotObjects.connectLine)
            delete(lastPlotObjects.connectLine);
        end
        
        % Save current temporary objects for next iterations
        lastPlotObjects.activePart = activePart;
        lastPlotObjects.currentDot = currentDot;
        lastPlotObjects.connectLine = connectLine;
        
        drawnow limitrate; % Smooth animation with optimized performance
    catch ME
        % Improved error handling
        disp(['Error updating Nyquist visualization: ' ME.message]);
        disp(getReport(ME, 'extended'));
    end
end

        
function calculateAndDisplayResponse(app, selectedButton)
    % CALCULATEANDDISPLAYRESPONSE
    % --------------------------
    % Enhanced function to calculate and display system response based on selected
    % response type with improved error handling, consistent legend handling, and
    % support for extended time simulations.
    %
    % Parameters:
    % - app: Application handle with necessary components
    % - selectedButton: Button that triggered the response calculation
    
    app.Response_UIAxes.Visible = 'on';
    
    % Set standard title
    responseTitle = 'System Response';
    
    % Check if time vector for setpoint is set
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            disp('Failed to create time vector');
            return;
        end
    end
    
    try
        % Prepare input values for simulation
        [input_r, input_d1, input_d2] = prepareInputValues(app);
        
        % IMPORTANT: FIRST clear all existing plots
        cla(app.Response_UIAxes);
        
        % Store plot handles for legend
        plotHandles = [];
        legendLabels = {};
        
        % Different cases based on the selected button
        if selectedButton == app.StepButton
            % Step response
            try
                % ----- EXTENDED TIME HANDLING FOR STEP RESPONSE -----
                % Get original time vector parameters
                original_dt = app.t(2) - app.t(1);
                original_end = app.t(end);
                
                % Determine if we need extended simulation time
                if isprop(app, 'ExtendSimulationTime') && app.ExtendSimulationTime
                    % Get the desired simulation end time (default: 5x original if not specified)
                    if isprop(app, 'ExtendedSimulationEndTime') && ~isempty(app.ExtendedSimulationEndTime)
                        extended_end = app.ExtendedSimulationEndTime;
                    else
                        extended_end = original_end * 5;
                    end
                    
                    % Create extended time vector (original + logarithmically spaced extension)
                    n_extra_points = min(1000, ceil((extended_end - original_end) / original_dt));
                    extra_times = logspace(log10(original_end + original_dt), log10(extended_end), n_extra_points);
                    t_sim = [app.t; extra_times(:)];
                    
                    % Create unit step for the extended time vector
                    u = ones(size(t_sim));
                else
                    % Use original time vector if extension not needed
                    t_sim = app.t;
                    u = ones(size(app.t));
                end
                
                % Calculate system response
                [y, t_out] = lsim(app.T, u, t_sim);
                
                % For plotting, downsample if necessary to improve performance
                if length(t_out) > 1000
                    % Use adaptive downsampling to preserve important features
                    % Keep more points during transient, fewer during steady state
                    mask = createAdaptiveSamplingMask(y, t_out, 800);
                    t_plot = t_out(mask);
                    y_plot = y(mask);
                else
                    t_plot = t_out;
                    y_plot = y;
                end
                
                % Plot system response
                hold(app.Response_UIAxes, 'on');
                h = plot(app.Response_UIAxes, t_plot, y_plot, 'LineWidth', 1.5);
                plotHandles = [plotHandles; h];
                legendLabels{end+1} = 'System Response';
                hold(app.Response_UIAxes, 'off');
                
                % Set title
                if t_sim(end) > app.t(end)
                    responseTitle = sprintf('System Step Response (Extended to %.1f s)', t_sim(end));
                else
                    responseTitle = 'System Step Response';
                end
                
                % Display key parameters with appropriate response type
                displayKeyParameters(app, y, t_out, 'step');
            catch ME
                disp(['Simulation error (Step): ', ME.message]);
                return;
            end
        elseif selectedButton == app.SetpointButton
            % Response to the user-defined setpoint
            try
                % ----- EXTENDED TIME HANDLING FOR SETPOINT RESPONSE -----
                % Get original time vector parameters
                original_dt = app.t(2) - app.t(1);
                original_end = app.t(end);
                
                % Determine active duration of setpoint signal
                active_indices = find(abs(diff(input_r)) > 1e-6);
                if isempty(active_indices)
                    last_active_idx = 1;
                else
                    last_active_idx = max(active_indices) + 1;
                end
                
                % Get the time where last setpoint change occurs
                if last_active_idx <= length(app.t)
                    last_active_time = app.t(last_active_idx);
                else
                    last_active_time = app.t(end);
                end
                
                % Determine if we need extended simulation time
                if isprop(app, 'ExtendSimulationTime') && app.ExtendSimulationTime
                    % Get the desired simulation end time
                    if isprop(app, 'ExtendedSimulationEndTime') && ~isempty(app.ExtendedSimulationEndTime)
                        extended_end = app.ExtendedSimulationEndTime;
                    else
                        % Default: extend to 5x the active signal duration or at least to the end of original time
                        extended_end = max(original_end, last_active_time * 5);
                    end
                    
                    if extended_end > original_end
                        % Create extended time vector
                        n_extra_points = min(1000, ceil((extended_end - original_end) / original_dt));
                        extra_times = logspace(log10(original_end + original_dt), log10(extended_end), n_extra_points);
                        t_sim = [app.t; extra_times(:)];
                        
                        % Create extended setpoint (hold last value)
                        r_extended = [input_r; ones(length(extra_times), 1) * input_r(end)];
                    else
                        t_sim = app.t;
                        r_extended = input_r;
                    end
                else
                    t_sim = app.t;
                    r_extended = input_r;
                end
                
                % Calculate system response
                [y, t_out] = lsim(app.T, r_extended, t_sim);
                
                % For plotting, downsample if necessary to improve performance
                if length(t_out) > 1000
                    mask = createAdaptiveSamplingMask(y, t_out, 800);
                    t_plot = t_out(mask);
                    y_plot = y(mask);
                    
                    % Create downsampled reference input for plotting
                    if length(r_extended) > length(t_out)
                        r_extended = r_extended(1:length(t_out));
                    elseif length(r_extended) < length(t_out)
                        r_extended = [r_extended; ones(length(t_out) - length(r_extended), 1) * r_extended(end)];
                    end
                    r_plot = r_extended(mask);
                else
                    t_plot = t_out;
                    y_plot = y;
                    r_plot = r_extended;
                    if length(r_plot) > length(t_plot)
                        r_plot = r_plot(1:length(t_plot));
                    elseif length(r_plot) < length(t_plot)
                        r_plot = [r_plot; ones(length(t_plot) - length(r_plot), 1) * r_plot(end)];
                    end
                end
                
                % Plot system response and reference input
                hold(app.Response_UIAxes, 'on');
                h1 = plot(app.Response_UIAxes, t_plot, y_plot, 'LineWidth', 1.5);
                h2 = plot(app.Response_UIAxes, t_plot, r_plot, '--', 'LineWidth', 1);
                plotHandles = [plotHandles; h1; h2];
                legendLabels{end+1} = 'System Response';
                legendLabels{end+1} = 'Reference Input';
                hold(app.Response_UIAxes, 'off');
                
                % Set title
                if t_sim(end) > app.t(end)
                    responseTitle = sprintf('System Response to Setpoint r (Extended to %.1f s)', t_sim(end));
                else
                    responseTitle = 'System Response to Setpoint r';
                end
                
                % Display key parameters with appropriate response type
                displayKeyParameters(app, y, t_out, 'setpoint');
            catch ME
                disp(['Simulation error (Setpoint): ', ME.message]);
                return;
            end
        elseif selectedButton == app.r_d12Button
            % Response to setpoint and disturbances
            try
                % Define closed-loop system
                closed_loop_system = feedback(app.L, 1);
                
                % Calculate response using the original time vector first
                [y_r_orig, t_out_orig] = lsim(closed_loop_system, input_r, app.t);
                
                % Transfer functions for disturbances
                H_d1 = feedback(1, app.L); % Transfer function for disturbance D1
                H_d2 = feedback(app.G, app.K * app.G); % Transfer function for disturbance D2
                
                % Calculate responses to disturbances using original time vector
                [y_d1_orig, ~] = lsim(H_d1, input_d1, app.t);
                [y_d2_orig, ~] = lsim(H_d2, input_d2, app.t);
                
                % Total response for original time
                y_total_orig = y_r_orig + y_d1_orig + y_d2_orig;
                
                % ----- EXTENDED TIME HANDLING (if enabled) -----
                % Check if simulation extension is requested
                extend_sim = false;
                if isprop(app, 'ExtendSimulationTime')
                    if ~isempty(app.ExtendSimulationTime) && app.ExtendSimulationTime == true
                        extend_sim = true;
                    end
                end
                
                if extend_sim
                    % Get original time vector parameters
                    original_dt = app.t(2) - app.t(1);
                    original_end = app.t(end);
                    
                    % Determine the extension time
                    if isprop(app, 'ExtendedSimulationEndTime') && ~isempty(app.ExtendedSimulationEndTime)
                        extended_end = app.ExtendedSimulationEndTime;
                    else
                        % Default: extend to 5x the original time
                        extended_end = original_end * 5;
                    end
                    
                    % Create extension time vector with fewer points for efficiency
                    n_extra_points = min(500, ceil((extended_end - original_end) / original_dt));
                    extra_times = linspace(original_end + original_dt, extended_end, n_extra_points)';
                    
                    % Combine original and extension time vectors
                    t_extended = [app.t; extra_times];
                    
                    % Extend input vectors with their final values
                    r_extended = [input_r; ones(length(extra_times), 1) * input_r(end)];
                    d1_extended = [input_d1; ones(length(extra_times), 1) * input_d1(end)];
                    d2_extended = [input_d2; ones(length(extra_times), 1) * input_d2(end)];
                    
                    % Simulate with extended time
                    [y_r, t_out] = lsim(closed_loop_system, r_extended, t_extended);
                    [y_d1, ~] = lsim(H_d1, d1_extended, t_extended);
                    [y_d2, ~] = lsim(H_d2, d2_extended, t_extended);
                    
                    % Total response
                    y_total = y_r + y_d1 + y_d2;
                    
                    % Control error
                    error = r_extended - y_total;
                    
                    % Title with extended time indication
                    responseTitle = sprintf('System Response to Setpoint r and Disturbances (Extended to %.1f s)', t_extended(end));
                else
                    % Use original simulation results if not extending
                    t_out = t_out_orig;
                    y_total = y_total_orig;
                    error = input_r - y_total;
                    
                    % Standard title
                    responseTitle = 'System Response to Setpoint r and Disturbances';
                end
                
                % ----- PLOT PREPARATION -----
                % Clear previous plots
                cla(app.Response_UIAxes);
                
                % Start plotting with consistent color scheme
                hold(app.Response_UIAxes, 'on');
                
                % 1. Plot system response (thicker line)
                h1 = plot(app.Response_UIAxes, t_out, y_total, 'LineWidth', 1.5, 'Color', [0 0.447 0.741]);
                
                % 2. Plot reference input
                h2 = plot(app.Response_UIAxes, app.t, input_r, '--', 'LineWidth', 1, 'Color', [0.85 0.325 0.098]);
                
                % 3. Plot disturbances with dotted lines (keeping original signals)
                h3 = plot(app.Response_UIAxes, app.t, input_d1, ':', 'LineWidth', 1.2, 'Color', [0.929 0.694 0.125]);
                h4 = plot(app.Response_UIAxes, app.t, input_d2, ':', 'LineWidth', 1.2, 'Color', [0.494 0.184 0.556]);
                
                % 4. Plot control error
                h5 = plot(app.Response_UIAxes, t_out, error, '-.', 'LineWidth', 1, 'Color', [0.635 0.078 0.184]);
                
                % Add to handle list for legend
                plotHandles = [h1; h2; h3; h4; h5];
                legendLabels = {'System Response', 'Reference Input', 'Disturbance 1', 'Disturbance 2', 'Control Error'};
                
                % If this is an extended simulation, add markers where disturbances occur
                if extend_sim && t_out(end) > app.t(end)
                    % Find where disturbances have significant changes
                    d1_changes = find(abs(diff(input_d1)) > 0.01 * max(abs(input_d1)));
                    d2_changes = find(abs(diff(input_d2)) > 0.01 * max(abs(input_d2)));
                    
                    % Mark significant disturbance 1 changes with vertical lines
                    for i = 1:length(d1_changes)
                        idx = d1_changes(i);
                        if idx <= length(app.t)
                            line([app.t(idx) app.t(idx)], get(app.Response_UIAxes, 'YLim'), ...
                                'Color', [0.929 0.694 0.125], 'LineStyle', ':', 'LineWidth', 0.5);
                        end
                    end
                    
                    % Mark significant disturbance 2 changes with vertical lines
                    for i = 1:length(d2_changes)
                        idx = d2_changes(i);
                        if idx <= length(app.t)
                            line([app.t(idx) app.t(idx)], get(app.Response_UIAxes, 'YLim'), ...
                                'Color', [0.494 0.184 0.556], 'LineStyle', ':', 'LineWidth', 0.5);
                        end
                    end
                end
                
                hold(app.Response_UIAxes, 'off');
                
                % Display key parameters
                displayKeyParameters(app, y_total, t_out, 'combined');
            catch ME
                disp(['Simulation error (Combined): ', ME.message]);
                return;
            end
        end
        
        % Set title and labels
        title(app.Response_UIAxes, responseTitle);
        xlabel(app.Response_UIAxes, 'Time (s)');
        ylabel(app.Response_UIAxes, 'Amplitude');
        
        % Add legend if there are plot handles
        if ~isempty(plotHandles)
            legend(app.Response_UIAxes, plotHandles, legendLabels, 'Location', 'best');
        end
        
        % Enable grid with lightweight styling for better performance
        grid(app.Response_UIAxes, 'on');
        set(app.Response_UIAxes, 'GridAlpha', 0.15);
        set(app.Response_UIAxes, 'MinorGridAlpha', 0.05);
        
        % Performance optimizations for interactive use
        set(app.Response_UIAxes, 'SortMethod', 'childorder');
        
    catch ME
        disp(['Error in calculateAndDisplayResponse: ', ME.message]);
        return;
    end
end


function displayKeyParameters(app, y, t_vector, responseType)
    % DISPLAYKEYPARAMETERS
    % ------------------
    % Enhanced function to display key response parameters with improved
    % formatting and error handling for different response types.
    %
    % Parameters:
    % - app: Application handle with HTMLkeyparamTable component
    % - y: Response signal vector
    % - t_vector: Time vector
    % - responseType: String indicating the type of response ('step', 'setpoint', or 'combined')
    
    if nargin < 4
        responseType = 'step'; % Default to step response if not specified
    end
    
    try
        % Ensure y and t_vector have matching lengths
        if length(y) ~= length(t_vector)
            disp('Warning: Sizes of y and t_vector mismatch in displayKeyParameters');
            if length(y) > length(t_vector)
                y = y(1:length(t_vector));
            elseif length(t_vector) > length(y)
                t_vector = t_vector(1:length(y));
            end
        end
        
        % Initialize parameters with default values
        RiseTime = NaN;
        SettlingTime = NaN;
        SettlingMin = NaN;
        SettlingMax = NaN;
        Overshoot = NaN;
        Undershoot = NaN;
        Peak = NaN;
        PeakTime = NaN;
        TransientTime = NaN;
        
        % Different analysis based on response type
        switch responseType
            case 'step'
                % Use stepinfo for step response analysis
                info = stepinfo(y, t_vector);
                
                % Extract data from the stepinfo structure
                RiseTime = info.RiseTime;
                SettlingTime = info.SettlingTime;
                SettlingMin = info.SettlingMin;
                SettlingMax = info.SettlingMax;
                Overshoot = info.Overshoot;
                Undershoot = info.Undershoot;
                Peak = info.Peak;
                PeakTime = info.PeakTime;
                
                % Check for TransientTime field
                if isfield(info, 'TransientTime')
                    TransientTime = info.TransientTime;
                end
                
            case 'setpoint'
                % Custom analysis for setpoint tracking
                % Calculate final value as the average of last few samples
                finalValue = mean(y(end-min(10, length(y)-1):end));
                
                % Calculate rise time (time to reach 90% of final value)
                riseThreshold = 0.9 * finalValue;
                riseIndices = find(y >= riseThreshold, 1);
                if ~isempty(riseIndices)
                    RiseTime = t_vector(riseIndices(1));
                end
                
                % Calculate peak and peak time
                [Peak, peakIndex] = max(abs(y));
                PeakTime = t_vector(peakIndex);
                
                % Calculate settling time (within 2% of final value)
                settlingBand = 0.02 * finalValue;
                upperBound = finalValue + settlingBand;
                lowerBound = finalValue - settlingBand;
                
                for i = length(y):-1:1
                    if y(i) > upperBound || y(i) < lowerBound
                        break;
                    end
                end
                
                if i < length(y)
                    SettlingTime = t_vector(i+1);
                    SettlingMin = min(y(i+1:end));
                    SettlingMax = max(y(i+1:end));
                else
                    SettlingTime = t_vector(1);
                    SettlingMin = min(y);
                    SettlingMax = max(y);
                end
                
                % Calculate overshoot
                if finalValue ~= 0
                    Overshoot = 100 * (max(y) - finalValue) / finalValue;
                end
                
                % Calculate undershoot
                if finalValue ~= 0
                    Undershoot = 100 * (finalValue - min(y)) / finalValue;
                end
                
            case 'combined'
                % Custom analysis for combined response (setpoint + disturbances)
                % Similar to setpoint but with additional focus on disturbance effects
                finalValue = mean(y(end-min(10, length(y)-1):end));
                
                % Calculate statistics
                [maxVal, maxIdx] = max(y);
                [minVal, minIdx] = min(y);
                Peak = maxVal;
                PeakTime = t_vector(maxIdx);
                
                % Calculate settling time (time after which response stays within ±2% of final value)
                settlingThreshold = 0.02 * finalValue;
                settled = false;
                for i = length(y):-1:1
                    if abs(y(i) - finalValue) > settlingThreshold
                        settled = true;
                        break;
                    end
                end
                
                if settled && i < length(y)
                    SettlingTime = t_vector(i+1);
                else
                    SettlingTime = NaN;
                end
                
                % Calculate rise time approximation
                rising = false;
                for i = 1:length(y)
                    if y(i) >= 0.9 * finalValue
                        rising = true;
                        break;
                    end
                end
                
                if rising
                    RiseTime = t_vector(i);
                end
                
                % Calculate min/max after settling
                if ~isnan(SettlingTime)
                    settlingIdx = find(t_vector >= SettlingTime, 1);
                    if ~isempty(settlingIdx)
                        SettlingMin = min(y(settlingIdx:end));
                        SettlingMax = max(y(settlingIdx:end));
                    end
                end
                
                % Calculate overshoot
                if finalValue > 0
                    Overshoot = 100 * max(0, (maxVal - finalValue) / finalValue);
                else
                    Overshoot = NaN;
                end
                
                % Calculate undershoot
                if finalValue > 0
                    Undershoot = 100 * max(0, (finalValue - minVal) / finalValue);
                else
                    Undershoot = NaN;
                end
                
            otherwise
                disp(['Unknown response type: ', responseType]);
                return;
        end
        
        % Format values with proper precision
        RiseTime_str = formatNumber(RiseTime);
        SettlingTime_str = formatNumber(SettlingTime);
        SettlingMin_str = formatNumber(SettlingMin);
        SettlingMax_str = formatNumber(SettlingMax);
        Overshoot_str = [formatNumber(Overshoot), ' %']; % Add percentage symbol
        Undershoot_str = formatNumber(Undershoot);
        Peak_str = formatNumber(Peak);
        PeakTime_str = formatNumber(PeakTime);
        TransientTime_str = formatNumber(TransientTime);
        
        % Create enhanced HTML table with better styling
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { text-align: left; font-weight: bold; width: 60%; }', ...
                'td:last-child { text-align: right; width: 40%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="2">Key Parameters of a Response</th></tr>', ...
                    '<tr><td>Rise time t<sub>r</sub></td><td>', RiseTime_str, '</td></tr>', ...
                    '<tr><td>Settling time (2% band) t<sub>s</sub></td><td>', SettlingTime_str, '</td></tr>', ...
                    '<tr><td>Settling Minimum</td><td>', SettlingMin_str, '</td></tr>', ...
                    '<tr><td>Settling Maximum</td><td>', SettlingMax_str, '</td></tr>', ...
                    '<tr><td>Overshoot</td><td>', Overshoot_str, '</td></tr>', ...
                    '<tr><td>Undershoot</td><td>', Undershoot_str, '</td></tr>', ...
                    '<tr><td>Peak</td><td>', Peak_str, '</td></tr>', ...
                    '<tr><td>Peak Time</td><td>', PeakTime_str, '</td></tr>'];
        
        % Add transient time only for step response
        if responseType == "step" && ~isnan(TransientTime)
            htmlTableStr = [htmlTableStr, ...
                    '<tr><td>Transient Time</td><td>', TransientTime_str, '</td></tr>'];
        end
        
        % Complete the table HTML
        htmlTableStr = [htmlTableStr, ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLkeyparamTable.HTMLSource = htmlTableStr;
    catch ME
        % Handle errors in a more user-friendly way
        disp(['Error in displayKeyParameters: ', ME.message]);
        
        % Create error table
        errorTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #E74C3C; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 12px; text-align: center; font-size: 14px; }', ...
                '.error { color: #E74C3C; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Response Analysis Error</th></tr>', ...
                    '<tr><td class="error">Could not calculate key parameters</td></tr>', ...
                    '<tr><td>Possible issues: non-standard response shape, insufficient data points, or negative time values</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        app.HTMLkeyparamTable.HTMLSource = errorTableStr;
    end
    
    % Helper function to format numbers with appropriate precision
    function str = formatNumber(num)
        if isnan(num)
            str = 'N/A';
        elseif abs(num) > 1000 || abs(num) < 0.01
            % Use scientific notation for very large/small numbers
            str = sprintf('%.4e', num);
        else
            % Use fixed-point notation with appropriate precision
            str = sprintf('%.4f', num);
        end
    end
end

        
function calculateAndDisplayErrorandOutput(app, selectedButton)
    % CALCULATEANDDISPLAYERRORANDOUTPUT - Berechnet und zeigt stationäre Werte an
    % Diese Funktion wurde überarbeitet, um die gleiche Eingabevorbereitung wie
    % calculateAndDisplayResponse zu verwenden und somit Dimensionsprobleme zu vermeiden.
    
    % Sichtbarkeit der Antwort-UIAxes aktivieren
    app.Controllanderrorplo.Visible = 'on';
    
    % Prüfen, ob gültige Übertragungsfunktionen vorhanden sind
    if ~isa(app.T, 'tf') || ~isa(app.S, 'tf') || ~isa(app.G, 'tf')
        uialert(app.UIFigure, 'Please define valid transfer functions G(s) and K(s) first.', 'Invalid System');
        return;
    end
    
    % Standardwerte für Sollwert und Störgrößen
    if isempty(app.r_s)
        app.r_s = 1;
    end
    
    if isempty(app.d_1_s)
        app.d_1_s = 0;
    end
    
    if isempty(app.d_2_s)
        app.d_2_s = 0;
    end
    
    disp(['Reference Input (r_s): ', num2str(app.r_s)]);
    disp(['Output Disturbance (d_1_s): ', num2str(app.d_1_s)]);
    disp(['Input Disturbance (d_2_s): ', num2str(app.d_2_s)]);
    
    % Zeitvektor prüfen
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            uialert(app.UIFigure, 'Unable to create time vector. Please set time parameters.', 'Error');
            return;
        end
    end
    
    % Eingabewerte vorbereiten mit der gleichen Funktion wie in calculateAndDisplayResponse
    try
        [input_r, input_d1, input_d2] = prepareInputValues(app);
    catch ME
        uialert(app.UIFigure, ['Error preparing inputs: ', ME.message], 'Error');
        disp(['Error details: ', getReport(ME)]);
        return;
    end
    
    % Struktur für die Simulation vorbereiten
    inputs_constant = struct('reference', true, 'disturbance1', true, 'disturbance2', true);
    
    % Prüfen, ob Eingaben konstant sind
    if iscell(app.r)
        inputs_constant.reference = false;
    end
    
    if iscell(app.d_1)
        inputs_constant.disturbance1 = false;
    end
    
    if iscell(app.d_2)
        inputs_constant.disturbance2 = false;
    end
    
    % Basierend auf dem ausgewählten Button unterschiedliche Simulationen durchführen
    if selectedButton == app.SetButton
        % Nur Sollwert: r konstant und Störgrößen = 0
        try
            % Simulation ausführen
            [y_TR, t_out] = lsim(app.T, input_r, app.t);
            [e_SR, ~] = lsim(app.S, input_r, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint = const and disturbances = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_TR, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SR, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_TR) && length(y_TR) > 10
                endpart = max(1, round(0.9*length(y_TR)));
                y_stat_TR = mean(y_TR(endpart:end));
                e_stat_SR = mean(e_SR(endpart:end));
            else
                y_stat_TR = y_TR(end);
                e_stat_SR = e_SR(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SR) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_TR, e_stat_SR, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.OutputdisturbanceButton
        % Nur Ausgangsstörung: r = 0, d_1 konstant
        try
            % Simulation ausführen
            [y_SD1, t_out] = lsim(-app.S, input_d1, app.t);
            [e_SD1, ~] = lsim(app.S, input_d1, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Output disturbance = const and r = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_SD1, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SD1, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_d1, ':', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_SD1) && length(y_SD1) > 10
                endpart = max(1, round(0.9*length(y_SD1)));
                y_stat_SD1 = mean(y_SD1(endpart:end));
                e_stat_SD1 = mean(e_SD1(endpart:end));
            else
                y_stat_SD1 = y_SD1(end);
                e_stat_SD1 = e_SD1(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SD1) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_SD1, e_stat_SD1, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.SetpointandDisturbanceButton
        % Kombinierter Fall: r und Ausgangsstörung
        try
            % Simulation für die kombinierte Antwort
            [y_r, t_out] = lsim(app.T, input_r, app.t);
            [y_d, ~] = lsim(-app.S, input_d1, app.t);
            y_total = y_r + y_d;  % Kombinierte Antwort
            
            [e_r, ~] = lsim(app.S, input_r, app.t);
            [e_d, ~] = lsim(app.S, input_d1, app.t);
            e_total = e_r + e_d;  % Kombinierter Fehler
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint and Output disturbance = const';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_total, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_total, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            plot(app.Controllanderrorplo, app.t, input_d1, '-.', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_total) && length(y_total) > 10
                endpart = max(1, round(0.9*length(y_total)));
                y_stat_combined = mean(y_total(endpart:end));
                e_stat_combined = mean(e_total(endpart:end));
            else
                y_stat_combined = y_total(end);
                e_stat_combined = e_total(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_combined) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_combined, e_stat_combined, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    end
    
    % Titel und Labels setzen
    title(app.Controllanderrorplo, responseTitle);
    xlabel(app.Controllanderrorplo, 'Time (s)');
    ylabel(app.Controllanderrorplo, 'Amplitude');
    grid(app.Controllanderrorplo, 'on');
    legend(app.Controllanderrorplo, 'Location', 'best');
end

function displayStationaryValues(app, y, e, inputs_constant)
    % DISPLAYSTATIONARYVALUES
    % ---------------------
    % Enhanced display of steady-state values with better formatting and more information
    % Now includes jumpability information in the HTML table
    
    % Define threshold for considering values as effectively zero
    threshold = 1e-6;
    
    % Add warnings for non-constant inputs
    warning_msg = '';
    if nargin >= 4 && isstruct(inputs_constant)
        if ~inputs_constant.reference
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Reference input is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance1
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Output disturbance is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance2
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Input disturbance is not constant.</td></tr>'];
        end
        
        if ~isempty(warning_msg)
            warning_msg = [warning_msg, '<tr><td class="warning">Steady-state values are based on simulation end points.</td></tr>'];
        end
    end
    
    % Format output value string with improved handling of special cases
    if isnan(y)
        disp('Warning: Output value is NaN, using 0 instead for display');
        y = 0;
        y_str = '<span class="estimate">y<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(y) < threshold
        y_str = 'y<sub>∞</sub> = 0';
    else
        % Format y with 4 significant digits and scientific notation for very large/small values
        if abs(y) > 10000 || abs(y) < 0.01
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4e', y)];
        else
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4g', y)];
        end
    end
    
    % Format error value string
    if isnan(e)
        disp('Warning: Error value is NaN, using 0 instead for display');
        e = 0;
        e_str = '<span class="estimate">e<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(e) < threshold
        e_str = 'e<sub>∞</sub> = 0';
    else
        % Format e with scientific notation if needed
        if abs(e) > 10000 || abs(e) < 0.01
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4e', e)];
        else
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4g', e)];
        end
    end
    
    % Set jumpability message with improved styling
    if app.cansysjump == 1
        jumpMessage = 'The control loop is jumpable (Zero steady-state error for step input)';
        jumpColor = '#2ECC71'; % Green color for positive result
    else
        jumpMessage = 'The control loop is not jumpable (Has steady-state error for step input)';
        jumpColor = '#E74C3C'; % Red color for negative result
    end
    
    % Still update the JumpLabel for backward compatibility
    app.JumpLabel.Text = jumpMessage;
    app.JumpLabel.FontColor = jumpColor;
    
    % Create enhanced HTML table - now including jumpability information
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
            'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
            'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }', ...
            'tr:nth-child(even) { background-color: #F2F2F2; }', ...
            'tr:hover { background-color: #E8F4F8; }', ...
            '.warning { color: #E74C3C; font-weight: bold; }', ...
            '.info { color: #3498DB; font-style: italic; }', ...
            '.estimate { color: #E67E22; font-style: italic; }', ...
            '.jumpable { color: ', jumpColor, '; font-weight: bold; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th>Stationary Values</th></tr>', ...
                '<tr><td>', y_str, '</td></tr>', ...
                '<tr><td>', e_str, '</td></tr>', ...
                '<tr><td class="jumpable">', jumpMessage, '</td></tr>', ...
                '<tr><td class="info">Based on simulation results</td></tr>', ...
                warning_msg, ...
            '</table>', ...
        '</body></html>'];
    
    % Update the HTML component
    app.HTMLkeyparamTable_2.HTMLSource = htmlTableStr;
    
    % Display additional diagnostic info in console
    disp(['Steady-state output: ', strrep(y_str, '<sub>∞</sub>', '(∞)')]);
    disp(['Steady-state error: ', strrep(e_str, '<sub>∞</sub>', '(∞)')]);
    disp(['System jumpability: ', jumpMessage]);
end


function PZchange(app, selectedButton)
    % PZCHANGE
    % -------
    % Creates an enhanced pole-zero plot with colored stable/unstable regions
    % and displays pole/zero information in HTML format
    
    try
        % Select the appropriate transfer function based on button selection
        if selectedButton == app.GsButton
            sys = app.G;
            varName = 'G';
        elseif selectedButton == app.TsButton
            sys = app.T;
            varName = 'T';
        elseif selectedButton == app.KsButton
            sys = app.K;
            varName = 'K';
        elseif selectedButton == app.KSsButton
            sys = app.KS;
            varName = 'KS';
        elseif selectedButton == app.GSsButton
            sys = app.GS;
            varName = 'GS';
        elseif selectedButton == app.LsButton
            sys = app.L;
            varName = 'L';
        elseif selectedButton == app.SsButton
            sys = app.S;
            varName = 'S';
        else
            error('Unknown transfer function button selected.');
        end
        
        % Debug output
        disp(['Selected Button: ', char(selectedButton.Tag)]);
        disp(['VarName: ', varName]);
        
        % Calculate poles and zeros
        poles = pole(sys);
        zeroes = zero(sys);
        
        % Create HTML strings for poles and zeros with improved formatting
        polesStr = formatPZListInline(poles, 'p');
        zeroesStr = formatPZListInline(zeroes, 'z');
        
        % Create enhanced HTML table
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td.pz-list { padding: 12px; text-align: left; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Poles and Zeros of ', varName, '(s)</th></tr>', ...
                    '<tr><td class="pz-list">', polesStr, '</td></tr>', ...
                    '<tr><td class="pz-list">', zeroesStr, '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLPZ.HTMLSource = htmlTableStr;
        
        % Store poles and zeros in app
        app.([varName, '_poles']) = poles;
        app.([varName, '_zeroes']) = zeroes;
        
        % Make the UIAxes visible if it wasn't already
        app.pzplotUIAxes.Visible = 'on';
        
        % Clear the plot area
        cla(app.pzplotUIAxes);
        
        % Check if the system is discrete or continuous
        isDiscrete = false;
        if isa(sys, 'tf') || isa(sys, 'ss')
            try
                isDiscrete = sys.Ts > 0;
            catch
                % If Ts property doesn't exist or can't be accessed
                isDiscrete = false;
            end
        end
        
        % Create enhanced pole-zero plot with colored regions
        createEnhancedPZMapInline(app.pzplotUIAxes, poles, zeroes, isDiscrete, varName);
        
    catch ME
        % Handle errors gracefully
        disp(['Error in PZchange: ' ME.message]);
        
        % Display error message in HTML
        app.HTMLPZ.HTMLSource = [...
            '<html><head><style>', ...
                'div.error { color: red; font-weight: bold; text-align: center; padding: 20px; }', ...
            '</style></head><body>', ...
                '<div class="error">Error displaying poles and zeros:<br>', ME.message, '</div>', ...
            '</body></html>'];
        
        % Display error in plot
        cla(app.pzplotUIAxes);
        text(app.pzplotUIAxes, 0.5, 0.5, 'Error displaying pole-zero plot', ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red');
    end
    
    % ====================================================================
    % INLINE HELPER FUNCTIONS
    % ====================================================================
    
    % Helper function to format poles/zeros list - defined inline to avoid scope issues
    function str = formatPZListInline(values, type)
        if isempty(values)
            if strcmp(type, 'p')
                str = 'No poles';
            else
                str = 'No zeros';
            end
            return;
        end
        
        str = '';
        for i = 1:length(values)
            % Format complex numbers nicely
            if imag(values(i)) == 0
                % Real value
                val_str = sprintf('%.4f', real(values(i)));
            elseif real(values(i)) == 0
                % Pure imaginary
                val_str = sprintf('%.4fi', imag(values(i)));
            elseif imag(values(i)) > 0
                % Complex with positive imaginary
                val_str = sprintf('%.4f + %.4fi', real(values(i)), imag(values(i)));
            else
                % Complex with negative imaginary
                val_str = sprintf('%.4f - %.4fi', real(values(i)), abs(imag(values(i))));
            end
            
            % Add to list with proper formatting
            if strcmp(type, 'p')
                str = [str, 'x<sub>p', num2str(i), '</sub> = ', val_str, '<br>'];
            else
                str = [str, 'x<sub>z', num2str(i), '</sub> = ', val_str, '<br>'];
            end
        end
    end
    
    % Enhanced pole-zero map creation function - defined inline to avoid scope issues
    function createEnhancedPZMapInline(axesHandle, poles, zeroes, isDiscrete, sysName)
        % Clear any previous content
        cla(axesHandle);
        
        % Enable hold for multiple elements
        hold(axesHandle, 'on');
        
        % Determine axis limits based on poles and zeros
        allPoints = [poles; zeroes];
        if ~isempty(allPoints)
            maxMag = max(abs(allPoints)) * 1.2;  % Add 20% margin
            maxMag = max(maxMag, 1.5);  % Ensure minimum view range
        else
            maxMag = 1.5;  % Default view range
        end
        
        % Define colors
        stableColor = [0.3, 0.6, 0.9];  % Blue
        unstableColor = [0.9, 0.6, 0.3]; % Orange
        
        % Create colored regions based on system type
        if isDiscrete
            % For discrete-time systems, stable region is inside the unit circle
            
            % Create a circle with many points for the stable region
            theta = linspace(0, 2*pi, 100);
            x_circle = cos(theta);
            y_circle = sin(theta);
            
            % Draw the unstable region (outside the unit circle)
            % Create a rectangular boundary for the outer region
            x_rect = [-maxMag, maxMag, maxMag, -maxMag];
            y_rect = [-maxMag, -maxMag, maxMag, maxMag];
            
            % Create patch objects for regions
            patch(axesHandle, x_rect, y_rect, unstableColor, 'EdgeColor', 'none');
            patch(axesHandle, x_circle, y_circle, stableColor, 'EdgeColor', 'k');
            
            % Draw the unit circle boundary
            plot(axesHandle, x_circle, y_circle, 'k-', 'LineWidth', 2);
            
            % Removed region labels as requested
        else
            % For continuous-time systems, stable region is the left half-plane
            
            % Draw the stable region (left half-plane)
            x_stable = [-maxMag, 0, 0, -maxMag];
            y_stable = [-maxMag, -maxMag, maxMag, maxMag];
            patch(axesHandle, x_stable, y_stable, stableColor, 'EdgeColor', 'none');
            
            % Draw the unstable region (right half-plane)
            x_unstable = [0, maxMag, maxMag, 0];
            y_unstable = [-maxMag, -maxMag, maxMag, maxMag];
            patch(axesHandle, x_unstable, y_unstable, unstableColor, 'EdgeColor', 'none');
            
            % Draw the imaginary axis boundary
            plot(axesHandle, [0, 0], [-maxMag, maxMag], 'k-', 'LineWidth', 2);
            
            % Removed region labels as requested
        end
        
        % Plot poles (x) and zeros (o)
        if ~isempty(poles)
            h_poles = plot(axesHandle, real(poles), imag(poles), 'kx', 'MarkerSize', 10, 'LineWidth', 2);
        else
            % Create dummy poles for legend
            h_poles = plot(axesHandle, NaN, NaN, 'kx', 'MarkerSize', 10, 'LineWidth', 2);
        end
        
        if ~isempty(zeroes)
            h_zeros = plot(axesHandle, real(zeroes), imag(zeroes), 'ko', 'MarkerSize', 10, 'LineWidth', 2);
        else
            % Create dummy zeros for legend
            h_zeros = plot(axesHandle, NaN, NaN, 'ko', 'MarkerSize', 10, 'LineWidth', 2);
        end
        
        % Set axis properties
        axis(axesHandle, 'equal');
        grid(axesHandle, 'on');
        axesHandle.XGrid = 'on';
        axesHandle.YGrid = 'on';
        axesHandle.Box = 'on';
        xlim(axesHandle, [-maxMag, maxMag]);
        ylim(axesHandle, [-maxMag, maxMag]);
        
        % Add title and axis labels
        if isDiscrete
            title(axesHandle, ['Pole-Zero Map of Discrete-Time System ', sysName, '(z)']);
            xlabel(axesHandle, 'Real Axis');
            ylabel(axesHandle, 'Imaginary Axis');
        else
            title(axesHandle, ['Pole-Zero Map of Continuous-Time System ', sysName, '(s)']);
            xlabel(axesHandle, 'Real Axis (seconds^{-1})');
            ylabel(axesHandle, 'Imaginary Axis (seconds^{-1})');
        end
        
        % Create dummy patches for legend
        h_stable = patch(axesHandle, 'XData', NaN, 'YData', NaN, 'FaceColor', stableColor, 'EdgeColor', 'none');
        h_unstable = patch(axesHandle, 'XData', NaN, 'YData', NaN, 'FaceColor', unstableColor, 'EdgeColor', 'none');
        
        % Add legend
        legend(axesHandle, [h_stable, h_unstable, h_poles, h_zeros], ...
               {'Stable Region', 'Unstable Region', 'Poles', 'Zeros'}, ...
               'Location', 'best');
        
        % Turn off hold
        hold(axesHandle, 'off');
    end
end


    function build_G(app)
  % Get the button text as the function label
    label = 'G';

    % Store a temporary copy of the current G
    tempG = app.G;
    
    % Get the transfer function from the user
    newG = get_user_transfer_function();
    
    % If user didn't cancel, update app.G and display it
    if ~isempty(newG)
        app.G = newG;
        displayTransferFunctionInApp(app, app.G, label);
    else
        % User canceled, ensure app.G retains its original value
        app.G = tempG;
        % No need to update display as nothing changed
    end
    end

  function build_K(app)
    % Get the button text as the function label
    label = 'K';

    % Store the existing controller before calling get_user_controllerbuild_r
    existing_K = app.K;

    % Call User-Controller function and pass G directly
    if ~isempty(app.G)
        app.K = get_user_controller(app.G);
    else
        % If no plant defined, show warning
        app.K = get_user_controller(); % Call without G (Auto-Tuning will be deactivated)
    end
    
    % If user canceled (returned empty controller), keep the existing one
    if isempty(app.K) && ~isempty(existing_K)
        disp('Controller selection cancelled - keeping existing controller.');
        app.K = existing_K;
    end
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
    end
end


  function build_r(app)
    % Get the button text as the function label
    label = 'r';

    % Call the function without nested functions
    [app.r, app.r_num_sections, app.r_start_time, app.r_end_time] = get_target_value_version2();
    
    % In case r was defined already and is changed
    % the time vector changes as well
    % hence t needs to be defined new:
    app.t = create_linear_time_vector(app.r_num_sections);
    
    if ~isempty(app.r)
        displayTimeFunction(app, app.r, app.r_num_sections, label, app.r_start_time, app.r_end_time);
    end
  end


function saveAppVariables(app)
    % SAVEAPPVARIABLES - Save app variables to a file
    %
    % This improved function ensures that all necessary variables are saved,
    % including LaTeX string representations of time functions and their
    % time parameters.
    
    % List of variables to save, grouped by category
    % Basic transfer function variables
    basicVars = {'G', 'K', 'T', 'S', 'L', 'GS', 'KS'};
    
    % Reference signal variables
    refVars = {'r', 'r_num_sections', 'r_start_time', 'r_end_time', 'r_s', 'r_latex_string'};
    
    % Disturbance 1 variables
    d1Vars = {'d_1', 'd1_num_sections', 'd1_start_time', 'd1_end_time', 'd1_time_steps', 'd1_time_vector', 'd_1_s', 'd1_latex_string'};
    
    % Disturbance 2 variables
    d2Vars = {'d_2', 'd2_num_sections', 'd2_start_time', 'd2_end_time', 'd2_time_steps', 'd2_time_vector', 'd_2_s', 'd2_latex_string'};
    
    % Time and system state variables
    stateVars = {'t', 'timeDescription', 'cansysjump', 'char_eq'};
    
    % Combine all variable groups
    allVars = [basicVars, refVars, d1Vars, d2Vars, stateVars];
    
    % Initialize structure to save variables
    varsStruct = struct();

    % Assign values from app to the structure, with error handling for each variable
    for i = 1:length(allVars)
        varName = allVars{i};
        try
            if isprop(app, varName) && ~isempty(app.(varName))
                varsStruct.(varName) = app.(varName);
            end
        catch ME
            warning(['Error saving variable ' varName ': ' ME.message]);
        end
    end
    
    % Check if LaTeX string representations exist, and if not, try to generate them
    if ~isfield(varsStruct, 'r_latex_string') && isfield(varsStruct, 'r') && ~isempty(varsStruct.r)
        % Generate LaTeX string for r
        try
            syms t;
            if ~iscell(varsStruct.r)
                if isa(varsStruct.r, 'function_handle')
                    funcStr = func2str(varsStruct.r);
                    exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                    if ~isempty(exprStr) && ~isempty(exprStr{1})
                        timeFormula = str2sym(exprStr{1}{1});
                        latexTimeStr = latex(timeFormula);
                        varsStruct.r_latex_string = ['$$r(t) = ' latexTimeStr '$$'];
                    end
                end
            else
                % For piecewise function, construct a basic representation
                latexTimeStr = ['\left\{ \begin{array}{ll}'];
                for j = 1:varsStruct.r_num_sections
                    latexTimeStr = [latexTimeStr, ' f_', num2str(j), '(t) & ', ...
                        num2str(varsStruct.r_start_time(j)), ' \leq t \leq ', num2str(varsStruct.r_end_time(j)), ' \\[5pt] '];
                end
                latexTimeStr = [latexTimeStr, '\end{array}\right.'];
                varsStruct.r_latex_string = ['$$r(t) = ' latexTimeStr '$$'];
            end
        catch
            warning('Could not generate LaTeX string for r(t)');
        end
    end
    
    % Similar checks for d1 and d2 LaTeX strings
    % [Similar code for d1_latex_string and d2_latex_string would go here]
    
    % Prompt user for filename
    filename = inputdlg('Enter filename (without extension):', 'Save Variables');
    if isempty(filename)
        msgbox('No filename entered. Operation cancelled.', 'Error', 'error');
        return;
    end
    filename = filename{1};
    
    % Remove invalid characters from filename
    invalidChars = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
    for i = 1:length(invalidChars)
        filename = strrep(filename, invalidChars{i}, '');
    end
    
    % Save the structure to a .mat file
    try
        save([filename, '.mat'], '-struct', 'varsStruct');
        
        % Display confirmation message 
        msgbox('All done! Your variables are now saved!', 'Save Successful', 'help');
    catch ME
        msgbox(['Error saving file: ' ME.message], 'Save Error', 'error');
    end
end
        
function success = loadAppVariables(app)
    % LOADAPPVARIABLES loads the required variables from a .mat file.
    % Returns false if no file is selected.
    
    [file, path] = uigetfile('*.mat', 'Select a file');
    if isequal(file, 0)
        % No file selected - abort function
        success = false;
        return;
    end
    
    % Create the full file path and load the data
    fullFileName = fullfile(path, file);
    data = load(fullFileName);
    
    % Keep track of variables loaded successfully for debugging
    loadedVariables = {};
    
    % Transfer function variables
    if isfield(data, 'G')
        app.G = data.G;
        loadedVariables{end+1} = 'G';
    end
    
    if isfield(data, 'K')
        app.K = data.K;
        loadedVariables{end+1} = 'K';
    end
    
    % Reference signal and time parameters
    if isfield(data, 'r')
        app.r = data.r;
        loadedVariables{end+1} = 'r';
    end
    
    if isfield(data, 'r_num_sections')
        app.r_num_sections = data.r_num_sections;
        loadedVariables{end+1} = 'r_num_sections';
    end
    
    if isfield(data, 'r_start_time')
        app.r_start_time = data.r_start_time;
        loadedVariables{end+1} = 'r_start_time';
    end
    
    if isfield(data, 'r_end_time')
        app.r_end_time = data.r_end_time;
        loadedVariables{end+1} = 'r_end_time';
    end
    
    % Time vector
    if isfield(data, 't')
        app.t = data.t;
        loadedVariables{end+1} = 't';
    end
    
    % Disturbance 1 variables
    if isfield(data, 'd_1')
        app.d_1 = data.d_1;
        loadedVariables{end+1} = 'd_1';
    end
    
    if isfield(data, 'd1_num_sections')
        app.d1_num_sections = data.d1_num_sections;
        loadedVariables{end+1} = 'd1_num_sections';
    end
    
    if isfield(data, 'd1_start_time')
        app.d1_start_time = data.d1_start_time;
        loadedVariables{end+1} = 'd1_start_time';
    end
    
    if isfield(data, 'd1_end_time')
        app.d1_end_time = data.d1_end_time;
        loadedVariables{end+1} = 'd1_end_time';
    end
    
    if isfield(data, 'd1_time_steps')
        app.d1_time_steps = data.d1_time_steps;
        loadedVariables{end+1} = 'd1_time_steps';
    end
    
    if isfield(data, 'd1_time_vector')
        app.d1_time_vector = data.d1_time_vector;
        loadedVariables{end+1} = 'd1_time_vector';
    end
    
    % Disturbance 2 variables
    if isfield(data, 'd_2')
        app.d_2 = data.d_2;
        loadedVariables{end+1} = 'd_2';
    end
    
    if isfield(data, 'd2_num_sections')
        app.d2_num_sections = data.d2_num_sections;
        loadedVariables{end+1} = 'd2_num_sections';
    end
    
    if isfield(data, 'd2_start_time')
        app.d2_start_time = data.d2_start_time;
        loadedVariables{end+1} = 'd2_start_time';
    end
    
    if isfield(data, 'd2_end_time')
        app.d2_end_time = data.d2_end_time;
        loadedVariables{end+1} = 'd2_end_time';
    end
    
    if isfield(data, 'd2_time_steps')
        app.d2_time_steps = data.d2_time_steps;
        loadedVariables{end+1} = 'd2_time_steps';
    end
    
    if isfield(data, 'd2_time_vector')
        app.d2_time_vector = data.d2_time_vector;
        loadedVariables{end+1} = 'd2_time_vector';
    end
    
    % Load LaTeX string representations if available
    if isfield(data, 'r_latex_string')
        app.r_latex_string = data.r_latex_string;
        loadedVariables{end+1} = 'r_latex_string';
    end
    
    if isfield(data, 'd1_latex_string')
        app.d1_latex_string = data.d1_latex_string;
        loadedVariables{end+1} = 'd1_latex_string';
    end
    
    if isfield(data, 'd2_latex_string')
        app.d2_latex_string = data.d2_latex_string;
        loadedVariables{end+1} = 'd2_latex_string';
    end
    
    % Additional parameters that might be saved
    if isfield(data, 'r_s')
        app.r_s = data.r_s;
        loadedVariables{end+1} = 'r_s';
    end
    
    if isfield(data, 'd_1_s')
        app.d_1_s = data.d_1_s;
        loadedVariables{end+1} = 'd_1_s';
    end
    
    if isfield(data, 'd_2_s')
        app.d_2_s = data.d_2_s;
        loadedVariables{end+1} = 'd_2_s';
    end
    
    % Display debug info
    disp('Successfully loaded variables:');
    disp(loadedVariables);
    
    % Verify time vector is properly loaded or create it if necessary
    % (this is the key part for fixing the issue)
    if isempty(app.t) && ~isempty(app.r) && ~isempty(app.r_num_sections) && ...
       ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
        disp('Time vector not found in saved data. Creating from parameters...');
        % Store current time parameters for get_time_vector
        [~, ~, ~] = get_time_vector(app.r_num_sections, app.r_start_time, app.r_end_time, ones(size(app.r_start_time))*0.1);
        % Create time vector using stored parameters
        app.t = create_linear_time_vector(app.r_num_sections);
    end
    
    % Do the same for disturbance time vectors if needed
    if isempty(app.d1_time_vector) && ~isempty(app.d_1) && ~isempty(app.d1_num_sections) && ...
       ~isempty(app.d1_start_time) && ~isempty(app.d1_end_time) && ~isempty(app.d1_time_steps)
        disp('d1_time_vector not found. Creating from parameters...');
        [~, ~, ~] = get_time_vector_disturbance(app.d1_num_sections, app.d1_start_time, app.d1_end_time, app.d1_time_steps, 'd_1');
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, 'd_1');
    end
    
    if isempty(app.d2_time_vector) && ~isempty(app.d_2) && ~isempty(app.d2_num_sections) && ...
       ~isempty(app.d2_start_time) && ~isempty(app.d2_end_time) && ~isempty(app.d2_time_steps)
        disp('d2_time_vector not found. Creating from parameters...');
        [~, ~, ~] = get_time_vector_disturbance(app.d2_num_sections, app.d2_start_time, app.d2_end_time, app.d2_time_steps, 'd_2');
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, 'd_2');
    end
    
    % Successfully loaded variables
    success = true;
end

function [input_r, input_d1, input_d2] = prepareInputValues(app)
    % PREPAREINPUTVALUES - Bereitet die Eingabewerte für die Simulation vor
    % Diese Funktion wertet die Funktionen r, d_1 und d_2 für den gegebenen Zeitvektor aus,
    % unter Berücksichtigung, ob sie kontinuierlich oder stückweise definiert sind.
    
    % Initialisieren der Ausgabevektoren
    input_r = zeros(size(app.t));
    input_d1 = zeros(size(app.t));
    input_d2 = zeros(size(app.t));
    
    % Auswerten des Sollwerts
    if ~isempty(app.r)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.r)
            % Kontinuierlicher Fall
            if isa(app.r, 'function_handle')
                input_r = arrayfun(app.r, app.t);
            else
                % Konstanter Wert
                input_r = app.r * ones(size(app.t));
            end
        else
            % Stückweise Funktion
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Den aktiven Abschnitt für diesen Zeitpunkt finden
                active_section = 0;
                
                % Überprüfen, ob alle notwendigen Daten vorhanden sind
                if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && length(app.r) <= length(app.r_start_time)
                    for j = 1:length(app.r)
                        if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                            active_section = j;
                            break;
                        end
                    end
                end
                
                % Wenn kein aktiver Abschnitt gefunden wurde, den nächstgelegenen verwenden
                if active_section == 0 && ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
                    if current_time < app.r_start_time(1)
                        active_section = 1; % Zeit liegt vor dem ersten Abschnitt
                    elseif current_time > app.r_end_time(end)
                        active_section = length(app.r); % Zeit liegt nach dem letzten Abschnitt
                    end
                elseif active_section == 0 && length(app.r) > 0
                    active_section = 1; % Fallback: ersten Abschnitt verwenden
                end
                
                % Funktion auswerten, wenn ein aktiver Abschnitt gefunden wurde
                if active_section > 0 && active_section <= length(app.r)
                    if isa(app.r{active_section}, 'function_handle')
                        input_r(i) = app.r{active_section}(current_time);
                    else
                        input_r(i) = app.r{active_section};
                    end
                end
            end
        end
    end
    
    % Auswerten der ersten Störgröße
    if ~isempty(app.d_1)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_1)
            % Kontinuierlicher Fall
            if isa(app.d_1, 'function_handle')
                input_d1 = arrayfun(app.d_1, app.t);
            else
                % Konstanter Wert
                input_d1 = app.d_1 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei r
            % Da die Zeitbereiche für d_1 nicht explizit gespeichert werden, verwenden wir
            % die Zeitbereiche von r oder machen eine konservative Schätzung
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d1_num_sections > 0 && app.d1_num_sections <= length(app.d_1)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d1_num_sections <= length(app.r_start_time)
                        for j = 1:app.d1_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d1_num_sections;
                            for j = 1:app.d1_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_1)));
                
                % Funktion auswerten
                if isa(app.d_1{active_section}, 'function_handle')
                    input_d1(i) = app.d_1{active_section}(current_time);
                else
                    input_d1(i) = app.d_1{active_section};
                end
            end
        end
    end
    
    % Auswerten der zweiten Störgröße (gleicher Ansatz wie bei d_1)
    if ~isempty(app.d_2)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_2)
            % Kontinuierlicher Fall
            if isa(app.d_2, 'function_handle')
                input_d2 = arrayfun(app.d_2, app.t);
            else
                % Konstanter Wert
                input_d2 = app.d_2 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei d_1
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d2_num_sections > 0 && app.d2_num_sections <= length(app.d_2)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d2_num_sections <= length(app.r_start_time)
                        for j = 1:app.d2_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d2_num_sections;
                            for j = 1:app.d2_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_2)));
                
                % Funktion auswerten
                if isa(app.d_2{active_section}, 'function_handle')
                    input_d2(i) = app.d_2{active_section}(current_time);
                else
                    input_d2(i) = app.d_2{active_section};
                end
            end
        end
    end
end

        
function updateMonteCarloFields(app, paramName)
    % Get references to the relevant dropdown and edit fields
    if strcmp(paramName, 'Kp')
        distDropdown = app.KpDistDropdown;
        param1Edit = app.KpParam1Edit;
        param2Edit = app.KpParam2Edit;
        meanMinLabel = app.MeanMinLabel;
        stdMaxLabel = app.StdMaxLabel;
    elseif strcmp(paramName, 'Ki')
        distDropdown = app.KiDistDropdown;
        param1Edit = app.KiParam1Edit;
        param2Edit = app.KiParam2Edit;
        meanMinLabel = app.MeanMinLabel_2;
        stdMaxLabel = app.StdMaxLabel_2;
    elseif strcmp(paramName, 'Kd')
        distDropdown = app.KdDistDropdown;
        param1Edit = app.KdParam1Edit;
        param2Edit = app.KdParam2Edit;
        meanMinLabel = app.MeanMinLabel_3;
        stdMaxLabel = app.StdMaxLabel_3;
    else
        return;
    end
    
    % Update field availability based on distribution
    switch distDropdown.Value
        case 'Normal'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'on';
            
            % Update labels to reflect normal distribution parameters
            meanMinLabel.Text = 'Mean:';
            stdMaxLabel.Text = 'Std:';
            
        case 'Uniform'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'on';
            
            % Update labels to reflect uniform distribution parameters
            meanMinLabel.Text = 'Min:';
            stdMaxLabel.Text = 'Max:';
            
        case 'Fixed'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'off';
            
            % Update labels to reflect fixed value
            meanMinLabel.Text = 'Value:';
            stdMaxLabel.Text = 'N/A:';
    end
end
        
function runSensitivityAnalysis(app)
    % RUNSENSITIVITYANALYSIS - Performs parameter sensitivity analysis
    % This function analyzes how changes in a selected parameter affect system performance
    
    % Check if G and K are defined
    if isempty(app.G) || isempty(app.K)
        uialert(app.UIFigure, 'Please define system G(s) and controller K(s) first.', 'Missing System');
        return;
    end
    
    % Get analysis parameters
    selectedParam = app.SensitivityParamDropdown.Value;
    variationRange = app.SensitivityRangeEdit.Value / 100; % Convert from percentage
    numPoints = app.SensitivityPointsEdit.Value;
    metric = app.SensitivityMetricDropdown.Value;
    
    % Create parameter variations
    variations = linspace(-variationRange, variationRange, numPoints);
    
    % Initialize result arrays
    metricValues = zeros(size(variations));
    baseValue = 0;
    
    % Display status
    app.SensitivityHTML.HTMLSource = createHtmlContent('Running sensitivity analysis...', ...
        ['Parameter: ' selectedParam], ...
        ['Metric: ' metric]);
    drawnow;
    
    try
        % Extract controller parameters
        [num_K, den_K] = tfdata(app.K, 'v');
        [num_G, den_G] = tfdata(app.G, 'v');
        
        % Calculate the nominal (base) system performance first
        nominal_K = app.K;
        nominal_G = app.G;
        [T_nominal, ~, ~] = transferfunctions(nominal_G, nominal_K);
        
        % Calculate base metric value
        baseValue = calculatePerformanceMetric(T_nominal, metric);
        
        if isnan(baseValue) || isinf(baseValue)
            error('Base system performance metric cannot be calculated. Please check system stability or select a different metric.');
        end
        
        % Loop through variations
        for i = 1:length(variations)
            % Create modified controller or plant based on selected parameter
            K_modified = app.K;
            G_modified = app.G;
            
            paramValue = variations(i);
            
            switch selectedParam
                case 'Kp (Controller Gain)'
                    if length(num_K) >= 1
                        K_modified = tf(num_K * (1 + paramValue), den_K);
                    end
                    
                case 'Ki (Integral Gain)'
                    % Find Ki in controller
                    if length(num_K) >= 2 && length(den_K) >= 2 && den_K(end) == 0
                        % For controllers with integrator Ki is typically the last coefficient
                        num_K_modified = num_K;
                        num_K_modified(end) = num_K(end) * (1 + paramValue);
                        K_modified = tf(num_K_modified, den_K);
                    end
                    
                case 'Kd (Derivative Gain)'
                    % For controllers with derivative term
                    if length(num_K) >= 2
                        num_K_modified = num_K;
                        num_K_modified(1) = num_K(1) * (1 + paramValue);
                        K_modified = tf(num_K_modified, den_K);
                    end
                    
                case 'Plant Gain'
                    G_modified = tf(num_G * (1 + paramValue), den_G);
                    
                case 'Time Constant'
                    % Approximate first-order time constant modification
                    % Assumes the first coefficient in denominator relates to time constant
                    if length(den_G) >= 2
                        den_G_modified = den_G;
                        den_G_modified(1) = den_G(1) * (1 + paramValue);
                        G_modified = tf(num_G, den_G_modified);
                    end
            end
            
            % Calculate modified closed-loop system
            [T_modified, ~, ~] = transferfunctions(G_modified, K_modified);
            
            % Calculate the selected performance metric
            metricValues(i) = calculatePerformanceMetric(T_modified, metric);
            
            % Handle problematic values
            if isnan(metricValues(i)) || isinf(metricValues(i))
                metricValues(i) = NaN;
            end
        end
        
        % Remove any NaN values for calculation
        validIndices = ~isnan(metricValues) & ~isnan(variations);
        
        % Calculate normalized sensitivity - percentage change in metric per percentage change in parameter
        if any(validIndices) && sum(validIndices) > 2
            % Calculate sensitivity coefficient using central difference
            normalizedMetrics = (metricValues(validIndices) - baseValue) / baseValue;
            validVariations = variations(validIndices);
            
            % Use polyfit to get the slope at the origin
            if length(normalizedMetrics) >= 3
                p = polyfit(validVariations, normalizedMetrics, 1);
                sensitivityCoefficient = p(1);
            else
                sensitivityCoefficient = NaN;
            end
        else
            sensitivityCoefficient = NaN;
        end
        
        % Enhanced plotting section for sensitivity analysis
        cla(app.SensitivityUIAxes);

        % Plot non-NaN values only
        validIdx = ~isnan(metricValues);
        if sum(validIdx) <= 1
            % Not enough valid points to plot a line
            text(app.SensitivityUIAxes, 0.5, 0.5, 'Insufficient valid data points for plotting', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', 'FontSize', 14, 'Color', 'red');
        else
            % Add special handling for Gain Margin
            if strcmp(metric, 'Gain Margin')
                % Plot with appropriate scaling for gain margin in dB
                plot(app.SensitivityUIAxes, variations(validIdx) * 100, metricValues(validIdx), 'LineWidth', 2);
                hold(app.SensitivityUIAxes, 'on');
                plot(app.SensitivityUIAxes, 0, baseValue, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
                
                % Add horizontal line at 0 dB (marginally stable system)
                yline(app.SensitivityUIAxes, 0, 'r--', 'Marginally Stable', 'LineWidth', 1.5, 'Alpha', 0.7);
                
                % Add horizontal line at 6 dB (recommended minimum)
                yline(app.SensitivityUIAxes, 6, 'g--', 'Recommended Min (6 dB)', 'LineWidth', 1.5, 'Alpha', 0.7);
                
                % Set appropriate y-axis limits
                validValues = metricValues(validIdx);
                yMin = min([0, min(validValues)]) - 2;
                yMax = max([10, max(validValues)]) + 2;
                ylim(app.SensitivityUIAxes, [yMin, yMax]);
                
                hold(app.SensitivityUIAxes, 'off');
                
                % Add labels and title
                title(app.SensitivityUIAxes, ['Gain Margin Sensitivity to ' selectedParam]);
                xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)');
                ylabel(app.SensitivityUIAxes, 'Gain Margin (dB)');
                
            else
                % Standard plotting for other metrics
                plot(app.SensitivityUIAxes, variations(validIdx) * 100, metricValues(validIdx), 'LineWidth', 2);
                hold(app.SensitivityUIAxes, 'on');
                plot(app.SensitivityUIAxes, 0, baseValue, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
                hold(app.SensitivityUIAxes, 'off');
                
                % Add labels with appropriate units based on metric
                title(app.SensitivityUIAxes, ['Sensitivity Analysis for ' selectedParam]);
                xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)');
                
                % Add appropriate units to y-axis based on metric
                switch metric
                    case 'Settling Time'
                        ylabel(app.SensitivityUIAxes, 'Settling Time (s)');
                    case 'Overshoot'
                        ylabel(app.SensitivityUIAxes, 'Overshoot (%)');
                    case 'Rise Time'
                        ylabel(app.SensitivityUIAxes, 'Rise Time (s)');
                    case 'Bandwidth'
                        ylabel(app.SensitivityUIAxes, 'Bandwidth (rad/s)');
                    case 'Phase Margin'
                        ylabel(app.SensitivityUIAxes, 'Phase Margin (degrees)');
                    otherwise
                        ylabel(app.SensitivityUIAxes, metric);
                end
                
                % Ensure axes have reasonable limits
                if ~all(isnan(metricValues))
                    validValues = metricValues(~isnan(metricValues));
                    if ~isempty(validValues)
                        yMin = min(validValues) * 0.9;
                        yMax = max(validValues) * 1.1;
                        if yMin ~= yMax
                            ylim(app.SensitivityUIAxes, [yMin, yMax]);
                        end
                    end
                end
            end
            
            grid(app.SensitivityUIAxes, 'on');
        end

        % Update results in HTML display
        if ~isnan(sensitivityCoefficient)
            sensText = num2str(sensitivityCoefficient, '%.3f');
            interpretation = interpretSensitivityCoefficient(sensitivityCoefficient, selectedParam, metric);
        else
            sensText = 'N/A (Could not calculate)';
            interpretation = 'Could not determine sensitivity coefficient. The system may become unstable for some parameter values.';
        end
        
        app.SensitivityHTML.HTMLSource = createHtmlContent('Sensitivity Analysis Results:', ...
            ['Parameter: ' selectedParam], ...
            ['Base Value: ' num2str(baseValue, '%.4g') getMetricUnit(metric)], ...
            ['Sensitivity Coefficient: ' sensText], ...
            '', ...
            'Interpretation:', ...
            interpretation);
        
    catch ME
        app.SensitivityHTML.HTMLSource = createHtmlContent('Error in sensitivity analysis:', ...
            ME.message, '', ...
            'Tips for troubleshooting:', ...
            '- Check if the system becomes unstable for the parameter range', ...
            '- Reduce the variation range', ...
            '- Try a different performance metric');
        
        % Create an empty plot with error message
        cla(app.SensitivityUIAxes);
        text(app.SensitivityUIAxes, 0.5, 0.5, {'Error performing sensitivity analysis', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end


function runMonteCarloSimulation(app)
    % RUNMONTECARLSIMULATION - Performs Monte Carlo simulations to analyze robustness
    % This enhanced function runs multiple simulations with randomly varied parameters
    % and displays results in a professionally styled HTML table
    
    % Check if G and K are defined
    if isempty(app.G) || isempty(app.K)
        uialert(app.UIFigure, 'Please define system G(s) and controller K(s) first.', 'Missing System');
        return;
    end
    
    % Get simulation parameters
    numSimulations = app.NumSimulationsEdit.Value;
    selectedMetric = app.MonteCarloMetricDropdown.Value;
    
    % Initialize parameter settings structure
    paramSettings = struct();
    
    % Kp distribution
    paramSettings.Kp.dist = app.KpDistDropdown.Value;
    paramSettings.Kp.param1 = app.KpParam1Edit.Value;
    paramSettings.Kp.param2 = app.KpParam2Edit.Value;
    
    % Ki distribution
    paramSettings.Ki.dist = app.KiDistDropdown.Value;
    paramSettings.Ki.param1 = app.KiParam1Edit.Value;
    paramSettings.Ki.param2 = app.KiParam2Edit.Value;
    
    % Kd distribution
    paramSettings.Kd.dist = app.KdDistDropdown.Value;
    paramSettings.Kd.param1 = app.KdParam1Edit.Value;
    paramSettings.Kd.param2 = app.KdParam2Edit.Value;
    
    % Display status
    app.MonteCarloHTML.HTMLSource = createHtmlContent('Running Monte Carlo simulation...', ...
        ['Number of Simulations: ' num2str(numSimulations)]);
    drawnow;
    
    try
        % Extract nominal controller parameters - assuming PID structure
        [num_K, den_K] = tfdata(app.K, 'v');
        
        % Determine controller type based on transfer function structure
        controllerType = determineControllerType(app.K);
        
        % Initialize result arrays
        settlingTimeValues = zeros(numSimulations, 1);
        overshootValues = zeros(numSimulations, 1);
        riseTimeValues = zeros(numSimulations, 1);
        
        % Time vector for step response
        t = linspace(0, 10, 100);
        responsesMatrix = zeros(numSimulations, length(t));
        
        % Create waitbar instead of uiprogressbar
        waitbarHandle = waitbar(0, 'Running Monte Carlo simulations...', ...
            'Name', 'Monte Carlo Progress');
        
        % Store parameters for each simulation (for statistical analysis)
        Kp_values = zeros(numSimulations, 1);
        Ki_values = zeros(numSimulations, 1);
        Kd_values = zeros(numSimulations, 1);
        
        % Count successful simulations
        successCount = 0;
        
        % Run simulations
        for i = 1:numSimulations
            try
                % Generate random parameter values
                Kp = generateRandomParameter(paramSettings.Kp);
                Ki = generateRandomParameter(paramSettings.Ki);
                Kd = generateRandomParameter(paramSettings.Kd);
                
                % Store parameter values
                Kp_values(i) = Kp;
                Ki_values(i) = Ki;
                Kd_values(i) = Kd;
                
                % Create modified controller based on controller type
                switch controllerType
                    case 'P'
                        K_modified = tf(Kp, 1);
                    case 'PI'
                        K_modified = tf([Kp, Ki], [1, 0]);
                    case 'PD'
                        % Add filter for derivative term
                        epsilon = 0.1;
                        K_modified = tf([Kd, Kp], [epsilon*Kd, 1]);
                    case 'PID'
                        % Add filter for derivative term
                        epsilon = 0.1;
                        K_modified = tf([Kd, Kp, Ki], [epsilon*Kd, 1, 0]);
                    otherwise
                        % Unknown controller type - use original with scaled gains
                        K_modified = tf(num_K * Kp, den_K);
                end
                
                % Calculate closed-loop system
                T_modified = feedback(app.G * K_modified, 1);
                
                % Simulate step response
                [y, ~] = step(T_modified, t);
                responsesMatrix(i, :) = y;
                
                % Calculate performance metrics
                info = stepinfo(T_modified);
                settlingTimeValues(i) = info.SettlingTime;
                overshootValues(i) = info.Overshoot;
                riseTimeValues(i) = info.RiseTime;
                
                % Increment success counter
                successCount = successCount + 1;
            catch ME
                % Handle error in this simulation
                disp(['Error in simulation ' num2str(i) ': ' ME.message]);
                settlingTimeValues(i) = NaN;
                overshootValues(i) = NaN;
                riseTimeValues(i) = NaN;
                responsesMatrix(i, :) = NaN(1, length(t));
            end
            
            % Update waitbar
            if ishandle(waitbarHandle)
                waitbar(i/numSimulations, waitbarHandle, ...
                    sprintf('Running simulations... %d/%d', i, numSimulations));
            end
        end
        
        % Close waitbar if it still exists
        if ishandle(waitbarHandle)
            close(waitbarHandle);
        end
        
        % Clear axes for plotting and completely reset properties
        cla(app.MonteCarloHistUIAxes);
        cla(app.MonteCarloResponseUIAxes);
        
        % Complete reset of the histogram axes - much more thorough
        cla(app.MonteCarloHistUIAxes, 'reset');  % Complete reset of axis properties
        
        % Make sure the axis is visible and has grid
        app.MonteCarloHistUIAxes.Visible = 'on';
        app.MonteCarloHistUIAxes.XGrid = 'on';
        app.MonteCarloHistUIAxes.YGrid = 'on';
        
        % Remove NaN values for statistics
        validIndices = ~isnan(settlingTimeValues);
        validSettling = settlingTimeValues(validIndices);
        validOvershoot = overshootValues(validIndices);
        validRiseTime = riseTimeValues(validIndices);
        
        % Check if we have enough valid results
        if successCount < 1
            error('All simulations failed. Try different parameter ranges or check system stability.');
        end
        
        % Get valid parameter values
        validKp = Kp_values(validIndices);
        validKi = Ki_values(validIndices);
        validKd = Kd_values(validIndices);
        
        % Compute correlation matrices for all metrics
        corrMatrices = struct();
        
        % For settling time
        corrMatrices.SettlingTime.Kp = calculateCorrelation(validKp, validSettling);
        corrMatrices.SettlingTime.Ki = calculateCorrelation(validKi, validSettling);
        corrMatrices.SettlingTime.Kd = calculateCorrelation(validKd, validSettling);
        
        % For overshoot
        corrMatrices.Overshoot.Kp = calculateCorrelation(validKp, validOvershoot);
        corrMatrices.Overshoot.Ki = calculateCorrelation(validKi, validOvershoot);
        corrMatrices.Overshoot.Kd = calculateCorrelation(validKd, validOvershoot);
        
        % For rise time
        corrMatrices.RiseTime.Kp = calculateCorrelation(validKp, validRiseTime);
        corrMatrices.RiseTime.Ki = calculateCorrelation(validKi, validRiseTime);
        corrMatrices.RiseTime.Kd = calculateCorrelation(validKd, validRiseTime);
        
        % Calculate statistics for all metrics for comprehensive display
        % Settling Time statistics
        meanST = mean(validSettling);
        stdST = std(validSettling);
        minST = min(validSettling);
        maxST = max(validSettling);
        covST = 100*stdST/meanST;
        
        % Overshoot statistics
        meanOS = mean(validOvershoot);
        stdOS = std(validOvershoot);
        minOS = min(validOvershoot);
        maxOS = max(validOvershoot);
        covOS = 100*stdOS/meanOS;
        
        % Rise Time statistics
        meanRT = mean(validRiseTime);
        stdRT = std(validRiseTime);
        minRT = min(validRiseTime);
        maxRT = max(validRiseTime);
        covRT = 100*stdRT/meanRT;
        
        % Create metrics data for comprehensive table
        metricsData = {
            {'Settling Time', formatNumber(meanST, 3), formatNumber(stdST, 3), formatNumber(covST, 1), 's', formatNumber(minST, 3), formatNumber(maxST, 3)},
            {'Overshoot', formatNumber(meanOS, 2), formatNumber(stdOS, 2), formatNumber(covOS, 1), '%', formatNumber(minOS, 2), formatNumber(maxOS, 2)},
            {'Rise Time', formatNumber(meanRT, 3), formatNumber(stdRT, 3), formatNumber(covRT, 1), 's', formatNumber(minRT, 3), formatNumber(maxRT, 3)}
        };
        
        % Create correlation data for comprehensive table
        correlationData = {
            {'Settling Time', formatNumber(corrMatrices.SettlingTime.Kp, 2), formatNumber(corrMatrices.SettlingTime.Ki, 2), formatNumber(corrMatrices.SettlingTime.Kd, 2)},
            {'Overshoot', formatNumber(corrMatrices.Overshoot.Kp, 2), formatNumber(corrMatrices.Overshoot.Ki, 2), formatNumber(corrMatrices.Overshoot.Kd, 2)},
            {'Rise Time', formatNumber(corrMatrices.RiseTime.Kp, 2), formatNumber(corrMatrices.RiseTime.Ki, 2), formatNumber(corrMatrices.RiseTime.Kd, 2)}
        };
        
        % Create comprehensive HTML results table only for Correlation Matrix view
        htmlComprehensive = createComprehensiveResultsTable(successCount, numSimulations, metricsData, correlationData);
        
        % Plot based on selected metric type
        switch selectedMetric
            case 'Settling Time'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Settling Time Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Settling Time (s)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validSettling);
                
                % Calculate 95% confidence interval for Settling Time
                ci95 = [meanST - 1.96*stdST/sqrt(length(validSettling)), ...
                       meanST + 1.96*stdST/sqrt(length(validSettling))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanST, 3), 's'},
                    {'Std Dev', formatNumber(stdST, 3), 's'},
                    {'95% CI', [formatNumber(ci95(1), 3) ' - ' formatNumber(ci95(2), 3)], 's'},
                    {'Min', formatNumber(minST, 3), 's'},
                    {'Max', formatNumber(maxST, 3), 's'},
                    {'Median', formatNumber(median(validSettling), 3), 's'},
                    {'CoV', formatNumber(covST, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.SettlingTime.Kp, 2), getCorrelationStrength(corrMatrices.SettlingTime.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.SettlingTime.Ki, 2), getCorrelationStrength(corrMatrices.SettlingTime.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.SettlingTime.Kd, 2), getCorrelationStrength(corrMatrices.SettlingTime.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Overshoot'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Overshoot Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Overshoot (%)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validOvershoot);
                
                % Calculate 95% confidence interval for Overshoot
                ci95 = [meanOS - 1.96*stdOS/sqrt(length(validOvershoot)), ...
                       meanOS + 1.96*stdOS/sqrt(length(validOvershoot))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanOS, 2), '%'},
                    {'Std Dev', formatNumber(stdOS, 2), '%'},
                    {'95% CI', [formatNumber(ci95(1), 2) ' - ' formatNumber(ci95(2), 2)], '%'},
                    {'Min', formatNumber(minOS, 2), '%'},
                    {'Max', formatNumber(maxOS, 2), '%'},
                    {'Median', formatNumber(median(validOvershoot), 2), '%'},
                    {'CoV', formatNumber(covOS, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.Overshoot.Kp, 2), getCorrelationStrength(corrMatrices.Overshoot.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.Overshoot.Ki, 2), getCorrelationStrength(corrMatrices.Overshoot.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.Overshoot.Kd, 2), getCorrelationStrength(corrMatrices.Overshoot.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Rise Time'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Rise Time Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Rise Time (s)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validRiseTime);
                
                % Calculate 95% confidence interval for Rise Time
                ci95 = [meanRT - 1.96*stdRT/sqrt(length(validRiseTime)), ...
                       meanRT + 1.96*stdRT/sqrt(length(validRiseTime))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanRT, 3), 's'},
                    {'Std Dev', formatNumber(stdRT, 3), 's'},
                    {'95% CI', [formatNumber(ci95(1), 3) ' - ' formatNumber(ci95(2), 3)], 's'},
                    {'Min', formatNumber(minRT, 3), 's'},
                    {'Max', formatNumber(maxRT, 3), 's'},
                    {'Median', formatNumber(median(validRiseTime), 3), 's'},
                    {'CoV', formatNumber(covRT, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.RiseTime.Kp, 2), getCorrelationStrength(corrMatrices.RiseTime.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.RiseTime.Ki, 2), getCorrelationStrength(corrMatrices.RiseTime.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.RiseTime.Kd, 2), getCorrelationStrength(corrMatrices.RiseTime.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Correlation Matrix'
                % Create correlation matrix for visualization
                corrMatrix = zeros(3, 3);
                
                % Fill correlation matrix with values
                corrMatrix(1, 1) = corrMatrices.SettlingTime.Kp;
                corrMatrix(1, 2) = corrMatrices.SettlingTime.Ki;
                corrMatrix(1, 3) = corrMatrices.SettlingTime.Kd;
                
                corrMatrix(2, 1) = corrMatrices.Overshoot.Kp;
                corrMatrix(2, 2) = corrMatrices.Overshoot.Ki;
                corrMatrix(2, 3) = corrMatrices.Overshoot.Kd;
                
                corrMatrix(3, 1) = corrMatrices.RiseTime.Kp;
                corrMatrix(3, 2) = corrMatrices.RiseTime.Ki;
                corrMatrix(3, 3) = corrMatrices.RiseTime.Kd;
                
                % Create a heatmap visualization in the histogram axis
                imagesc(app.MonteCarloHistUIAxes, corrMatrix);
                colormap(app.MonteCarloHistUIAxes, redblue(21)); % Red-blue colormap centered at zero
                colorbar(app.MonteCarloHistUIAxes);
                caxis(app.MonteCarloHistUIAxes, [-1 1]); % Set color scale from -1 to 1
                
                % Set axis labels
                set(app.MonteCarloHistUIAxes, 'XTick', 1:3, 'XTickLabel', {'Kp', 'Ki', 'Kd'});
                set(app.MonteCarloHistUIAxes, 'YTick', 1:3, 'YTickLabel', {'Settling Time', 'Overshoot', 'Rise Time'});
                
                % Add correlation values as text in each cell
                for i = 1:3
                    for j = 1:3
                        textColor = 'k'; % Default text color (black)
                        
                        % Make text white for dark background cells (strong correlations)
                        if abs(corrMatrix(i, j)) > 0.7
                            textColor = 'w';
                        end
                        
                        % Add text with correlation value to cell
                        text(app.MonteCarloHistUIAxes, j, i, num2str(corrMatrix(i, j), '%.2f'), ...
                            'HorizontalAlignment', 'center', 'Color', textColor, 'FontWeight', 'bold');
                    end
                end
                
                title(app.MonteCarloHistUIAxes, 'Parameter-Metric Correlation Matrix');
                
                % ONLY for Correlation Matrix: Update HTML with comprehensive table
                app.MonteCarloHTML.HTMLSource = htmlComprehensive;
        end
        
        % Plot all valid response curves
        validRows = find(~any(isnan(responsesMatrix), 2));
        
        if ~isempty(validRows)
            % Hold for multiple plots
            hold(app.MonteCarloResponseUIAxes, 'on');
            
            % Create a colormap with enough colors for all valid simulations
            numValidSimulations = length(validRows);
            colorMap = jet(numValidSimulations);
            
            % Optimized rendering approach for large numbers of plots
            % For efficiency with large datasets, we handle plotting differently
            if numValidSimulations > 100
                % Split the data into smaller batches and plot multiple lines at once
                % This is much more efficient than individual plot calls
                BATCH_SIZE = 20;  % Process in batches of 20 for better performance
                numBatches = ceil(numValidSimulations / BATCH_SIZE);
                
                for batch = 1:numBatches
                    startIdx = (batch-1)*BATCH_SIZE + 1;
                    endIdx = min(batch*BATCH_SIZE, numValidSimulations);
                    batchIndices = startIdx:endIdx;
                    
                    if endIdx >= startIdx
                        % Extract batch data
                        batchMatrix = zeros(length(t), length(batchIndices));
                        for i = 1:length(batchIndices)
                            rowIdx = validRows(batchIndices(i));
                            batchMatrix(:, i) = responsesMatrix(rowIdx, :)';
                        end
                        
                        % Plot the batch
                        for i = 1:length(batchIndices)
                            plot(app.MonteCarloResponseUIAxes, t, batchMatrix(:, i), ...
                                'LineWidth', 0.5, 'Color', colorMap(batchIndices(i), :));
                        end
                        drawnow;  % Update display after each batch
                    end
                end
            else
                % For smaller datasets, plot each line individually
                for i = 1:numValidSimulations
                    plot(app.MonteCarloResponseUIAxes, t, responsesMatrix(validRows(i), :), ...
                        'LineWidth', 1, 'Color', colorMap(i, :));
                end
            end
            
            % Display the nominal response with a thicker black line if available
            try
                nominalSystem = feedback(app.G * app.K, 1);
                [nominalResponse, ~] = step(nominalSystem, t);
                plot(app.MonteCarloResponseUIAxes, t, nominalResponse, 'b-', 'LineWidth', 2, 'DisplayName', 'Nominal');
                legend(app.MonteCarloResponseUIAxes, 'Nominal Response');
            catch
                % Skip nominal response if it can't be calculated
            end
            
            % Add a grid and improve the visual appearance
            grid(app.MonteCarloResponseUIAxes, 'on');
            title(app.MonteCarloResponseUIAxes, sprintf('System Responses (All %d Valid Simulations)', numValidSimulations));
            xlabel(app.MonteCarloResponseUIAxes, 'Time (s)');
            ylabel(app.MonteCarloResponseUIAxes, 'Output');
            
            % Add a border around the plot to improve visibility
            box(app.MonteCarloResponseUIAxes, 'on');
            
            hold(app.MonteCarloResponseUIAxes, 'off');
        else
            % No valid responses to plot
            text(app.MonteCarloResponseUIAxes, 0.5, 0.5, 'No valid responses to display', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized');
        end
        
    catch ME
        % Handle any general errors
        if exist('waitbarHandle', 'var') && ishandle(waitbarHandle)
            close(waitbarHandle);
        end
        
        app.MonteCarloHTML.HTMLSource = createHtmlContent('Error in Monte Carlo simulation:', ...
            ME.message, '', ...
            'Tips for troubleshooting:', ...
            '- Check parameter ranges', ...
            '- Ensure the controller structure is correctly identified', ...
            '- Try fewer simulations to start');
        
        % Create empty plots
        cla(app.MonteCarloHistUIAxes);
        cla(app.MonteCarloResponseUIAxes);
        
        text(app.MonteCarloHistUIAxes, 0.5, 0.5, {'Error in Monte Carlo simulation', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end
        
        
function displaySavedTimeFunction(app, latex_string, label)
    % DISPLAYSAVEDTIMEFUNCTION
    % ----------------------
    % This function displays previously saved LaTeX string representations
    % of time functions when loading the app state.
    %
    % Inputs:
    %   app          - The app object containing UI components
    %   latex_string - The LaTeX string representation of the function
    %   label        - Function label (e.g., 'r', 'd_1', etc.)
    
    try
        % Get the target UIAxes component
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        
        % Clear the axes and turn off axis lines
        cla(uiAxesHandle);
        axis(uiAxesHandle, 'off');
        
        % Display the saved LaTeX formula
        text(uiAxesHandle, 0.5, 0.5, latex_string, ...
            'Interpreter', 'latex', 'FontSize', 13, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
        
        % Store the LaTeX string in the appropriate app property
        latex_prop_name = '';
        switch label
            case 'r'
                app.r_latex_string = latex_string;
            case 'd_1'
                app.d1_latex_string = latex_string;
            case 'd_2'
                app.d2_latex_string = latex_string;
        end
    catch ME
        % Handle errors gracefully
        disp(['Error displaying saved time function: ' ME.message]);
        disp(['Error details: ', getReport(ME, 'basic')]);
        
        % Get the target UIAxes
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(t)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end


end
        
function updateAnalysisDisplay(app, analysisType)
    % UPDATEANALYSISDISPLAY - Updates analysis display based on selected analysis type
    % 
    % This enhanced function updates the UI elements with proper validation
    % and provides more helpful default values for each analysis type.
    
    try
        
        % Update the info panel with guidance
        try
            currentHTML = app.StateSpaceInfoHTML.HTMLSource;
            
            % Remove any existing guidance to avoid stacking
            if contains(currentHTML, 'Analysis Type Selected:')
                currentHTML = regexprep(currentHTML, '<div id="analysis-guidance".*?</div>', '');
            end
            
            % Create guidance message based on analysis type
            guidanceHTML = ['<div id="analysis-guidance" style="color: blue; text-align: center; margin-top: 10px; padding: 8px; border: 1px solid blue; border-radius: 5px;">', ...
                           'Analysis Type Selected: <b>', analysisType, '</b><br>'];
            
            % Add type-specific guidance
            switch analysisType
                case 'Eigenvalues'
                    guidanceHTML = [guidanceHTML, 'This will analyze system stability and dynamic behavior based on eigenvalues.'];
                    
                case 'Step Response'
                    guidanceHTML = [guidanceHTML, 'This will show how the system responds to a step input.<br>', ...
                                   'You can specify a custom time vector in the format: t=start:step:end'];
                    
                case 'Impulse Response'
                    guidanceHTML = [guidanceHTML, 'This will show how the system responds to an impulse input.<br>', ...
                                   'You can specify a custom time vector in the format: t=start:step:end'];
                    
                case 'Phase Portrait'
                    guidanceHTML = [guidanceHTML, 'This will create a phase portrait showing state trajectories (only for 2-state systems).'];
                    
                case 'Controllability'
                    guidanceHTML = [guidanceHTML, 'This will analyze if all system states can be controlled using the available inputs.'];
                    
                case 'Observability'
                    guidanceHTML = [guidanceHTML, 'This will analyze if all system states can be observed using the available outputs.'];
            end
            
            guidanceHTML = [guidanceHTML, '<br>Click "Run Analysis" to proceed.'];
            guidanceHTML = [guidanceHTML, '</div>'];
            
            % Append guidance to HTML
            app.StateSpaceInfoHTML.HTMLSource = [currentHTML, guidanceHTML];
            
        catch ME
            % If HTML update fails, just log it (don't interrupt the UI update)
            disp(['Warning: Could not update info panel: ' ME.message]);
        end
        
    catch ME
        % Log errors but don't disrupt the UI
        disp(['Error updating analysis display: ' ME.message]);
    end
end
        
function [sys_tf, success, errorMsg] = convertSS2TF(app, ssModel, inputIdx, outputIdx)
    % CONVERTSS2TF - Robust conversion from state-space to transfer function
    % 
    % Parameters:
    %   app - App object
    %   ssModel - State-space model (ss object)
    %   inputIdx - Input index for MIMO systems
    %   outputIdx - Output index for MIMO systems
    % 
    % Returns:
    %   sys_tf - Resulting transfer function
    %   success - Boolean indicating success
    %   errorMsg - Error message if conversion failed
    
    % Initialize return values
    success = true;
    errorMsg = '';
    sys_tf = [];
    
    try
        % Check if ssModel is valid
        if ~isa(ssModel, 'ss')
            errorMsg = 'Input must be a valid state-space (ss) model';
            success = false;
            return;
        end
        
        % Extract state-space matrices for manual conversion if needed
        A = ssModel.A;
        B = ssModel.B;
        C = ssModel.C;
        D = ssModel.D;
        Ts = ssModel.Ts;
        
        % Get system dimensions
        [n_states, n_inputs] = size(B);
        [n_outputs, ~] = size(C);
        
        % Validate input/output indices
        if inputIdx > n_inputs
            errorMsg = ['Input index ' num2str(inputIdx) ' exceeds the number of inputs (' num2str(n_inputs) ')'];
            success = false;
            return;
        end
        
        if outputIdx > n_outputs
            errorMsg = ['Output index ' num2str(outputIdx) ' exceeds the number of outputs (' num2str(n_outputs) ')'];
            success = false;
            return;
        end
        
        % Use ss2tf which is more reliable than direct tf() conversion
        try
            disp('Converting using ss2tf...');
            [num, den] = ss2tf(A, B, C, D, inputIdx);
            
            % For MIMO outputs, extract the specific output row
            if n_outputs > 1
                num = num(outputIdx, :);
            end
            
            % Create transfer function with sampling time
            sys_tf = tf(num, den, Ts);
            disp('ss2tf conversion successful');
        catch ss2tf_error
            disp(['ss2tf conversion failed: ' ss2tf_error.message]);
            
            % Try alternative method
            try
                disp('Attempting alternative conversion method...');
                
                % Extract the specific input/output matrices
                B_col = B(:, inputIdx);
                C_row = C(outputIdx, :);
                D_val = D(outputIdx, inputIdx);
                
                % Manual calculation using formula G(s) = C(sI-A)^-1B + D
                if Ts == 0  % Continuous-time system
                    s = tf('s');
                    I = eye(size(A));
                    sys_tf = C_row * inv(s*I - A) * B_col + D_val;
                else  % Discrete-time system
                    z = tf('z', Ts);
                    I = eye(size(A));
                    sys_tf = C_row * inv(z*I - A) * B_col + D_val;
                end
                disp('Alternative conversion successful');
            catch alt_error
                errorMsg = ['Could not convert to transfer function: ' alt_error.message];
                success = false;
                return;
            end
        end
        
        % Clean up numerical noise in coefficients
        [num, den] = tfdata(sys_tf, 'v');
        
        % Clean up coefficients
        num_clean = cleanup_tf_coefficients(num);
        den_clean = cleanup_tf_coefficients(den);
        
        % Create clean transfer function
        sys_tf = tf(num_clean, den_clean, Ts);
        
    catch ME
        % Handle unexpected errors
        success = false;
        errorMsg = ['Conversion to transfer function failed: ' ME.message];
        disp(['Error in convertSS2TF: ' ME.message]);
        disp(['Stack trace: ' getReport(ME, 'extended')]);
    end
end


function updateStateSpaceInfo(app, model, isNonlinear)
    % UPDATESTATESPACEINFO - Creates an enhanced HTML display of state-space information
    %
    % This improved function creates a more intuitive, visually-organized HTML display of
    % system properties with better formatting and layout
    
    if nargin < 3
        isNonlinear = false;
    end
    
    try
        % Get model properties
        A = model.A;
        B = model.B;
        C = model.C;
        D = model.D;
        Ts = model.Ts;
        
        % Calculate system dimensions
        [n, ~] = size(A);  % Number of states
        [~, m] = size(B);  % Number of inputs
        [p, ~] = size(C);  % Number of outputs
        
        % Calculate eigenvalues with error handling
        try
            eigs = eig(A);
        catch ME
            eigs = []; % Set empty if calculation fails
            warning(['Could not compute eigenvalues: ' ME.message]);
        end
        
        % Determine stability
        if isempty(eigs)
            isStable = false;
            stabilityMsg = 'Could not be determined';
            stabilityClass = 'warning';
        else
            if Ts > 0
                % Discrete-time stability (eigenvalues inside unit circle)
                isStable = all(abs(eigs) < 1);
            else
                % Continuous-time stability (eigenvalues in left half-plane)
                isStable = all(real(eigs) < 0);
            end
            
            if isStable
                stabilityMsg = 'Stable';
                stabilityClass = 'stable';
            else
                stabilityMsg = 'Unstable';
                stabilityClass = 'unstable';
            end
        end
        
        % Calculate controllability with error handling
        try
            Co = ctrb(A, B);
            rankCo = rank(Co);
            isControllable = (rankCo == n);
            if isControllable
                ctrbMsg = ['Controllable (rank: ' num2str(rankCo) '/' num2str(n) ')'];
                ctrbClass = 'yes';
            else
                ctrbMsg = ['Not Controllable (rank: ' num2str(rankCo) '/' num2str(n) ')'];
                ctrbClass = 'no';
            end
        catch ME
            isControllable = false;
            ctrbMsg = ['Error: ' ME.message];
            ctrbClass = 'warning';
            rankCo = 0;
        end
        
        % Calculate observability with error handling
        try
            Ob = obsv(A, C);
            rankOb = rank(Ob);
            isObservable = (rankOb == n);
            if isObservable
                obsvMsg = ['Observable (rank: ' num2str(rankOb) '/' num2str(n) ')'];
                obsvClass = 'yes';
            else
                obsvMsg = ['Not Observable (rank: ' num2str(rankOb) '/' num2str(n) ')'];
                obsvClass = 'no';
            end
        catch ME
            isObservable = false;
            obsvMsg = ['Error: ' ME.message];
            obsvClass = 'warning';
            rankOb = 0;
        end
        
        % Determine system type (continuous/discrete)
        if Ts > 0
            systemType = ['Discrete-time (Ts = ' num2str(Ts, '%.4g') ' s)'];
        else
            systemType = 'Continuous-time';
        end
        
        % *** IMPORTANT FIX: Improved HTML styling with better CSS ***
        html = ['<html><head><style>', ...
                'body { font-family: Arial, sans-serif; font-size: 12px; margin: 0; padding: 0; }', ...
                'table { border-collapse: collapse; width: 100%; margin-bottom: 12px; border: 1px solid #ddd; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 40%; font-weight: bold; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.warning { color: #F39C12; font-weight: bold; }', ...
                '.yes { color: #2ECC71; font-weight: bold; }', ...
                '.no { color: #E74C3C; font-weight: bold; }', ...
                '.eigenvalue-container { max-height: 120px; overflow-y: auto; margin-top: 5px; padding-right: 5px; }', ...
                '.system-title { text-align: center; font-size: 16px; margin: 8px 0; color: #2980B9; font-weight: bold; }', ...
                '.summary-box { background-color: #f8f8f8; border: 1px solid #ddd; border-radius: 5px; padding: 10px; margin-top: 8px; }', ...
                '.matrix-info { font-family: monospace; white-space: pre; overflow-x: auto; max-width: 100%; }', ...
                '.system-overview { margin-bottom: 15px; }', ...
                '</style></head><body>'];
        
        % *** IMPROVED LAYOUT: Better organized information with consistent sections ***
        
        % Title and system type
        html = [html, '<div class="system-title">'];
        if isNonlinear
            html = [html, 'Nonlinear System (Linearized)'];
        else
            html = [html, 'Linear State-Space System'];
        end
        html = [html, '</div>'];
        
        % System overview table - reformatted for better visual organization
        html = [html, '<div class="system-overview">'];
        html = [html, '<table>'];
        html = [html, '<tr><th colspan="2">System Overview</th></tr>'];
        html = [html, '<tr><td>System Type</td><td>', systemType, '</td></tr>'];
        html = [html, '<tr><td>Dimensions</td><td>', ...
                num2str(n), ' states, ', ...
                num2str(m), ' inputs, ', ...
                num2str(p), ' outputs', ...
                '</td></tr>'];
        
        % Stability information
        html = [html, '<tr><td>Stability</td><td class="', stabilityClass, '">', stabilityMsg, '</td></tr>'];
        
        % Controllability
        html = [html, '<tr><td>Controllability</td><td class="', ctrbClass, '">', ctrbMsg, '</td></tr>'];
        
        % Observability
        html = [html, '<tr><td>Observability</td><td class="', obsvClass, '">', obsvMsg, '</td></tr>'];
        
        % If system is not controllable or observable, show more info
        if ~isControllable && rankCo > 0
            html = [html, '<tr><td>Uncontrollable Modes</td><td>', num2str(n-rankCo), '</td></tr>'];
        end
        
        if ~isObservable && rankOb > 0
            html = [html, '<tr><td>Unobservable Modes</td><td>', num2str(n-rankOb), '</td></tr>'];
        end
        
        html = [html, '</table>'];
        html = [html, '</div>'];
        
        % *** NEW FEATURE: Visual status indicators for key properties ***
        html = [html, '<div class="summary-box">'];
        html = [html, '<h3 style="margin-top: 0; text-align: center; color: #333;">System Properties at a Glance</h3>'];
        
        % Create a flex container for status indicators
        html = [html, '<div style="display: flex; justify-content: space-around; margin-bottom: 10px;">'];
        
        % Stability indicator
        html = [html, '<div style="text-align: center;">'];
        if isStable
            html = [html, '<div style="width: 20px; height: 20px; background-color: #2ECC71; border-radius: 50%; margin: 0 auto;"></div>'];
        else
            html = [html, '<div style="width: 20px; height: 20px; background-color: #E74C3C; border-radius: 50%; margin: 0 auto;"></div>'];
        end
        html = [html, '<p style="margin: 5px 0;">Stability</p></div>'];
        
        % Controllability indicator
        html = [html, '<div style="text-align: center;">'];
        if isControllable
            html = [html, '<div style="width: 20px; height: 20px; background-color: #2ECC71; border-radius: 50%; margin: 0 auto;"></div>'];
        else
            html = [html, '<div style="width: 20px; height: 20px; background-color: #E74C3C; border-radius: 50%; margin: 0 auto;"></div>'];
        end
        html = [html, '<p style="margin: 5px 0;">Controllability</p></div>'];
        
        % Observability indicator
        html = [html, '<div style="text-align: center;">'];
        if isObservable
            html = [html, '<div style="width: 20px; height: 20px; background-color: #2ECC71; border-radius: 50%; margin: 0 auto;"></div>'];
        else
            html = [html, '<div style="width: 20px; height: 20px; background-color: #E74C3C; border-radius: 50%; margin: 0 auto;"></div>'];
        end
        html = [html, '<p style="margin: 5px 0;">Observability</p></div>'];
        
        html = [html, '</div>']; % Close flex container
        
        % Add interpretations based on properties
        html = [html, '<p style="text-align: center; margin: 5px 0; font-style: italic;">'];
        if isStable && isControllable && isObservable
            html = [html, 'System has good properties for control design.'];
        elseif ~isStable && isControllable
            html = [html, 'System is unstable but controllable (can be stabilized).'];
        elseif ~isStable && ~isControllable
            html = [html, 'System is unstable and not fully controllable (challenging control design).'];
        elseif isStable && ~isControllable
            html = [html, 'System is stable but not all states can be controlled.'];
        elseif isStable && ~isObservable
            html = [html, 'System is stable but not all states can be observed.'];
        end
        html = [html, '</p>'];
        
        html = [html, '</div>']; % Close summary box
        
        % *** IMPROVED VISUALIZATION: Better eigenvalue display ***
        html = [html, '<table>'];
        html = [html, '<tr><th colspan="2">Eigenvalues</th></tr>'];
        
        if ~isempty(eigs)
            html = [html, '<tr><td colspan="2"><div class="eigenvalue-container">'];
            
            % Create a table for eigenvalues
            html = [html, '<table style="width: 100%; border-collapse: collapse;">'];
            
            % Table header
            html = [html, '<tr><th style="width: 30%;">Eigenvalue</th><th>Value</th><th style="width: 20%;">Status</th></tr>'];
            
            for i = 1:length(eigs)
                % Format eigenvalue nicely
                if imag(eigs(i)) == 0
                    eigStr = num2str(real(eigs(i)), '%.4f');
                elseif real(eigs(i)) == 0
                    eigStr = [num2str(imag(eigs(i)), '%.4f'), 'i'];
                elseif imag(eigs(i)) > 0
                    eigStr = [num2str(real(eigs(i)), '%.4f'), ' + ', num2str(imag(eigs(i)), '%.4f'), 'i'];
                else
                    eigStr = [num2str(real(eigs(i)), '%.4f'), ' - ', num2str(abs(imag(eigs(i))), '%.4f'), 'i'];
                end
                
                % Determine stability for this eigenvalue
                if (Ts > 0 && abs(eigs(i)) >= 1) || (Ts == 0 && real(eigs(i)) >= 0)
                    eigClass = 'unstable';
                    eigStatus = 'Unstable';
                else
                    eigClass = 'stable';
                    eigStatus = 'Stable';
                end
                
                html = [html, '<tr>'];
                html = [html, '<td>λ<sub>', num2str(i), '</sub></td>'];
                html = [html, '<td>', eigStr, '</td>'];
                html = [html, '<td class="', eigClass, '">', eigStatus, '</td>'];
                html = [html, '</tr>'];
            end
            
            html = [html, '</table>'];
            html = [html, '</div></td></tr>'];
        else
            html = [html, '<tr><td colspan="2"><span class="warning">Could not compute eigenvalues</span></td></tr>'];
        end
        
        html = [html, '</table>'];
        
        % *** IMPROVED VISUALIZATION: Matrix previews with better formatting ***
        if n <= 5 && m <= 5 && p <= 5
            html = [html, '<table>'];
            html = [html, '<tr><th colspan="2">State-Space Matrices</th></tr>'];
            
            % A matrix preview with better formatting
            html = [html, '<tr><td>A Matrix</td><td class="matrix-info">'];
            for i = 1:min(n, 3)
                rowStr = '';
                for j = 1:min(n, 3)
                    try
                        val_str = sprintf('% 8.4g', A(i,j));
                        rowStr = [rowStr, val_str];
                    catch
                        rowStr = [rowStr, ' ERR '];
                    end
                end
                if n > 3 && i == 3
                    rowStr = [rowStr, ' ...'];
                end
                html = [html, rowStr, '<br>'];
            end
            if n > 3
                html = [html, '...<br>'];
            end
            html = [html, '</td></tr>'];
            
            % B matrix preview
            html = [html, '<tr><td>B Matrix</td><td class="matrix-info">'];
            for i = 1:min(n, 3)
                rowStr = '';
                for j = 1:min(m, 3)
                    try
                        val_str = sprintf('% 8.4g', B(i,j));
                        rowStr = [rowStr, val_str];
                    catch
                        rowStr = [rowStr, ' ERR '];
                    end
                end
                if m > 3 && i == 3
                    rowStr = [rowStr, ' ...'];
                end
                html = [html, rowStr, '<br>'];
            end
            if n > 3
                html = [html, '...<br>'];
            end
            html = [html, '</td></tr>'];
            
            % C matrix preview
            html = [html, '<tr><td>C Matrix</td><td class="matrix-info">'];
            for i = 1:min(p, 3)
                rowStr = '';
                for j = 1:min(n, 3)
                    try
                        val_str = sprintf('% 8.4g', C(i,j));
                        rowStr = [rowStr, val_str];
                    catch
                        rowStr = [rowStr, ' ERR '];
                    end
                end
                if n > 3 && i == 3
                    rowStr = [rowStr, ' ...'];
                end
                html = [html, rowStr, '<br>'];
            end
            if p > 3
                html = [html, '...<br>'];
            end
            html = [html, '</td></tr>'];
            
            % D matrix preview (only if not all zeros)
            if any(D(:) ~= 0)
                html = [html, '<tr><td>D Matrix</td><td class="matrix-info">'];
                for i = 1:min(p, 3)
                    rowStr = '';
                    for j = 1:min(m, 3)
                        try
                            val_str = sprintf('% 8.4g', D(i,j));
                            rowStr = [rowStr, val_str];
                        catch
                            rowStr = [rowStr, ' ERR '];
                        end
                    end
                    if m > 3 && i == 3
                        rowStr = [rowStr, ' ...'];
                    end
                    html = [html, rowStr, '<br>'];
                end
                if p > 3
                    html = [html, '...<br>'];
                end
                html = [html, '</td></tr>'];
            else
                html = [html, '<tr><td>D Matrix</td><td>All zeros (no feedthrough)</td></tr>'];
            end
            
            html = [html, '</table>'];
        end
        
        % *** NEW FEATURE: Analysis instructions ***
        html = [html, '<div class="summary-box" style="margin-top: 15px;">'];
        html = [html, '<h3 style="margin-top: 0; text-align: center; color: #333;">Available Analyses</h3>'];
        html = [html, '<p style="text-align: center;">Use the <b>Analysis Type</b> dropdown and <b>Run Analysis</b> button to perform the following analyses:</p>'];
        
        html = [html, '<ul style="list-style-type: none; padding: 0; margin: 0; display: flex; flex-wrap: wrap; justify-content: space-around;">'];
        html = [html, '<li style="margin: 5px; text-align: center; width: 45%;"><div style="font-weight: bold; color: #3498DB;">Eigenvalues</div><div style="font-size: 11px;">System stability analysis</div></li>'];
        
        if n == 2
            html = [html, '<li style="margin: 5px; text-align: center; width: 45%;"><div style="font-weight: bold; color: #3498DB;">Phase Portrait</div><div style="font-size: 11px;">2D state trajectories</div></li>'];
        else
            html = [html, '<li style="margin: 5px; text-align: center; width: 45%;"><div style="font-weight: bold; color: #aaa;">Phase Portrait</div><div style="font-size: 11px;">Requires 2-state system</div></li>'];
        end
        
        html = [html, '<li style="margin: 5px; text-align: center; width: 45%;"><div style="font-weight: bold; color: #3498DB;">Controllability</div><div style="font-size: 11px;">State control analysis</div></li>'];
        html = [html, '<li style="margin: 5px; text-align: center; width: 45%;"><div style="font-weight: bold; color: #3498DB;">Observability</div><div style="font-size: 11px;">State observation analysis</div></li>'];
        html = [html, '</ul>'];
        
        html = [html, '</div>'];
        
        % Close the HTML
        html = [html, '</body></html>'];
        
        % Update the HTML component
        app.StateSpaceInfoHTML.HTMLSource = html;
        
    catch ME
        % Enhanced error handling with detailed error message
        disp(['Error updating state-space information: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        try
            % Create a user-friendly error message
            errorHtml = ['<html><div style="color: red; text-align: center; margin-top: 20px; font-weight: bold; padding: 15px; border: 1px solid red; border-radius: 5px;">', ...
                        'Error updating system information:<br><br>', ...
                        ME.message, ...
                        '<br><br>Please check that your system matrices are valid.', ...
                        '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = errorHtml;
        catch
            % If even creating the error message fails, don't crash
            disp('Could not update HTML component with error message.');
        end
    end
end

function [A, B, C, D] = linearizeSystem(app, state_vars, input_vars, state_eqs, output_eqs, linearization_point)
    % LINEARIZESYSTEM - Linearizes a nonlinear system around an operating point
    %
    % This enhanced function computes the linearized state-space matrices (A, B, C, D) 
    % for a nonlinear system, with improved error handling, progress reporting and 
    % matrix calculation.
    %
    % Inputs:
    %   app                 - App object with UI components for feedback
    %   state_vars          - Cell array of state variable names
    %   input_vars          - Cell array of input variable names
    %   state_eqs           - Cell array of state equations (dx/dt expressions)
    %   output_eqs          - Cell array of output equations
    %   linearization_point - Container with variable values at linearization point
    %
    % Outputs:
    %   A, B, C, D - Linearized state-space matrices
    
    try
        % Check if Symbolic Math Toolbox is available
        if ~license('test', 'Symbolic_Toolbox')
            error('Symbolic Math Toolbox is required for linearization but is not available.');
        end
        
        % Validate inputs
        if isempty(state_vars) || isempty(input_vars) || isempty(state_eqs) || isempty(output_eqs)
            error('All parameters (state variables, input variables, state equations, output equations) must be provided.');
        end
        
        n = length(state_vars);  % Number of states
        m = length(input_vars);  % Number of inputs
        p = length(output_eqs);  % Number of outputs
        
        % Check dimensions match
        if length(state_eqs) ~= n
            error('Number of state equations (%d) must match number of state variables (%d).', length(state_eqs), n);
        end
        
        % Initialize matrices
        A = zeros(n, n);
        B = zeros(n, m);
        C = zeros(p, n);
        D = zeros(p, m);
        
        % Update status
        statusMsg = 'Creating symbolic variables...';
        disp(statusMsg);
        try
            % Create status display in UI
            if ~isempty(app) && isfield(app, 'StateSpaceInfoHTML')
                currentHTML = app.StateSpaceInfoHTML.HTMLSource;
                statusHTML = ['<html><div style="color: blue; text-align: center; margin-top: 10px;">', ...
                              statusMsg, '</div></html>'];
                app.StateSpaceInfoHTML.HTMLSource = [currentHTML, statusHTML];
                drawnow;
            end
        catch
            % Continue without UI update if it fails
        end
        
        % Ensure all variables are valid
        for i = 1:length(state_vars)
            if ~isvarname(state_vars{i})
                error('Invalid state variable name: %s', state_vars{i});
            end
        end
        
        for i = 1:length(input_vars)
            if ~isvarname(input_vars{i})
                error('Invalid input variable name: %s', input_vars{i});
            end
        end
        
        % Create symbolic variables
        try
            % Convert cell arrays to strings for syms function
            state_vars_str = strjoin(state_vars, ' ');
            input_vars_str = strjoin(input_vars, ' ');
            
            % Create symbolic variables
            eval(['syms ', state_vars_str, ' ', input_vars_str, ' real']);
            
            % Store symbolic variables in arrays for easier access
            % Use str2sym to ensure proper symbolic conversion
            sym_state_vars = cell(size(state_vars));
            sym_input_vars = cell(size(input_vars));
            
            for i = 1:length(state_vars)
                sym_state_vars{i} = str2sym(state_vars{i});
            end
            
            for i = 1:length(input_vars)
                sym_input_vars{i} = str2sym(input_vars{i});
            end
        catch ME
            error('Error creating symbolic variables: %s\nDetail: %s', ME.message, getReport(ME, 'basic'));
        end
        
        % Convert state equations to symbolic expressions
        disp('Converting state equations to symbolic form...');
        sym_state_eqs = cell(size(state_eqs));
        
        for i = 1:length(state_eqs)
            try
                if ischar(state_eqs{i})
                    % Use str2sym explicitly for conversion instead of sym
                    sym_state_eqs{i} = str2sym(state_eqs{i});
                elseif isa(state_eqs{i}, 'sym')
                    % If it's already symbolic, use as is
                    sym_state_eqs{i} = state_eqs{i};
                else
                    error('State equation must be a string or symbolic expression');
                end
            catch ME
                error('Error in state equation %d (%s): %s', i, state_eqs{i}, ME.message);
            end
        end
        
        % Convert output equations to symbolic expressions
        disp('Converting output equations to symbolic form...');
        sym_output_eqs = cell(size(output_eqs));
        
        for i = 1:length(output_eqs)
            try
                if ischar(output_eqs{i})
                    % Use str2sym explicitly for conversion instead of sym
                    sym_output_eqs{i} = str2sym(output_eqs{i});
                elseif isa(output_eqs{i}, 'sym')
                    % If it's already symbolic, use as is
                    sym_output_eqs{i} = output_eqs{i};
                else
                    error('Output equation must be a string or symbolic expression');
                end
            catch ME
                error('Error in output equation %d (%s): %s', i, output_eqs{i}, ME.message);
            end
        end
        
        % Verify linearization point contains all required variables
        missing_vars = {};
        for i = 1:length(state_vars)
            if ~isKey(linearization_point, state_vars{i})
                missing_vars{end+1} = state_vars{i};
            end
        end
        
        for i = 1:length(input_vars)
            if ~isKey(linearization_point, input_vars{i})
                missing_vars{end+1} = input_vars{i};
            end
        end
        
        if ~isempty(missing_vars)
            error('Linearization point missing values for: %s', strjoin(missing_vars, ', '));
        end
        
        % ----------------------------------------------------------------------
        % Calculate A matrix (df/dx) - Jacobian of state equations w.r.t. states
        % ----------------------------------------------------------------------
        disp('Computing A matrix (df/dx)...');
        
        for i = 1:n
            for j = 1:n
                try
                    % Calculate partial derivative
                    Aij = diff(sym_state_eqs{i}, sym_state_vars{j});
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Aij = subs(Aij, sym_state_vars{k}, linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Aij = subs(Aij, sym_input_vars{k}, linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    A(i, j) = double(Aij);
                    
                    % Check for NaN or Inf
                    if isnan(A(i, j)) || isinf(A(i, j))
                        warning('A(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Aij));
                        A(i, j) = 0;
                    end
                catch ME
                    warning('Error computing A(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    A(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate B matrix (df/du) - Jacobian of state equations w.r.t. inputs
        % ----------------------------------------------------------------------
        disp('Computing B matrix (df/du)...');
        
        for i = 1:n
            for j = 1:m
                try
                    % Calculate partial derivative
                    Bij = diff(sym_state_eqs{i}, sym_input_vars{j});
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Bij = subs(Bij, sym_state_vars{k}, linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Bij = subs(Bij, sym_input_vars{k}, linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    B(i, j) = double(Bij);
                    
                    % Check for NaN or Inf
                    if isnan(B(i, j)) || isinf(B(i, j))
                        warning('B(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Bij));
                        B(i, j) = 0;
                    end
                catch ME
                    warning('Error computing B(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    B(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate C matrix (dg/dx) - Jacobian of output equations w.r.t. states
        % ----------------------------------------------------------------------
        disp('Computing C matrix (dg/dx)...');
        
        for i = 1:p
            for j = 1:n
                try
                    % Calculate partial derivative
                    Cij = diff(sym_output_eqs{i}, sym_state_vars{j});
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Cij = subs(Cij, sym_state_vars{k}, linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Cij = subs(Cij, sym_input_vars{k}, linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    C(i, j) = double(Cij);
                    
                    % Check for NaN or Inf
                    if isnan(C(i, j)) || isinf(C(i, j))
                        warning('C(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Cij));
                        C(i, j) = 0;
                    end
                catch ME
                    warning('Error computing C(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    C(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate D matrix (dg/du) - Jacobian of output equations w.r.t. inputs
        % ----------------------------------------------------------------------
        disp('Computing D matrix (dg/du)...');
        
        for i = 1:p
            for j = 1:m
                try
                    % Calculate partial derivative
                    Dij = diff(sym_output_eqs{i}, sym_input_vars{j});
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Dij = subs(Dij, sym_state_vars{k}, linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Dij = subs(Dij, sym_input_vars{k}, linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    D(i, j) = double(Dij);
                    
                    % Check for NaN or Inf
                    if isnan(D(i, j)) || isinf(D(i, j))
                        warning('D(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Dij));
                        D(i, j) = 0;
                    end
                catch ME
                    warning('Error computing D(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    D(i, j) = 0;
                end
            end
        end
        
        % Update status
        disp('Linearization completed successfully.');
        
    catch ME
        % Enhanced error handling with context details
        errorMsg = sprintf('Linearization error: %s\n\nContext: %s', ...
                           ME.message, getReport(ME, 'basic'));
        disp(errorMsg);
        
        % Re-throw with more context
        error('Linearization failed: %s', ME.message);
    end
end


function performEigenvalueAnalysis(app)
    % PERFORMEIGENVALUEANALYSIS - Performs and visualizes eigenvalue analysis
    
    app.StateSpaceVisHTML.Visible = 'off';
    app.StateSpaceAxes.Visible = 'on';

    try
        % Validate model first
        if ~validateStateSpaceModel(app)
            return;
        end
        
        % Completely reset axes to consistent state
        resetStateSpaceAxes(app);
        
        model = app.LinearizedModel;
        A = model.A;
        Ts = model.Ts;
        
        % Calculate eigenvalues
        eigs = eig(A);
        
        % Clear axes and set basic properties again (to be extra sure)
        cla(app.StateSpaceAxes);
        
        % Start plotting
        hold(app.StateSpaceAxes, 'on');
        
        % Determine system type
        isDiscrete = (Ts > 0);
        
        % FIXED: Use explicit hard-coded values to avoid any possible errors
        max_abs_real = 5;
        max_abs_imag = 5;
        
        % Background regions
        if isDiscrete
            % Discrete system - stable inside unit circle
            fill(app.StateSpaceAxes, [-max_abs_real, max_abs_real, max_abs_real, -max_abs_real], ...
                [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag], ...
                [1 0.98 0.94], 'EdgeColor', 'none');
            
            % Stable region - inside unit circle
            theta = linspace(0, 2*pi, 200);
            x_circle = cos(theta);
            y_circle = sin(theta);
            fill(app.StateSpaceAxes, x_circle, y_circle, [0.9 1 0.9], 'EdgeColor', 'none');
            
            % Unit circle
            plot(app.StateSpaceAxes, x_circle, y_circle, 'k-', 'LineWidth', 2);
        else
            % Continuous system - stable in left half-plane
            fill(app.StateSpaceAxes, [-max_abs_real, max_abs_real, max_abs_real, -max_abs_real], ...
                 [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag], ...
                 [1 0.98 0.94], 'EdgeColor', 'none');
            
            % Stable region (left half-plane)
            fill(app.StateSpaceAxes, [-max_abs_real, 0, 0, -max_abs_real], ...
                 [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag], ...
                 [0.9 1 0.9], 'EdgeColor', 'none');
            
            % Unstable region (right half-plane)
            fill(app.StateSpaceAxes, [0, max_abs_real, max_abs_real, 0], ...
                 [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag], ...
                 [1 0.9 0.9], 'EdgeColor', 'none');
            
            % Imaginary axis
            plot(app.StateSpaceAxes, [0, 0], [-max_abs_imag, max_abs_imag], 'k-', 'LineWidth', 2);
        end
        
        % Region labels with background
        if isDiscrete
            text(app.StateSpaceAxes, 0, 0.7*max_abs_imag, 'Stable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0 0.5 0], 'FontWeight', 'bold', 'FontSize', 12, ...
                'BackgroundColor', [1 1 1 0.7], 'Margin', 3);
                
            text(app.StateSpaceAxes, 0.7*max_abs_real, 0, 'Unstable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0.6 0 0], 'FontWeight', 'bold', 'FontSize', 12, ...
                'BackgroundColor', [1 1 1 0.7], 'Margin', 3);
        else
            text(app.StateSpaceAxes, -0.5*max_abs_real, 0.7*max_abs_imag, 'Stable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0 0.5 0], 'FontWeight', 'bold', 'FontSize', 12, ...
                'BackgroundColor', [1 1 1 0.7], 'Margin', 3);
                
            text(app.StateSpaceAxes, 0.5*max_abs_real, 0.7*max_abs_imag, 'Unstable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0.6 0 0], 'FontWeight', 'bold', 'FontSize', 12, ...
                'BackgroundColor', [1 1 1 0.7], 'Margin', 3);
        end
        
        % Add coordinate axes
        plot(app.StateSpaceAxes, [-max_abs_real, max_abs_real], [0, 0], 'k-', 'LineWidth', 1.5);
        plot(app.StateSpaceAxes, [0, 0], [-max_abs_imag, max_abs_imag], 'k-', 'LineWidth', 1.5);
        
        % Plot eigenvalues
        for i = 1:length(eigs)
            if isDiscrete
                isStableEig = abs(eigs(i)) < 1;
            else
                isStableEig = real(eigs(i)) < 0;
            end
            
            % Choose colors based on stability
            if isStableEig
                markerColor = [0 0.6 0]; % Stable: green
                fillColor = [0.3 0.9 0.3];
            else
                markerColor = [0.8 0 0]; % Unstable: red
                fillColor = [1 0.3 0.3];
            end
            
            % Plot markers
            scatter(app.StateSpaceAxes, real(eigs(i)), imag(eigs(i)), 120, 'x', 'LineWidth', 2, ...
                'MarkerEdgeColor', markerColor);
            scatter(app.StateSpaceAxes, real(eigs(i)), imag(eigs(i)), 80, 'o', 'LineWidth', 1.5, ...
                'MarkerEdgeColor', markerColor, 'MarkerFaceColor', fillColor);
            
            % Format eigenvalue label
            if imag(eigs(i)) == 0
                eigStr = num2str(real(eigs(i)), '%.3f');
            elseif real(eigs(i)) == 0
                eigStr = [num2str(imag(eigs(i)), '%.3f'), 'i'];
            elseif imag(eigs(i)) > 0
                eigStr = [num2str(real(eigs(i)), '%.3f'), '+', num2str(imag(eigs(i)), '%.3f'), 'i'];
            else
                eigStr = [num2str(real(eigs(i)), '%.3f'), num2str(imag(eigs(i)), '%.3f'), 'i'];
            end
            
            label = ['λ', num2str(i), ' = ', eigStr];
            
            % Position label with safe offsets
            label_offset_x = 0.3;
            if real(eigs(i)) < 0
                label_offset_x = -1.0;
            end
            
            label_offset_y = 0.3;
            
            % Add text with background
            text(app.StateSpaceAxes, real(eigs(i)) + label_offset_x, imag(eigs(i)) + label_offset_y, ...
                label, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', ...
                'BackgroundColor', [1 1 1 0.7], 'EdgeColor', [0.7 0.7 0.7], 'Margin', 3);
        end
        
        % Determine overall stability
        if isDiscrete
            isStable = all(abs(eigs) < 1);
        else
            isStable = all(real(eigs) < 0);
        end
        
        % Stability information
        if isStable
            stabilityText = 'System is STABLE';
            stabilityColor = [0 0.6 0]; % Green
        else
            stabilityText = 'System is UNSTABLE';
            stabilityColor = [0.8 0 0]; % Red
        end
        
        % Create a rectangle for stability info
        fill(app.StateSpaceAxes, ...
            [-(max_abs_real*0.8), max_abs_real*0.8, max_abs_real*0.8, -(max_abs_real*0.8)], ...
            [-(max_abs_imag*0.9), -(max_abs_imag*0.9), -(max_abs_imag*0.8), -(max_abs_imag*0.8)], ...
            [1 1 1], 'EdgeColor', stabilityColor, 'LineWidth', 2);
        
        % Add stability text
        text(app.StateSpaceAxes, 0, -(max_abs_imag*0.85), stabilityText, ...
            'HorizontalAlignment', 'center', 'FontWeight', 'bold', 'FontSize', 14, ...
            'Color', stabilityColor);
        
        % CRITICAL FIX: Use DataAspectRatio instead of axis('equal')
        app.StateSpaceAxes.DataAspectRatio = [1 1 1];
        
        % CRITICAL FIX: Set explicit axis limits
        app.StateSpaceAxes.XLim = [-5, 5];
        app.StateSpaceAxes.YLim = [-5, 5];
        
        % Set labels
        if isDiscrete
            title(app.StateSpaceAxes, 'Eigenvalue Analysis (z-plane)', 'FontSize', 16, 'FontWeight', 'bold');
            xlabel(app.StateSpaceAxes, 'Real Axis', 'FontSize', 14);
            ylabel(app.StateSpaceAxes, 'Imaginary Axis', 'FontSize', 14);
        else
            title(app.StateSpaceAxes, 'Eigenvalue Analysis (s-plane)', 'FontSize', 16, 'FontWeight', 'bold');
            xlabel(app.StateSpaceAxes, 'Real Axis (seconds^{-1})', 'FontSize', 14);
            ylabel(app.StateSpaceAxes, 'Imaginary Axis (seconds^{-1})', 'FontSize', 14);
        end
        
        % Turn off hold
        hold(app.StateSpaceAxes, 'off');
        
    catch ME
        % Error handling
        disp(['Error in eigenvalue analysis: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        % Clean up axes
        resetStateSpaceAxes(app);
        
        % Display error
        text(app.StateSpaceAxes, 0.5, 0.6, 'Eigenvalue Analysis Error', ...
            'HorizontalAlignment', 'center', 'FontSize', 14, 'FontWeight', 'bold', 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.5, ME.message, ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.4, 'Troubleshooting: Check if model exists and is valid', ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', [0.6 0.6 0.6], ...
            'Units', 'normalized');
    end
end


function performPhasePortrait(app)
    % PERFORMPHASEPORTRAIT - Creates a phase portrait visualization
    % with streamlined visualization and direct labels instead of legends
    
    app.StateSpaceVisHTML.Visible = 'off';
    app.StateSpaceAxes.Visible = 'on';

    try
        % Check if the model has 2 states
        model = app.LinearizedModel;
        A = model.A;
        
        [n, ~] = size(A);
        
        % Reset axes to consistent state
        resetStateSpaceAxes(app);
        
        if n ~= 2
            % Display warning for non-2D systems
            text(app.StateSpaceAxes, 0.5, 0.6, 'Phase Portrait Requires 2-State System', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', ...
                'FontSize', 14, 'FontWeight', 'bold', 'Color', [0.8, 0.4, 0]);
            
            text(app.StateSpaceAxes, 0.5, 0.5, ['This system has ', num2str(n), ' states'], ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', 'FontSize', 12);
            
            text(app.StateSpaceAxes, 0.5, 0.4, 'Please use a 2-state model for phase portrait analysis', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', 'FontSize', 12);
            
            return;
        end
        
        % Start plotting
        hold(app.StateSpaceAxes, 'on');
        
        % Create meshgrid for vector field
        [x1, x2] = meshgrid(linspace(-2.5, 2.5, 25), linspace(-2.5, 2.5, 25));
        
        % Calculate vector field
        dx1 = zeros(size(x1));
        dx2 = zeros(size(x2));
        
        for i = 1:numel(x1)
            x = [x1(i); x2(i)];
            xdot = A * x;
            dx1(i) = xdot(1);
            dx2(i) = xdot(2);
        end
        
        % Normalize vectors
        magnitude = sqrt(dx1.^2 + dx2.^2);
        max_magnitude = max(magnitude(:));
        
        if max_magnitude > 0
            scale_factor = 0.4;
            x_range = max(abs(x1(:)));
            scale_factor = scale_factor * (x_range / 2.5);
            
            dx1_scaled = scale_factor * dx1 ./ (magnitude + eps);
            dx2_scaled = scale_factor * dx2 ./ (magnitude + eps);
        else
            dx1_scaled = zeros(size(dx1));
            dx2_scaled = zeros(size(dx2));
        end
        
        % Determine stability based on eigenvalues
        eigs = eig(A);
        
        % FIXED: Use explicit values for drawing regions
        x_range = [-3, 3]; 
        y_range = [-3, 3];
        
        % Add background color based on system type - no DisplayName for legend
        if all(real(eigs) < 0)
            % Stable system - green background
            fill(app.StateSpaceAxes, [x_range(1), x_range(2), x_range(2), x_range(1)], ...
                 [y_range(1), y_range(1), y_range(2), y_range(2)], ...
                 [0.9, 1, 0.9], 'EdgeColor', 'none');
            stability_text = 'Stable System: All trajectories converge to origin';
            stability_color = [0, 0.6, 0]; % Green
        elseif all(real(eigs) > 0)
            % Unstable system - red background
            fill(app.StateSpaceAxes, [x_range(1), x_range(2), x_range(2), x_range(1)], ...
                 [y_range(1), y_range(1), y_range(2), y_range(2)], ...
                 [1, 0.9, 0.9], 'EdgeColor', 'none');
            stability_text = 'Unstable System: All trajectories diverge from origin';
            stability_color = [0.8, 0, 0]; % Red
        else
            % Saddle point - orange/yellow background
            fill(app.StateSpaceAxes, [x_range(1), x_range(2), x_range(2), x_range(1)], ...
                 [y_range(1), y_range(1), y_range(2), y_range(2)], ...
                 [1, 0.98, 0.9], 'EdgeColor', 'none');
            stability_text = 'Saddle Point: Some trajectories converge, others diverge';
            stability_color = [0.7, 0.5, 0]; % Orange
        end
        
        % Plot vector field without DisplayName for legend
        q = quiver(app.StateSpaceAxes, x1, x2, dx1_scaled, dx2_scaled, 0);
        q.Color = [0.2, 0.2, 0.7];  % Dark blue vectors
        q.LineWidth = 1.1;
        if isprop(q, 'AlignVertexCenters')
            q.AlignVertexCenters = 'on';
        end
        
        % Plot nullclines with direct labels instead of legend entries
        try
            % x1-nullcline: dx1/dt = 0 (A(1,1)*x1 + A(1,2)*x2 = 0)
            if A(1,2) ~= 0
                x1_range = linspace(-3, 3, 100);
                x2_nullcline1 = -A(1,1) * x1_range / A(1,2);
                plot(app.StateSpaceAxes, x1_range, x2_nullcline1, 'r--', 'LineWidth', 1.8);
                
                % Add direct label at a good position
                idx = max(1, round(length(x1_range)*0.8));
                text(app.StateSpaceAxes, x1_range(idx), x2_nullcline1(idx), 'x₁-nullcline', ...
                    'Color', 'r', 'FontWeight', 'bold', 'FontSize', 10, ...
                    'BackgroundColor', [1 1 1 0.7]);
            elseif A(1,1) ~= 0
                % Vertical line nullcline
                x1_val = 0;
                y_range = linspace(-3, 3, 100);
                plot(app.StateSpaceAxes, ones(size(y_range))*x1_val, y_range, 'r--', 'LineWidth', 1.8);
                
                % Add direct label
                text(app.StateSpaceAxes, x1_val+0.2, 0, 'x₁-nullcline', ...
                    'Color', 'r', 'FontWeight', 'bold', 'FontSize', 10, ...
                    'BackgroundColor', [1 1 1 0.7]);
            end
            
            % x2-nullcline: dx2/dt = 0 (A(2,1)*x1 + A(2,2)*x2 = 0)
            if A(2,2) ~= 0
                x1_range = linspace(-3, 3, 100);
                x2_nullcline2 = -A(2,1) * x1_range / A(2,2);
                plot(app.StateSpaceAxes, x1_range, x2_nullcline2, 'g-', 'LineWidth', 1.8);
                
                % Add direct label at a good position
                idx = max(1, round(length(x1_range)*0.2));
                text(app.StateSpaceAxes, x1_range(idx), x2_nullcline2(idx), 'x₂-nullcline', ...
                    'Color', 'g', 'FontWeight', 'bold', 'FontSize', 10, ...
                    'BackgroundColor', [1 1 1 0.7]);
            elseif A(2,1) ~= 0
                % Vertical line nullcline
                x1_val = 0;
                y_range = linspace(-3, 3, 100);
                plot(app.StateSpaceAxes, ones(size(y_range))*x1_val, y_range, 'g-', 'LineWidth', 1.8);
                
                % Add direct label
                text(app.StateSpaceAxes, x1_val+0.2, 2, 'x₂-nullcline', ...
                    'Color', 'g', 'FontWeight', 'bold', 'FontSize', 10, ...
                    'BackgroundColor', [1 1 1 0.7]);
            end
        catch
            % Continue if nullclines fail
            disp('Warning: Could not plot nullclines');
        end
        
        % Plot equilibrium point
        plot(app.StateSpaceAxes, 0, 0, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
        text(app.StateSpaceAxes, 0.3, 0.3, 'Equilibrium', ...
            'Color', 'r', 'FontWeight', 'bold', 'FontSize', 10, ...
            'BackgroundColor', [1 1 1 0.7]);
        
        % Calculate trajectories
        try
            num_trajectories = 6;
            max_time = 10;
            t = linspace(0, max_time, 200);
            
            % Initial conditions - distributed in circle
            r = 2;
            angles = linspace(0, 2*pi, num_trajectories+1);
            angles = angles(1:end-1); % Remove duplicate
            
            % Plot trajectories
            colors = hsv(num_trajectories);
            
            for i = 1:num_trajectories
                % Initial condition
                x0 = [r*cos(angles(i)); r*sin(angles(i))];
                
                % Simulate response
                [~, ~, x] = initial(model, x0, t);
                
                % Check for valid trajectory
                if ~isempty(x) && ~all(isnan(x(:))) && size(x, 1) > 2
                    % Plot trajectory with arrows directly - no DisplayName
                    try
                        % Custom plotting for trajectory
                        plot(app.StateSpaceAxes, x(:,1), x(:,2), ...
                            'LineWidth', 1.5, 'Color', colors(i,:));
                        
                        % Add direction arrows separately
                        L = length(x(:,1));
                        arrow_indices = round(linspace(L/5, 4*L/5, 3));  % Place 3 arrows along the trajectory
                        
                        for j = 1:length(arrow_indices)
                            idx = arrow_indices(j);
                            if idx >= 3 && idx <= L-2  % Ensure we have points before and after
                                % Get direction vector
                                dx = x(idx+2,1) - x(idx-2,1);
                                dy = x(idx+2,2) - x(idx-2,2);
                                
                                % Normalize and scale
                                mag = sqrt(dx^2 + dy^2);
                                if mag > 0
                                    dx = dx/mag * 0.2;
                                    dy = dy/mag * 0.2;
                                    
                                    % Create arrow (filled triangle)
                                    arrow_size = 0.08;
                                    arrow_angle = atan2(dy, dx);
                                    
                                    % Arrow head coordinates
                                    tip_x = x(idx,1);
                                    tip_y = x(idx,2);
                                    left_x = tip_x - arrow_size * cos(arrow_angle - pi/6);
                                    left_y = tip_y - arrow_size * sin(arrow_angle - pi/6);
                                    right_x = tip_x - arrow_size * cos(arrow_angle + pi/6);
                                    right_y = tip_y - arrow_size * sin(arrow_angle + pi/6);
                                    
                                    % Draw filled arrow head
                                    fill(app.StateSpaceAxes, [tip_x, left_x, right_x], [tip_y, left_y, right_y], ...
                                        colors(i,:), 'EdgeColor', colors(i,:));
                                end
                            end
                        end
                    catch
                        % Fallback to simple plotting
                        plot(app.StateSpaceAxes, x(:,1), x(:,2), ...
                            'LineWidth', 1.5, 'Color', colors(i,:));
                    end
                    
                    % Plot starting point
                    plot(app.StateSpaceAxes, x0(1), x0(2), 'o', 'MarkerSize', 6, ...
                        'MarkerFaceColor', colors(i,:), 'MarkerEdgeColor', 'k');
                    
                    % Plot ending point
                    last_point = [x(end,1), x(end,2)];
                    if ~isnan(last_point(1)) && ~isnan(last_point(2)) && norm(last_point) < 4
                        plot(app.StateSpaceAxes, last_point(1), last_point(2), 'x', ...
                            'MarkerSize', 6, 'Color', colors(i,:), 'LineWidth', 2);
                    end
                    
                    % Add a direct trajectory label using the index
                    text(app.StateSpaceAxes, x0(1) + 0.1, x0(2) + 0.1, ['Traj ' num2str(i)], ...
                         'Color', colors(i,:), 'FontSize', 8);
                end
            end
        catch ME
            % Continue if trajectories fail
            disp(['Warning: Could not plot all trajectories: ' ME.message]);
        end
        
        % Add stability information
        text(app.StateSpaceAxes, 0, -2.7, stability_text, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom', ...
            'FontWeight', 'bold', 'FontSize', 12, 'Color', stability_color, ...
            'BackgroundColor', [1 1 1 0.8], 'EdgeColor', [0.7 0.7 0.7], 'Margin', 5);
        
        % Add eigenvalue information
        eig_text = '';
        for i = 1:length(eigs)
            if i > 1
                eig_text = [eig_text, ', '];
            end
            
            if imag(eigs(i)) == 0
                eig_text = [eig_text, '\lambda_', num2str(i), ' = ', num2str(real(eigs(i)), '%.3f')];
            elseif real(eigs(i)) == 0
                eig_text = [eig_text, '\lambda_', num2str(i), ' = ', num2str(imag(eigs(i)), '%.3f'), 'i'];
            else
                if imag(eigs(i)) > 0
                    eig_text = [eig_text, '\lambda_', num2str(i), ' = ', num2str(real(eigs(i)), '%.3f'), ' + ', num2str(imag(eigs(i)), '%.3f'), 'i'];
                else
                    eig_text = [eig_text, '\lambda_', num2str(i), ' = ', num2str(real(eigs(i)), '%.3f'), ' - ', num2str(abs(imag(eigs(i))), '%.3f'), 'i'];
                end
            end
        end
        
        if ~isempty(eig_text)
            text(app.StateSpaceAxes, 0, 2.7, ['Eigenvalues: ', eig_text], ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'top', ...
                'FontSize', 11, 'Interpreter', 'tex', ...
                'BackgroundColor', [1 1 1 0.8], 'EdgeColor', [0.7 0.7 0.7], 'Margin', 5);
        end
        
        % CRITICAL FIX: Use DataAspectRatio instead of axis('equal')
        app.StateSpaceAxes.DataAspectRatio = [1 1 1];
        
        % CRITICAL FIX: Set explicit limits with try-catch
        try
            % Explicitly set axis limits
            app.StateSpaceAxes.XLim = [-3, 3];
            app.StateSpaceAxes.YLim = [-3, 3];
        catch
            % Fallback to guaranteed valid limits
            app.StateSpaceAxes.XLim = [-5, 5];
            app.StateSpaceAxes.YLim = [-5, 5];
        end
        
        % Set labels
        title(app.StateSpaceAxes, 'Phase Portrait', 'FontSize', 16, 'FontWeight', 'bold');
        xlabel(app.StateSpaceAxes, 'x₁', 'FontSize', 14);
        ylabel(app.StateSpaceAxes, 'x₂', 'FontSize', 14);
        
        % Turn off hold
        hold(app.StateSpaceAxes, 'off');
        
    catch ME
        % Error handling
        disp(['Error in phase portrait analysis: ', ME.message]);
        disp(getReport(ME, 'extended'));
        
        % Reset axes
        resetStateSpaceAxes(app);
        
        % Create error message
        text(app.StateSpaceAxes, 0.5, 0.6, 'Phase Portrait Error', ...
            'HorizontalAlignment', 'center', 'FontSize', 14, 'FontWeight', 'bold', 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.5, ME.message, ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.4, 'Troubleshooting: Check if model exists and is 2x2', ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', [0.6 0.6 0.6], ...
            'Units', 'normalized');
    end
end   

        
function valid = validateStateSpaceModel(app)
    valid = false;
    
    % Check if we have a model at all - improved property verification
    if ~isprop(app, 'LinearizedModel') || isempty(app.LinearizedModel)
        % Try to use StateSpaceModel if available
        if isprop(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
            app.LinearizedModel = app.StateSpaceModel;
            disp('Using StateSpaceModel as LinearizedModel');
        else
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('No state-space model available. Please create a model first.');
            return;
        end
    end
    
    % Basic validation that it's an ss object
    if ~isa(app.LinearizedModel, 'ss')
        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid state-space model. Object is not an ss model.');
        return;
    end
    
    % Check for valid matrices
    if isempty(app.LinearizedModel.A) || isempty(app.LinearizedModel.B) || ...
       isempty(app.LinearizedModel.C) || isempty(app.LinearizedModel.D)
        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid state-space model. One or more matrices are empty.');
        return;
    end
    
    % Check for NaN or Inf values
    if any(isnan(app.LinearizedModel.A(:))) || any(isinf(app.LinearizedModel.A(:))) || ...
       any(isnan(app.LinearizedModel.B(:))) || any(isinf(app.LinearizedModel.B(:))) || ...
       any(isnan(app.LinearizedModel.C(:))) || any(isinf(app.LinearizedModel.C(:))) || ...
       any(isnan(app.LinearizedModel.D(:))) || any(isinf(app.LinearizedModel.D(:)))
        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid state-space model. Model contains NaN or Inf values.');
        return;
    end
    
    valid = true;
end


function integrateStateSpaceWithControlLoop(app)
    % INTEGRATESTATESPACEWITHCONTROLLOOP - Integrates state-space model with control loop analysis
    %
    % This enhanced function creates a transfer function G(s) from the state-space model
    % and integrates it with the control loop analysis with improved error handling
    % and user feedback
    
    try
        % First validate the state-space model
        if ~validateStateSpaceModel(app)
            % Error message already displayed by validation function
            return;
        end
        
        % Display status message
        statusMsg = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid blue; border-radius: 5px;">Converting state-space model to G(s)...</div></html>';
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, statusMsg];
        drawnow;
        
        % *** IMPROVED CONVERSION: Better handling of MIMO systems ***
        % Get state-space model dimensions
        [n_outputs, ~] = size(app.LinearizedModel.C);
        [~, n_inputs] = size(app.LinearizedModel.B);
        
        % If MIMO, use first input/output by default
        inputIdx = 1;
        outputIdx = 1;
        
        % Use indices from UI if they exist
        if isfield(app, 'InputIndexInput') && ~isempty(app.InputIndexInput)
            inputIdx = app.InputIndexInput.Value;
        end
        
        if isfield(app, 'OutputIndexInput') && ~isempty(app.OutputIndexInput)
            outputIdx = app.OutputIndexInput.Value;
        end
        
        % Validate indices
        if inputIdx > n_inputs
            warning(['Input index ' num2str(inputIdx) ' exceeds number of inputs (' num2str(n_inputs) '). Using input 1.']);
            inputIdx = 1;
        end
        
        if outputIdx > n_outputs
            warning(['Output index ' num2str(outputIdx) ' exceeds number of outputs (' num2str(n_outputs) '). Using output 1.']);
            outputIdx = 1;
        end
        
        % Convert to transfer function
        [G, success, errorMsg] = convertSS2TF(app, app.LinearizedModel, inputIdx, outputIdx);
        
        if ~success
            % Conversion failed, display error and return
            errorHTML = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                        'Error converting to G(s): ', errorMsg, ...
                        '<br><br>Control loop integration aborted.', ...
                        '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, errorHTML];
            return;
        end
        
        % Store the transfer function as G
        app.G = G;
        
        % *** IMPROVED INTEGRATION: Better handling of controller K ***
        % Check if K already exists
        if isempty(app.K)
            % No controller yet - ask user to either create one or use a default
            infoHTML = ['<html><div style="color: #333; text-align: center; margin-top: 10px; padding: 10px; border: 1px solid #999; border-radius: 5px; background-color: #f8f8f8;">', ...
                      'State-space model converted to G(s) successfully.', ...
                      '<br><br>Click "Update K" to define a controller, or continue with analysis to use a unit controller.', ...
                      '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, infoHTML];
            
            % Create a simple unit controller as default
            app.K = tf(1, 1);
        end
        
        % Display the transfer functions
        displayTransferFunctionInApp(app, app.G, 'G');
        displayTransferFunctionInApp(app, app.K, 'K');
        
        % *** IMPROVED ROBUSTNESS: Check for required components ***
        % Create dummy signals for control if not present
        if isempty(app.r)
            app.r = @(t) ones(size(t)); % Unit step
            app.r_num_sections = 1;
            displayTimeFunction(app, app.r, app.r_num_sections, 'r');
        end
        
        if isempty(app.d_1)
            app.d_1 = @(t) zeros(size(t));
            app.d1_num_sections = 1;
            displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1');
        end
        
        if isempty(app.d_2)
            app.d_2 = @(t) zeros(size(t));
            app.d2_num_sections = 1;
            displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2');
        end
        
        % Create time vector if not already defined
        if isempty(app.t)
            app.t = linspace(0, 10, 100);
        end
        
        % *** IMPROVED WORKFLOW: Perform all analyses in sequence ***
        
        % 1. Calculate transfer functions
        try
            [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);
            % Update HTML table with transfer function properties
            render_html_table(app);
            disp('Transfer functions calculated successfully.');
        catch ME
            errorMsg = ['Error calculating transfer functions: ' ME.message];
            disp(errorMsg);
            % Display warning but continue with other analyses
            warningHTML = ['<html><div style="color: orange; text-align: center; margin-top: 10px; padding: 8px; border: 1px solid orange; border-radius: 5px;">', ...
                         'Warning: ', errorMsg, ...
                         '<br>Some analyses may not be available.', ...
                         '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, warningHTML];
        end
                
        % 2. Make UI components visible
        app.PZButton.Visible = 'on';
        app.HtmlTable.Visible = 'on';
        app.HTMLPZ.Visible = 'on';
        app.SaveButton.Visible = 'on';
        app.ButtonGroup.Visible = 'on';
        app.ButtonGroup_2.Visible = 'on';
        app.nyquist_UIAxes2.Visible = "on";
        app.Bandwidth_UIAxes.Visible = "on";
        app.HTMLkeyparamTable_2.Visible = "on";
        app.HTMLNyquistInfo.Visible = "on";
        app.HTMLkeyparamTable.Visible = "on";

        % 3. Perform various analyses with error handling
        analysisResults = struct();
        
        % 3.1 Nyquist diagram
        try
            plotnyquist(app);
            analysisResults.Nyquist = 'Success';
        catch ME
            disp(['Error plotting Nyquist diagram: ' ME.message]);
            analysisResults.Nyquist = ['Error: ' ME.message];
        end
        
        % 3.2 Characteristic equation
        try
            app.char_eq = get_characteristic_equation(app, app.G, app.K);
            analysisResults.CharEq = 'Success';
        catch ME
            disp(['Error calculating characteristic equation: ' ME.message]);
            analysisResults.CharEq = ['Error: ' ME.message];
        end
        
        % 3.3 Control loop stability
        try
            controlloopstable(app);
            analysisResults.Stability = 'Success';
        catch ME
            disp(['Error checking control loop stability: ' ME.message]);
            analysisResults.Stability = ['Error: ' ME.message];
        end
        
        % 3.4 Bandwidth analysis
        try
            plot_TS_bandwidth(app, app.T, app.S);
            analysisResults.Bandwidth = 'Success';
        catch ME
            disp(['Error plotting bandwidth: ' ME.message]);
            analysisResults.Bandwidth = ['Error: ' ME.message];
        end
        
        % 3.5 Bode margins
        try
            plot_bode_margins(app, app.L);
            analysisResults.Bode = 'Success';
        catch ME
            disp(['Error plotting Bode margins: ' ME.message]);
            analysisResults.Bode = ['Error: ' ME.message];
        end
        
        % 3.6 Stationary values/errors
        try
            calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);
            analysisResults.StationaryValues = 'Success';
        catch ME
            disp(['Error calculating stationary values: ' ME.message]);
            analysisResults.StationaryValues = ['Error: ' ME.message];
        end
        
        % 3.7 Response visualization
        try
            calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);
            analysisResults.Response = 'Success';
        catch ME
            disp(['Error calculating response: ' ME.message]);
            analysisResults.Response = ['Error: ' ME.message];
        end
        
        % 3.8 Pole-zero visualization
        try
            PZchange(app, app.PZButton.SelectedObject);
            analysisResults.PoleZero = 'Success';
        catch ME
            disp(['Error in pole-zero analysis: ' ME.message]);
            analysisResults.PoleZero = ['Error: ' ME.message];
        end
        
        % *** IMPROVED FEEDBACK: Better status summary ***
        
        % Count successes and failures
        successCount = 0;
        failureCount = 0;
        analysisFields = fieldnames(analysisResults);
        
        for i = 1:length(analysisFields)
            field = analysisFields{i};
            if strncmp(analysisResults.(field), 'Success', 7)
                successCount = successCount + 1;
            else
                failureCount = failureCount + 1;
            end
        end
        
        % Create status summary
        if failureCount == 0
            successHTML = ['<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">', ...
                         'All control loop analyses completed successfully!', ...
                         '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, successHTML];
        else
            warningHTML = ['<html><div style="color: orange; text-align: center; margin-top: 10px; padding: 10px; border: 1px solid orange; border-radius: 5px;">', ...
                         num2str(successCount), ' of ', num2str(length(analysisFields)), ' analyses completed successfully.', ...
                         '<br>Some analyses failed - check console for details.', ...
                         '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, warningHTML];
        end
        
    catch ME
        % Handle errors
        disp(['Error in control loop integration: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        errorHTML = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                   'Error integrating state-space with control loop:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        
        % Add to current HTML content without removing existing messages
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, errorHTML];
    end
end


function resetStateSpaceAxes(app)
    % RESETSTATESPACEAXES - Reset axes completely between analyses
    %
    % This improved function completely resets the visualization components
    % to fix visibility issues and clear any error messages
    
    try
        % First make sure both visualization components are properly visible/hidden
        app.StateSpaceVisHTML.Visible = 'off';
        app.StateSpaceAxes.Visible = 'on';
        
        % Bring axes to front in the z-order
        uistack(app.StateSpaceAxes, 'top');
        
        % Remove any leftover axes created by previous analyses
        existingAxes = findobj(app.StateSpaceAxes.Parent, 'Type', 'axes');
        for i = 1:length(existingAxes)
            if existingAxes(i) ~= app.StateSpaceAxes
                delete(existingAxes(i));
            end
        end
        
        % Restore original position if it was changed
        if isfield(app, 'originalStateSpaceAxesPosition') && ~isempty(app.originalStateSpaceAxesPosition)
            app.StateSpaceAxes.Position = app.originalStateSpaceAxesPosition;
        else
            % Store original position for future resets
            app.originalStateSpaceAxesPosition = app.StateSpaceAxes.Position;
        end
        
        % IMPORTANT: Clear ALL content
        cla(app.StateSpaceAxes, 'reset');
        
        % Clear any error text in the HTML visualization
        app.StateSpaceVisHTML.HTMLSource = '<html><body></body></html>';
        
        % Set consistent properties for all analyses
        app.StateSpaceAxes.XLim = [-5, 5];
        app.StateSpaceAxes.YLim = [-5, 5];
        app.StateSpaceAxes.XGrid = 'on';
        app.StateSpaceAxes.YGrid = 'on';
        app.StateSpaceAxes.Box = 'on';
        app.StateSpaceAxes.GridLineStyle = ':';
        app.StateSpaceAxes.GridAlpha = 0.3;
        app.StateSpaceAxes.Visible = 'on';
        app.StateSpaceAxes.FontSize = 12;
        
        % Reset the title and labels
        title(app.StateSpaceAxes, 'System Visualization');
        xlabel(app.StateSpaceAxes, 'X-Axis');
        ylabel(app.StateSpaceAxes, 'Y-Axis');
        
        % Ensure there are no legend entries remaining
        legend(app.StateSpaceAxes, 'off');
        
        % Also clear any text elements that might persist
        delete(findall(app.StateSpaceAxes, 'Type', 'Text'));
        
        % Clear any status messages in the information panel
        if isprop(app, 'StateSpaceInfoHTML') && ~isempty(app.StateSpaceInfoHTML)
            currentHTML = app.StateSpaceInfoHTML.HTMLSource;
            % Remove any status/error messages but keep the system information
            if contains(currentHTML, 'style="color: red') || ...
               contains(currentHTML, 'style="color: blue') || ...
               contains(currentHTML, 'style="color: orange')
                
                % Try to extract and preserve only the system info section
                try
                    % Find the system info section (usually has a title or specific class)
                    if contains(currentHTML, 'class="system-title"')
                        % Keep only the essential system information
                        parts = regexp(currentHTML, '(<div class="system-title">.*?)<div style="color:', 'tokens', 'once');
                        if ~isempty(parts)
                            app.StateSpaceInfoHTML.HTMLSource = ['<html>', parts{1}, '</div></html>'];
                        end
                    end
                catch
                    % If extraction fails, just provide a neutral message
                    app.StateSpaceInfoHTML.HTMLSource = '<html><body><p style="font-family: Arial; text-align: center; margin-top: 50px; color: #666;">Ready for analysis.</p></body></html>';
                end
            end
        end
        
    catch ME
        % Just log errors, don't interrupt with error message
        disp(['Warning: Error resetting axes: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        % Try a more aggressive reset
        try
            cla(app.StateSpaceAxes);
            app.StateSpaceAxes.Visible = 'on';
            app.StateSpaceVisHTML.Visible = 'off';
        catch
            % Last resort - do nothing and continue
        end
    end
end


function updateStatusHTML(app, message, type)
    % UPDATESTATUSHTML - Updates the status HTML with a new message
    % Replaces existing status messages instead of appending
    %
    % Inputs:
    %   app - The app object
    %   message - The message to display
    %   type - Type of message: 'info', 'success', 'warning', 'error'
    
    % Get current HTML
    currentHTML = app.StateSpaceInfoHTML.HTMLSource;
    
    % Remove any existing status messages (blue info, green success, orange warning, red error)
    pattern = '<div style="color: (blue|green|orange|red); text-align: center; margin-top: 10px;.*?</div>';
    cleanedHTML = regexprep(currentHTML, pattern, '');
    
    % Set color based on message type
    switch lower(type)
        case 'info'
            color = 'blue';
        case 'success'
            color = 'green';
        case 'warning'
            color = 'orange';
        case 'error'
            color = 'red';
        otherwise
            color = 'blue'; % Default
    end
    
    % Create new status message
    newStatus = ['<div style="color: ' color '; text-align: center; margin-top: 10px; ' ...
                'font-weight: bold; padding: 10px; border: 1px solid ' color '; border-radius: 5px;">' ...
                message '</div>'];
    
    % Update HTML content with new status message
    app.StateSpaceInfoHTML.HTMLSource = [cleanedHTML, newStatus];
end


function htmlContent = createControllabilityHTML(app, A, B, Co, rankCo, n, isControllable)
    % CREATECONTROLLABILITYHTML - Creates HTML table for controllability analysis
    
    % Get eigenvalues for mode information
    eigs = eig(A);
    
    % Calculate singular values to assess "degree" of controllability
    try
        [~, S, ~] = svd(Co);
        if size(S,1) >= 1 && size(S,2) >= 1
            sigma_values = diag(S);
        else
            sigma_values = S(1,1);
        end
        condition_number = max(sigma_values)/min(sigma_values);
    catch
        sigma_values = [];
        condition_number = Inf;
    end
    
    % Start building HTML with consistent styling - matched with system info table
    htmlContent = ['<html><head><style>', ...
                'body { font-family: Arial, sans-serif; font-size: 12px; margin: 0; padding: 0; }', ...
                'table { border-collapse: collapse; width: 100%; margin-bottom: 12px; border: 1px solid #ddd; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 40%; font-weight: bold; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.warning { color: #F39C12; font-weight: bold; }', ...
                '.yes { color: #2ECC71; font-weight: bold; }', ...
                '.no { color: #E74C3C; font-weight: bold; }', ...
                '.system-title { text-align: center; font-size: 16px; margin: 8px 0; color: #2980B9; font-weight: bold; }', ...
                '.summary-box { background-color: #f8f8f8; border: 1px solid #ddd; border-radius: 5px; padding: 10px; margin-top: 8px; }', ...
                '</style></head><body>'];
    
    % Add system title
    htmlContent = [htmlContent, '<div class="system-title">Controllability Analysis</div>'];
    
    % Add primary controllability table
    htmlContent = [htmlContent, '<table>'];
    
    % Add header
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<th colspan="2">System Controllability</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add controllability status with color
    if isControllable
        statusColor = 'yes';
        statusText = 'CONTROLLABLE';
    else
        statusColor = 'no';
        statusText = 'NOT CONTROLLABLE';
    end
    
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>System Status</td>'];
    htmlContent = [htmlContent, '<td class="', statusColor, '">', statusText, '</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add rank information
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>Controllability Matrix Rank</td>'];
    htmlContent = [htmlContent, '<td>', num2str(rankCo), ' / ', num2str(n), '</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add number of states and inputs
    [~, m] = size(B);
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>System Size</td>'];
    htmlContent = [htmlContent, '<td>', num2str(n), ' states, ', num2str(m), ' inputs</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add condition number if available
    if ~isempty(sigma_values)
        htmlContent = [htmlContent, '<tr>'];
        htmlContent = [htmlContent, '<td>Condition Number</td>'];
        
        if isinf(condition_number)
            htmlContent = [htmlContent, '<td>∞ (Ill-conditioned)</td>'];
        else
            htmlContent = [htmlContent, '<td>', num2str(condition_number, '%.2e'), '</td>'];
        end
        
        htmlContent = [htmlContent, '</tr>'];
    end
    
    % Close the table
    htmlContent = [htmlContent, '</table>'];
    
    % Add eigenvalue information
    htmlContent = [htmlContent, '<table>'];
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<th colspan="3">Eigenvalues & Mode Controllability</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    htmlContent = [htmlContent, '<tr style="background-color: #e6e6e6;">'];
    htmlContent = [htmlContent, '<th style="width: 20%; text-align: center; background-color: transparent;">Mode</th>'];
    htmlContent = [htmlContent, '<th style="width: 50%; text-align: center; background-color: transparent;">Eigenvalue</th>'];
    htmlContent = [htmlContent, '<th style="width: 30%; text-align: center; background-color: transparent;">Status</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add each eigenvalue with controllability assessment
    for i = 1:length(eigs)
        % Format eigenvalue
        if imag(eigs(i)) == 0
            eigStr = num2str(real(eigs(i)), '%.4f');
        elseif real(eigs(i)) == 0
            eigStr = [num2str(imag(eigs(i)), '%.4f'), 'i'];
        elseif imag(eigs(i)) > 0
            eigStr = [num2str(real(eigs(i)), '%.4f'), ' + ', num2str(imag(eigs(i)), '%.4f'), 'i'];
        else
            eigStr = [num2str(real(eigs(i)), '%.4f'), ' - ', num2str(abs(imag(eigs(i))), '%.4f'), 'i'];
        end
        
        % Check if mode is controllable (simplified)
        if isControllable || i <= rankCo
            modeClass = 'yes';
            modeStatus = 'Controllable';
        else
            modeClass = 'no';
            modeStatus = 'Uncontrollable';
        end
        
        bgClass = '';
        if mod(i, 2) == 0
            bgClass = ' style="background-color: #F2F2F2;"';
        end
        
        htmlContent = [htmlContent, '<tr', bgClass, '>'];
        htmlContent = [htmlContent, '<td style="text-align: center;">λ', num2str(i), '</td>'];
        htmlContent = [htmlContent, '<td style="text-align: center;">', eigStr, '</td>'];
        htmlContent = [htmlContent, '<td class="', modeClass, '" style="text-align: center;">', modeStatus, '</td>'];
        htmlContent = [htmlContent, '</tr>'];
    end
    
    htmlContent = [htmlContent, '</table>'];
    
    % Add explanation and interpretation in a summary box
    htmlContent = [htmlContent, '<div class="summary-box">'];
    if isControllable
        htmlContent = [htmlContent, '<p style="margin-top: 0; font-style: italic; color: #555;">The system is fully controllable. All states can be driven to any desired value using the available inputs.</p>'];
    else
        htmlContent = [htmlContent, '<p style="margin-top: 0; font-style: italic; color: #555;">The system is not fully controllable. Some states cannot be controlled using the available inputs.</p>'];
        
        % Add remediation suggestions
        htmlContent = [htmlContent, '<p style="margin-bottom: 0;"><b>Possible remediation:</b></p>'];
        htmlContent = [htmlContent, '<ul style="margin-top: 5px;">'];
        htmlContent = [htmlContent, '<li>Add more input actuators</li>'];
        htmlContent = [htmlContent, '<li>Modify the system structure</li>'];
        htmlContent = [htmlContent, '<li>Consider controlling only the controllable subspace</li>'];
        htmlContent = [htmlContent, '</ul>'];
    end
    htmlContent = [htmlContent, '</div>'];
    
    % Close the entire body
    htmlContent = [htmlContent, '</body></html>'];
end


function htmlContent = createObservabilityHTML(app, A, C, Ob, rankOb, n, isObservable)
    % CREATEOBSERVABILITYHTML - Creates HTML table for observability analysis
    
    % Get eigenvalues for mode information
    eigs = eig(A);
    
    % Calculate singular values to assess "degree" of observability
    try
        [~, S, ~] = svd(Ob);
        if size(S,1) >= 1 && size(S,2) >= 1
            sigma_values = diag(S);
        else
            sigma_values = S(1,1);
        end
        condition_number = max(sigma_values)/min(sigma_values);
    catch
        sigma_values = [];
        condition_number = Inf;
    end
    
    % Start building HTML with consistent styling - matched with system info table
    htmlContent = ['<html><head><style>', ...
                'body { font-family: Arial, sans-serif; font-size: 12px; margin: 0; padding: 0; }', ...
                'table { border-collapse: collapse; width: 100%; margin-bottom: 12px; border: 1px solid #ddd; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 40%; font-weight: bold; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.warning { color: #F39C12; font-weight: bold; }', ...
                '.yes { color: #2ECC71; font-weight: bold; }', ...
                '.no { color: #E74C3C; font-weight: bold; }', ...
                '.system-title { text-align: center; font-size: 16px; margin: 8px 0; color: #2980B9; font-weight: bold; }', ...
                '.summary-box { background-color: #f8f8f8; border: 1px solid #ddd; border-radius: 5px; padding: 10px; margin-top: 8px; }', ...
                '</style></head><body>'];
    
    % Add system title
    htmlContent = [htmlContent, '<div class="system-title">Observability Analysis</div>'];
    
    % Add primary observability table
    htmlContent = [htmlContent, '<table>'];
    
    % Add header
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<th colspan="2">System Observability</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add observability status with color
    if isObservable
        statusColor = 'yes';
        statusText = 'OBSERVABLE';
    else
        statusColor = 'no';
        statusText = 'NOT OBSERVABLE';
    end
    
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>System Status</td>'];
    htmlContent = [htmlContent, '<td class="', statusColor, '">', statusText, '</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add rank information
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>Observability Matrix Rank</td>'];
    htmlContent = [htmlContent, '<td>', num2str(rankOb), ' / ', num2str(n), '</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add number of states and outputs
    [p, ~] = size(C);
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<td>System Size</td>'];
    htmlContent = [htmlContent, '<td>', num2str(n), ' states, ', num2str(p), ' outputs</td>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add condition number if available
    if ~isempty(sigma_values)
        htmlContent = [htmlContent, '<tr>'];
        htmlContent = [htmlContent, '<td>Condition Number</td>'];
        
        if isinf(condition_number)
            htmlContent = [htmlContent, '<td>∞ (Ill-conditioned)</td>'];
        else
            htmlContent = [htmlContent, '<td>', num2str(condition_number, '%.2e'), '</td>'];
        end
        
        htmlContent = [htmlContent, '</tr>'];
    end
    
    % Close the table
    htmlContent = [htmlContent, '</table>'];
    
    % Add eigenvalue information
    htmlContent = [htmlContent, '<table>'];
    htmlContent = [htmlContent, '<tr>'];
    htmlContent = [htmlContent, '<th colspan="3">Eigenvalues & Mode Observability</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    htmlContent = [htmlContent, '<tr style="background-color: #e6e6e6;">'];
    htmlContent = [htmlContent, '<th style="width: 20%; text-align: center; background-color: transparent;">Mode</th>'];
    htmlContent = [htmlContent, '<th style="width: 50%; text-align: center; background-color: transparent;">Eigenvalue</th>'];
    htmlContent = [htmlContent, '<th style="width: 30%; text-align: center; background-color: transparent;">Status</th>'];
    htmlContent = [htmlContent, '</tr>'];
    
    % Add each eigenvalue with observability assessment
    for i = 1:length(eigs)
        % Format eigenvalue
        if imag(eigs(i)) == 0
            eigStr = num2str(real(eigs(i)), '%.4f');
        elseif real(eigs(i)) == 0
            eigStr = [num2str(imag(eigs(i)), '%.4f'), 'i'];
        elseif imag(eigs(i)) > 0
            eigStr = [num2str(real(eigs(i)), '%.4f'), ' + ', num2str(imag(eigs(i)), '%.4f'), 'i'];
        else
            eigStr = [num2str(real(eigs(i)), '%.4f'), ' - ', num2str(abs(imag(eigs(i))), '%.4f'), 'i'];
        end
        
        % Check if mode is observable (simplified)
        if isObservable || i <= rankOb
            modeClass = 'yes';
            modeStatus = 'Observable';
        else
            modeClass = 'no';
            modeStatus = 'Unobservable';
        end
        
        bgClass = '';
        if mod(i, 2) == 0
            bgClass = ' style="background-color: #F2F2F2;"';
        end
        
        htmlContent = [htmlContent, '<tr', bgClass, '>'];
        htmlContent = [htmlContent, '<td style="text-align: center;">λ', num2str(i), '</td>'];
        htmlContent = [htmlContent, '<td style="text-align: center;">', eigStr, '</td>'];
        htmlContent = [htmlContent, '<td class="', modeClass, '" style="text-align: center;">', modeStatus, '</td>'];
        htmlContent = [htmlContent, '</tr>'];
    end
    
    htmlContent = [htmlContent, '</table>'];
    
    % Add explanation and interpretation in a summary box
    htmlContent = [htmlContent, '<div class="summary-box">'];
    if isObservable
        htmlContent = [htmlContent, '<p style="margin-top: 0; font-style: italic; color: #555;">The system is fully observable. All internal states can be determined from observing the system outputs.</p>'];
    else
        htmlContent = [htmlContent, '<p style="margin-top: 0; font-style: italic; color: #555;">The system is not fully observable. Some states cannot be determined from the available outputs.</p>'];
        
        % Add remediation suggestions
        htmlContent = [htmlContent, '<p style="margin-bottom: 0;"><b>Possible remediation:</b></p>'];
        htmlContent = [htmlContent, '<ul style="margin-top: 5px;">'];
        htmlContent = [htmlContent, '<li>Add more sensor outputs</li>'];
        htmlContent = [htmlContent, '<li>Modify the sensor placement/configuration</li>'];
        htmlContent = [htmlContent, '<li>Design a reduced-order observer for the observable subspace</li>'];
        htmlContent = [htmlContent, '</ul>'];
    end
    htmlContent = [htmlContent, '</div>'];
    
    % Close the entire body
    htmlContent = [htmlContent, '</body></html>'];
end
    
function performControllabilityAnalysis(app)
    % PERFORMCONTROLLABILITYANALYSIS - Analyze system controllability
    % with enhanced HTML visualization
    
    % Check if model exists
    if ~isprop(app, 'LinearizedModel') || isempty(app.LinearizedModel)
        updateStatusHTML(app, 'No state-space model available.', 'error');
        return;
    end
    
    % Get the model
    ss_model = app.LinearizedModel;
    A = ss_model.A;
    B = ss_model.B;
    
    % Get system dimensions
    [n_states, n_inputs] = size(B);
    
    % Get input channel parameter
    param_str = 't=0:0.1:100';
    
    % Parse input channel (default to all)
    selected_inputs = 1:n_inputs;
    
    if ~strcmpi(param_str, 'all')
        try
            % Try to parse as array
            if contains(param_str, '[') && contains(param_str, ']')
                selected_inputs = eval(param_str);
            else
                % Try to parse as scalar
                selected_inputs = str2double(param_str);
                
                if isnan(selected_inputs)
                    selected_inputs = 1:n_inputs;
                end
            end
            
            % Validate input indices
            if any(selected_inputs < 1) || any(selected_inputs > n_inputs)
                updateStatusHTML(app, sprintf('Input indices must be between 1 and %d. Using all inputs.', n_inputs), 'warning');
                selected_inputs = 1:n_inputs;
            end
        catch
            updateStatusHTML(app, 'Could not parse input channel. Using all inputs.', 'warning');
            selected_inputs = 1:n_inputs;
        end
    end
    
    % Select B columns for the selected inputs
    B_selected = B(:, selected_inputs);
    
    % Compute controllability matrix
    C_matrix = ctrb(A, B_selected);
    
    % Compute rank of controllability matrix
    C_rank = rank(C_matrix);
    
    % System is controllable if the rank equals the number of states
    isControllable = (C_rank == n_states);
    
    % Create HTML with better styling using the existing function
    htmlContent = createControllabilityHTML(app, A, B_selected, C_matrix, C_rank, n_states, isControllable);
    
    app.StateSpaceAxes.Visible = 'off';

    % Update HTML display
    app.StateSpaceVisHTML.HTMLSource = htmlContent;
    app.StateSpaceVisHTML.Visible = 'on';
end
    
function performObservabilityAnalysis(app)
    % PERFORMOBSERVABILITYANALYSIS - Analyze system observability
    % with enhanced HTML visualization
    
    % Check if model exists
    if ~isprop(app, 'LinearizedModel') || isempty(app.LinearizedModel)
        updateStatusHTML(app, 'No state-space model available.', 'error');
        return;
    end
    
    % Get the model
    ss_model = app.LinearizedModel;
    A = ss_model.A;
    C = ss_model.C;
    
    % Get system dimensions
    [n_outputs, n_states] = size(C);
    
    % Get output channel parameter
    param_str = 't=0:0.1:100';
    
    % Parse output channel (default to all)
    selected_outputs = 1:n_outputs;
    
    if ~strcmpi(param_str, 'all')
        try
            % Try to parse as array
            if contains(param_str, '[') && contains(param_str, ']')
                selected_outputs = eval(param_str);
            else
                % Try to parse as scalar
                selected_outputs = str2double(param_str);
                
                if isnan(selected_outputs)
                    selected_outputs = 1:n_outputs;
                end
            end
            
            % Validate output indices
            if any(selected_outputs < 1) || any(selected_outputs > n_outputs)
                updateStatusHTML(app, sprintf('Output indices must be between 1 and %d. Using all outputs.', n_outputs), 'warning');
                selected_outputs = 1:n_outputs;
            end
        catch
            updateStatusHTML(app, 'Could not parse output channel. Using all outputs.', 'warning');
            selected_outputs = 1:n_outputs;
        end
    end
    
    % Select C rows for the selected outputs
    C_selected = C(selected_outputs, :);
    
    % Compute observability matrix
    O_matrix = obsv(A, C_selected);
    
    % Compute rank of observability matrix
    O_rank = rank(O_matrix);
    
    % System is observable if the rank equals the number of states
    isObservable = (O_rank == n_states);
    
    % Create HTML with better styling using the existing function
    htmlContent = createObservabilityHTML(app, A, C_selected, O_matrix, O_rank, n_states, isObservable);

    app.StateSpaceAxes.Visible = 'off';

    % Update HTML display
    app.StateSpaceVisHTML.HTMLSource = htmlContent;
    app.StateSpaceVisHTML.Visible = 'on';
end      
        
function [G_tf, K_tf, stable] = convertSingleChannelWithController(app, ssModel, K_full, inputIdx, outputIdx)
    % CONVERTSINGLECONTROLCHANNEL Creates transfer functions for a single I/O channel with controller
    %
    % Parameters:
    %   ssModel - State space model (ss object)
    %   K_full - Full state feedback gain matrix
    %   inputIdx - Selected input index
    %   outputIdx - Selected output index
    %
    % Returns:
    %   G_tf - Plant transfer function for the selected channel
    %   K_tf - Corresponding controller transfer function
    %   stable - Boolean indicating stability of the closed-loop system

    % Extract state-space matrices
    A = ssModel.A;
    B = ssModel.B;
    C = ssModel.C;
    D = ssModel.D;
    Ts = ssModel.Ts;
    
    % Get system dimensions
    [n_states, n_inputs] = size(B);
    [n_outputs, ~] = size(C);
    
    % Validate input and output indices
    if inputIdx > n_inputs || inputIdx < 1
        error('Input index out of range');
    end
    if outputIdx > n_outputs || outputIdx < 1
        error('Output index out of range');
    end
    
    % Check K matrix dimensions
    [k_rows, k_cols] = size(K_full);
    if k_cols ~= n_states
        warning('K matrix dimensions (%dx%d) don''t match expected dimensions (%dx%d)', ...
            k_rows, k_cols, n_inputs, n_states);
    end
    
    % Check if we're using advanced controllers (LQG, H-inf)
    isAdvancedController = false;
    controllerType = '';
    
    % Check if we have controller info in app or the StateFeedbackGain has special properties
    if isprop(app, 'ControllerInfo') && ~isempty(app.ControllerInfo) && ...
       isfield(app.ControllerInfo, 'type')
        isAdvancedController = true;
        controllerType = app.ControllerInfo.type;
        disp(['Detected advanced controller type: ' controllerType]);
    end
    
    % 1. Create plant transfer function for the selected channel
    try
        % Use ss2tf for reliable conversion 
        [num_g, den_g] = ss2tf(A, B, C, D, inputIdx);
        
        % For MIMO output, take the specific output row
        if n_outputs > 1
            num_g = num_g(outputIdx, :);
        end
        
        % Clean up numerical noise
        num_g = cleanup_tf_coefficients(num_g);
        den_g = cleanup_tf_coefficients(den_g);
        
        % Create transfer function
        G_tf = tf(num_g, den_g, Ts);
        
        disp(['Created plant transfer function G for input ' num2str(inputIdx) ...
             ' to output ' num2str(outputIdx)]);
    catch ME
        error('Error creating plant transfer function: %s', ME.message);
    end
    
    % Check stability of the open-loop system
    try
        if Ts > 0  % Discrete-time
            stable_open = all(abs(pole(G_tf)) < 1);
        else  % Continuous-time
            stable_open = all(real(pole(G_tf)) < 0);
        end
        
        if stable_open
            disp('Plant G(s) is stable');
        else
            disp('Plant G(s) is unstable');
        end
    catch
        disp('Could not check plant stability');
        stable_open = false;
    end
    
    % 2. Create corresponding controller transfer function based on controller type
    try
        % Handle different controller types
        if isAdvancedController
            switch controllerType
                case 'LQG'
                    % For LQG, use the stored observer-based controller
                    if isfield(app.ControllerInfo, 'K_ss')
                        % Use the pre-computed state-space LQG controller
                        K_ss = app.ControllerInfo.K_ss;
                        
                        % For MIMO, extract the relevant input-output channel
                        if size(K_ss.C, 1) > 1 || size(K_ss.B, 2) > 1
                            % Extract SISO controller for the selected channel
                            K_ss_channel = ss(K_ss.A, K_ss.B(:,outputIdx), K_ss.C(inputIdx,:), K_ss.D(inputIdx,outputIdx), Ts);
                            K_tf = tf(K_ss_channel);
                        else
                            % Already SISO
                            K_tf = tf(K_ss);
                        end
                        
                        disp('Created LQG controller transfer function from stored state-space model');
                    elseif isfield(app.ControllerInfo, 'Ac') && isfield(app.ControllerInfo, 'Bc') && ...
                           isfield(app.ControllerInfo, 'Cc') && isfield(app.ControllerInfo, 'Dc')
                        % Create from individual matrices
                        Ac = app.ControllerInfo.Ac;
                        Bc = app.ControllerInfo.Bc;
                        Cc = app.ControllerInfo.Cc;
                        Dc = app.ControllerInfo.Dc;
                        
                        % For MIMO, extract the relevant channel
                        if size(Cc, 1) > 1 || size(Bc, 2) > 1
                            if size(Cc, 1) >= inputIdx && size(Bc, 2) >= outputIdx
                                Cc_channel = Cc(inputIdx, :);
                                Bc_channel = Bc(:, outputIdx);
                                Dc_channel = Dc(inputIdx, outputIdx);
                            else
                                Cc_channel = Cc(1, :);  % Use first row if index out of bounds
                                Bc_channel = Bc(:, 1);  % Use first column if index out of bounds
                                Dc_channel = 0;         % Default to zero
                            end
                        else
                            Cc_channel = Cc;
                            Bc_channel = Bc;
                            Dc_channel = Dc;
                        end
                        
                        % Create state-space model
                        K_ss = ss(Ac, Bc_channel, Cc_channel, Dc_channel, Ts);
                        K_tf = tf(K_ss);
                        
                        disp('Created LQG controller transfer function from component matrices');
                    else
                        % Fall back to creating observer-based controller now
                        disp('No pre-computed LQG controller found, creating observer-based controller');
                        K_tf = createObserverBasedController(A, B, C, K_full, inputIdx, outputIdx, Ts);
                    end
                    
                case 'Hinf'
                    % For H-infinity, use the stored controller if available
                    if isfield(app.ControllerInfo, 'K_hinf')
                        % Use the H-infinity controller
                        K_hinf = app.ControllerInfo.K_hinf;
                        
                        % Extract the relevant channel for MIMO
                        if size(K_hinf.C, 1) > 1 || size(K_hinf.B, 2) > 1
                            % Need to identify the correct input-output pairing
                            K_hinf_channel = ss(K_hinf.A, K_hinf.B(:,outputIdx), K_hinf.C(inputIdx,:), K_hinf.D(inputIdx,outputIdx), Ts);
                            K_tf = tf(K_hinf_channel);
                        else
                            % Already SISO
                            K_tf = tf(K_hinf);
                        end
                        
                        disp('Created H-infinity controller transfer function');
                    else
                        % Fall back to creating an observer-based controller
                        disp('No H-infinity controller found, creating observer-based controller');
                        K_tf = createObserverBasedController(A, B, C, K_full, inputIdx, outputIdx, Ts);
                    end
                
                otherwise
                    % Unknown advanced controller type, use observer-based
                    disp(['Unknown advanced controller type: ' controllerType]);
                    disp('Falling back to observer-based controller');
                    K_tf = createObserverBasedController(A, B, C, K_full, inputIdx, outputIdx, Ts);
            end
        else
            % Standard state feedback - create observer-based controller
            K_tf = createObserverBasedController(A, B, C, K_full, inputIdx, outputIdx, Ts);
        end
        
        % Clean up the controller transfer function
        [num_k, den_k] = tfdata(K_tf, 'v');
        num_k = cleanup_tf_coefficients(num_k);
        den_k = cleanup_tf_coefficients(den_k);
        K_tf = tf(num_k, den_k, Ts);
        
    catch ME
        warning('Error creating controller transfer function: %s', ME.message);
        disp('Falling back to simple gain controller');
        
        % Extract the relevant row of K
        if k_rows >= inputIdx
            K_row = K_full(inputIdx, :);
        else
            K_row = K_full(1, :);  % Use first row as fallback
        end
        
        % Simple gain controller
        K_tf = tf(norm(K_row), 1, Ts);
    end
    
    % 3. Check stability of the closed-loop system
    try
        % Create loop transfer function
        L_tf = series(G_tf, K_tf);
        
        % Create closed-loop transfer function
        T_tf = feedback(L_tf, 1);
        
        % Check stability
        if Ts > 0  % Discrete-time
            stable = all(abs(pole(T_tf)) < 1);
        else  % Continuous-time
            stable = all(real(pole(T_tf)) < 0);
        end
        
        if stable
            disp('Closed-loop system with controller is stable');
        else
            disp('WARNING: Closed-loop system with controller is UNSTABLE!');
            
            % For unstable closed-loop, try to redesign the controller
            if ~stable && ~stable_open
                disp('Both plant and closed-loop are unstable. Attempting stabilizing controller design...');
                
                % Try a more robust controller design for unstable systems
                K_tf_robust = designStabilizingController(G_tf, Ts);
                
                % Check if the new controller stabilizes the system
                T_tf_robust = feedback(series(G_tf, K_tf_robust), 1);
                
                if Ts > 0  % Discrete-time
                    stable_robust = all(abs(pole(T_tf_robust)) < 1);
                else  % Continuous-time
                    stable_robust = all(real(pole(T_tf_robust)) < 0);
                end
                
                if stable_robust
                    disp('Successfully designed stabilizing controller!');
                    K_tf = K_tf_robust;
                    stable = true;
                else
                    disp('Failed to design stabilizing controller for this channel');
                end
            end
        end
    catch ME
        warning('Error checking closed-loop stability: %s', ME.message);
        disp('Assuming system is stable for visualization purposes');
        stable = true;  % Assume stable for user experience purposes
    end
end


function showMIMOControls(app, show)
    % SHOWMIMOCONTROLS Shows or hides the MIMO selection controls
    % Parameters:
    %   app - App object
    %   show - Boolean, true to show, false to hide
    
    % Show/hide the existing components
    app.MIMOInputAndOutputSelectionLabel.Visible = show;
    app.InputIndexLabel.Visible = show;
    app.OutputIndexLabel.Visible = show;
    app.InputIndexInput.Visible = show;
    app.OutputIndexInput.Visible = show;
    app.UpdateGandKButton.Visible = show;
    
    % Update limits if showing
    if show && isprop(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
        [~, n_inputs] = size(app.LinearizedModel.B);
        [n_outputs, ~] = size(app.LinearizedModel.C);
        
        % Set limits for the spinners
        app.InputIndexInput.Limits = [1 max(1, n_inputs)];
        app.OutputIndexInput.Limits = [1 max(1, n_outputs)];
    end
end


function handleDirectConversion(app, currentHTML)
    % HANDLEDIRECTCONVERSION - Handles direct conversion from state space to transfer function
    % without controller design
    %
    % Parameters:
    %   app - The application object
    %   currentHTML - Current HTML content of the state space info panel
    
    try
        % Update status
        app.StateSpaceInfoHTML.HTMLSource = [currentHTML, '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Converting to transfer function...</div></html>'];
        drawnow;
        
        % Get selected input and output indices
        inputIdx = app.InputIndexInput.Value;
        outputIdx = app.OutputIndexInput.Value;
        
        % Validate indices
        [~, n_inputs] = size(app.LinearizedModel.B);
        [n_outputs, ~] = size(app.LinearizedModel.C);
        
        if inputIdx > n_inputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Input index ' num2str(inputIdx) ' exceeds the number of inputs (' num2str(n_inputs) ').']);
            return;
        end
        
        if outputIdx > n_outputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Output index ' num2str(outputIdx) ' exceeds the number of outputs (' num2str(n_outputs) ').']);
            return;
        end
        
        % Convert to transfer function
        [app.G, success, errorMsg] = convertSS2TF(app, app.LinearizedModel, inputIdx, outputIdx);
        
        if ~success
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error converting to transfer function: ' errorMsg]);
            return;
        end
        
        % Set K to unity (no controller)
        app.K = tf(1);
        
        % Show the transfer functions
        displayTransferFunctionInApp(app, app.G, 'G');
        displayTransferFunctionInApp(app, app.K, 'K');
        
        % Show MIMO controls for MIMO systems
        if n_inputs > 1 || n_outputs > 1
            showMIMOControls(app, true);
        end
        
        % Success message
        successHTML = '<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successHTML = [successHTML, 'State-space model successfully converted to transfer function G(s)!<br><br>'];
        
        % Transfer function details
        [num, den] = tfdata(app.G, 'v');
        successHTML = [successHTML, 'Transfer function G(s) created<br>'];
        successHTML = [successHTML, 'Numerator order: ', num2str(length(num)-1), '<br>'];
        successHTML = [successHTML, 'Denominator order: ', num2str(length(den)-1), '<br><br>'];
        
        % Display poles and zeros
        try
            poles = pole(app.G);
            zeros = zero(app.G);
            
            if ~isempty(poles)
                successHTML = [successHTML, 'Poles: '];
                for i = 1:length(poles)
                    if i > 1
                        successHTML = [successHTML, ', '];
                    end
                    if imag(poles(i)) == 0
                        successHTML = [successHTML, num2str(real(poles(i)), '%.4g')];
                    else
                        successHTML = [successHTML, num2str(real(poles(i)), '%.4g'), ' + ', num2str(imag(poles(i)), '%.4g'), 'i'];
                    end
                end
                successHTML = [successHTML, '<br>'];
            end
            
            if ~isempty(zeros)
                successHTML = [successHTML, 'Zeros: '];
                for i = 1:length(zeros)
                    if i > 1
                        successHTML = [successHTML, ', '];
                    end
                    if imag(zeros(i)) == 0
                        successHTML = [successHTML, num2str(real(zeros(i)), '%.4g')];
                    else
                        successHTML = [successHTML, num2str(real(zeros(i)), '%.4g'), ' + ', num2str(imag(zeros(i)), '%.4g'), 'i'];
                    end
                end
                successHTML = [successHTML, '<br>'];
            end
        catch
            % Skip poles/zeros if calculation fails
        end
        
        % Add channel info
        successHTML = [successHTML, '<br>Channel: Input ' num2str(inputIdx) ' to Output ' num2str(outputIdx)];
        
        if n_inputs > 1 || n_outputs > 1
            successHTML = [successHTML, '<br>Use MIMO controls to select different channels.'];
        end
        
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'Controller K(s) = 1 (unity)<br>'];
        successHTML = [successHTML, 'You can now analyze the control system using the Analyze button.'];
        successHTML = [successHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = [currentHTML, successHTML];
        
        % Highlight the Analyze button
        app.AnalyzeButton.BackgroundColor = [0.4 0.8 0.4];  % Green highlight
        
    catch ME
        % Handle errors
        errorHTML = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                   'Error in direct conversion:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = [currentHTML, errorHTML];
        disp(['Error in handleDirectConversion: ' ME.message]);
        disp(['Stack trace: ' getReport(ME, 'extended')]);
    end
end


% Helper function to update UI elements based on selected button
function updateUIForSelection(app, selectedButton)
    % This function could be expanded based on your specific UI needs
    
    % Example: Update a label to show which response is being calculated
    if isprop(app, 'ResponseTypeLabel') && ~isempty(app.ResponseTypeLabel)
        if selectedButton == app.StepButton
            app.ResponseTypeLabel.Text = 'Step Response';
        elseif selectedButton == app.SetpointButton
            app.ResponseTypeLabel.Text = 'Setpoint Response';
        elseif selectedButton == app.r_d12Button
            app.ResponseTypeLabel.Text = 'Response with Disturbances';
        end
    end
    
    % Example: Enable/disable other UI elements based on selection
    % (e.g., perhaps certain parameters are only relevant for specific response types)
    if selectedButton == app.StepButton
        % For step response, maybe disable disturbance-related controls
        if isprop(app, 'DisturbancePanel') && ~isempty(app.DisturbancePanel)
            app.DisturbancePanel.Visible = 'off';
        end
    elseif selectedButton == app.r_d12Button
        % For response with disturbances, ensure disturbance panel is visible
        if isprop(app, 'DisturbancePanel') && ~isempty(app.DisturbancePanel)
            app.DisturbancePanel.Visible = 'on';
        end
    end
end

% Helper function to reset status label after delay
function resetStatusLabel(app, originalText)
    if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel)
        app.StatusLabel.Text = originalText;
        app.StatusLabel.FontColor = [0, 0, 0]; % Reset to black
    end
end
        

function displayTFKeyParams(app, y, t_vector, responseType)
    % DISPLAYTFKEYPARAMS
    % ---------------
    % Enhanced function to display key response parameters with improved
    % formatting and error handling for different response types.
    %
    % Parameters:
    % - app: Application handle with HTMLkeyparamTable_3 component
    % - y: Response signal vector
    % - t_vector: Time vector
    % - responseType: String indicating the type of response ('step', 'setpoint', or 'combined')
    
    if nargin < 4
        responseType = 'step'; % Default to step response if not specified
    end
    
    try
        % Ensure y and t_vector have matching lengths
        if length(y) ~= length(t_vector)
            disp('Warning: Sizes of y and t_vector mismatch in displayKeyParameters');
            if length(y) > length(t_vector)
                y = y(1:length(t_vector));
            elseif length(t_vector) > length(y)
                t_vector = t_vector(1:length(y));
            end
        end
        
        % Initialize parameters with default values
        riseTime = NaN;
        settlingTime = NaN;
        settlingMin = NaN;
        settlingMax = NaN;
        overshoot = NaN;
        undershoot = NaN;
        peak = NaN;
        peakTime = NaN;
        transientTime = NaN;
        
        % Different analysis based on response type
        switch responseType
            case 'step'
                % Use stepinfo for step response analysis
                info = stepinfo(y, t_vector);
                
                % Extract data from the stepinfo structure
                riseTime = info.RiseTime;
                settlingTime = info.SettlingTime;
                settlingMin = info.SettlingMin;
                settlingMax = info.SettlingMax;
                overshoot = info.Overshoot;
                undershoot = info.Undershoot;
                peak = info.Peak;
                peakTime = info.PeakTime;
                
                % Check for TransientTime field
                if isfield(info, 'TransientTime')
                    transientTime = info.TransientTime;
                end
                
            case 'setpoint'
                % Custom analysis for setpoint tracking
                % Calculate final value as the average of last few samples
                finalValue = mean(y(end-min(10, length(y)-1):end));
                
                % Calculate rise time (time to reach 90% of final value)
                riseThreshold = 0.9 * finalValue;
                riseIndices = find(y >= riseThreshold, 1);
                if ~isempty(riseIndices)
                    riseTime = t_vector(riseIndices(1));
                end
                
                % Calculate peak and peak time
                [peak, peakIndex] = max(abs(y));
                peakTime = t_vector(peakIndex);
                
                % Calculate settling time (within 2% of final value)
                settlingBand = 0.02 * finalValue;
                upperBound = finalValue + settlingBand;
                lowerBound = finalValue - settlingBand;
                
                for i = length(y):-1:1
                    if y(i) > upperBound || y(i) < lowerBound
                        break;
                    end
                end
                
                if i < length(y)
                    settlingTime = t_vector(i+1);
                    settlingMin = min(y(i+1:end));
                    settlingMax = max(y(i+1:end));
                else
                    settlingTime = t_vector(1);
                    settlingMin = min(y);
                    settlingMax = max(y);
                end
                
                % Calculate overshoot
                if finalValue ~= 0
                    overshoot = 100 * (max(y) - finalValue) / finalValue;
                end
                
                % Calculate undershoot
                if finalValue ~= 0
                    undershoot = 100 * (finalValue - min(y)) / finalValue;
                end
                
            case 'combined'
                % Custom analysis for combined response (setpoint + disturbances)
                % Similar to setpoint but with additional focus on disturbance effects
                finalValue = mean(y(end-min(10, length(y)-1):end));
                
                % Calculate statistics
                [maxVal, maxIdx] = max(y);
                [minVal, minIdx] = min(y);
                peak = maxVal;
                peakTime = t_vector(maxIdx);
                
                % Calculate settling time (time after which response stays within ±2% of final value)
                settlingThreshold = 0.02 * finalValue;
                settled = false;
                for i = length(y):-1:1
                    if abs(y(i) - finalValue) > settlingThreshold
                        settled = true;
                        break;
                    end
                end
                
                if settled && i < length(y)
                    settlingTime = t_vector(i+1);
                else
                    settlingTime = NaN;
                end
                
                % Calculate rise time approximation
                rising = false;
                for i = 1:length(y)
                    if y(i) >= 0.9 * finalValue
                        rising = true;
                        break;
                    end
                end
                
                if rising
                    riseTime = t_vector(i);
                end
                
                % Calculate min/max after settling
                if ~isnan(settlingTime)
                    settlingIdx = find(t_vector >= settlingTime, 1);
                    if ~isempty(settlingIdx)
                        settlingMin = min(y(settlingIdx:end));
                        settlingMax = max(y(settlingIdx:end));
                    end
                end
                
                % Calculate overshoot
                if finalValue > 0
                    overshoot = 100 * max(0, (maxVal - finalValue) / finalValue);
                else
                    overshoot = NaN;
                end
                
                % Calculate undershoot
                if finalValue > 0
                    undershoot = 100 * max(0, (finalValue - minVal) / finalValue);
                else
                    undershoot = NaN;
                end
                
            otherwise
                disp(['Unknown response type: ', responseType]);
                return;
        end
        
        % Format values with proper precision
        riseTime_str = formatNumber(riseTime);
        settlingTime_str = formatNumber(settlingTime);
        settlingMin_str = formatNumber(settlingMin);
        settlingMax_str = formatNumber(settlingMax);
        overshoot_str = [formatNumber(overshoot), ' %']; % Add percentage symbol
        undershoot_str = formatNumber(undershoot);
        peak_str = formatNumber(peak);
        peakTime_str = formatNumber(peakTime);
        transientTime_str = formatNumber(transientTime);
        
        % Create enhanced HTML table with better styling
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { text-align: left; font-weight: bold; width: 60%; }', ...
                'td:last-child { text-align: right; width: 40%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="2">Key Parameters of a Response</th></tr>', ...
                    '<tr><td>Rise time t<sub>r</sub></td><td>', riseTime_str, '</td></tr>', ...
                    '<tr><td>Settling time (2% band) t<sub>s</sub></td><td>', settlingTime_str, '</td></tr>', ...
                    '<tr><td>Settling Minimum</td><td>', settlingMin_str, '</td></tr>', ...
                    '<tr><td>Settling Maximum</td><td>', settlingMax_str, '</td></tr>', ...
                    '<tr><td>Overshoot</td><td>', overshoot_str, '</td></tr>', ...
                    '<tr><td>Undershoot</td><td>', undershoot_str, '</td></tr>', ...
                    '<tr><td>Peak</td><td>', peak_str, '</td></tr>', ...
                    '<tr><td>Peak Time</td><td>', peakTime_str, '</td></tr>'];
        
        % Add transient time only for step response
        if strcmp(responseType, 'step') && ~isnan(transientTime)
            htmlTableStr = [htmlTableStr, ...
                    '<tr><td>Transient Time</td><td>', transientTime_str, '</td></tr>'];
        end
        
        % Complete the table HTML
        htmlTableStr = [htmlTableStr, ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLkeyparamTable_3.HTMLSource = htmlTableStr;
    catch ME
        % Handle errors in a more user-friendly way
        disp(['Error in displayTFKeyParams: ', ME.message]);
        
        % Create error table
        errorTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #E74C3C; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 12px; text-align: center; font-size: 14px; }', ...
                '.error { color: #E74C3C; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Response Analysis Error</th></tr>', ...
                    '<tr><td class="error">Could not calculate key parameters</td></tr>', ...
                    '<tr><td>Possible issues: non-standard response shape, insufficient data points, or negative time values</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        app.HTMLkeyparamTable_3.HTMLSource = errorTableStr;
    end
    
    % Helper function to format numbers with appropriate precision
    function str = formatNumber(num)
        if isnan(num)
            str = 'N/A';
        elseif abs(num) > 1000 || abs(num) < 0.01
            % Use scientific notation for very large/small numbers
            str = sprintf('%.4e', num);
        else
            % Use fixed-point notation with appropriate precision
            str = sprintf('%.4f', num);
        end
    end
end
    end %end of private methods

    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    render_functions_table_structure(app)

    % Initialize state space models
    if isprop(app, 'LinearizedModel')
        app.LinearizedModel = [];
    end
    
    if isprop(app, 'StateSpaceModel')
        app.StateSpaceModel = []; 
    end
    
    % Initialize nonlinear model properties
    if isprop(app, 'NonlinearStateVars')
        app.NonlinearStateVars = [];
    end
    
    if isprop(app, 'NonlinearInputVars')
        app.NonlinearInputVars = [];
    end
    
    if isprop(app, 'NonlinearStateEqs')
        app.NonlinearStateEqs = [];
    end
    
    if isprop(app, 'NonlinearOutputEqs')
        app.NonlinearOutputEqs = [];
    end
    
    if isprop(app, 'LinearizationPoint')
        app.LinearizationPoint = [];
    end

    % Initialize other required properties if needed
    if ~isprop(app, 'G')
        app.G = [];
    end
    
    if ~isprop(app, 'K')
        app.K = [];
    end

    
    % Set placeholders for display
    displayPlaceholder(app, 'G', 's')
    displayPlaceholder(app, 'K', 's')
    displayPlaceholder(app, 'r', 't')
    displayPlaceholder(app, 'd_1', 't')
    displayPlaceholder(app, 'd_2', 't')


        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

        build_G(app);

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            build_K(app);
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            build_r(app);
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
    label = 'd_1';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_1, app.d1_num_sections, app.d1_start_time, app.d1_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && ~isempty(app.d1_end_time)
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, label);
        
        % Display the time function with the specific time parameters for d_1
        displayTimeFunction(app, app.d_1, app.d1_num_sections, label, app.d1_start_time, app.d1_end_time);
    end
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
    label = 'd_2';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_2, app.d2_num_sections, app.d2_start_time, app.d2_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && ~isempty(app.d2_end_time)
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, label);
        
        % Display the time function with the specific time parameters for d_2
        displayTimeFunction(app, app.d_2, app.d2_num_sections, label, app.d2_start_time, app.d2_end_time);
    end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
    % Check if G, K, and r(t) are entered - if not, prompt for them
    % If d1 and d2 are empty, they will be set to 0

    % Check if G is entered
    if isempty(app.G)
        build_G(app);
    end
    
    % Check if K is entered
    if isempty(app.K)
        build_K(app);
    end
    
    % Check if r(t) is entered
    if isempty(app.r)
        build_r(app);
    end
    
    % Create time vector if not already defined
    if isempty(app.t)
        % Check if we have time parameters already
        if ~isempty(app.r_num_sections) && ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
            disp('Creating time vector from existing parameters...');
            app.t = create_linear_time_vector(app.r_num_sections);
        else
            disp('No time parameters found. Prompting for time input...');
            app.t = create_linear_time_vector(app.r_num_sections);
        end
    else
        disp('Time vector already defined. Using cached values.');
    end
    
    % Create disturbance time vectors if necessary
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && isempty(app.d1_time_vector)
        if ~isempty(app.d1_num_sections)
            app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, 'd_1');
        end
    end
    
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && isempty(app.d2_time_vector)
        if ~isempty(app.d2_num_sections)
            app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, 'd_2');
        end
    end

    % Check if d1(t) and d2(t) were not entered 
    % If so, set both to zero
    if isempty(app.d_1)
        app.d_1 = @(t) 0 + 0*t;
        app.d1_num_sections = 1;
        label = 'd_1';
        displayTimeFunction(app, app.d_1, app.d1_num_sections, label);
    end    

    if isempty(app.d_2)
        app.d_2 = @(t) 0 + 0*t;
        app.d2_num_sections = 1;
        label = 'd_2';
        displayTimeFunction(app, app.d_2, app.d2_num_sections, label);
    end

    % Calculate transfer functions
    [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

    % Render HTML table and update UI components
    render_html_table(app);
            
    app.PZButton.Visible = 'on';
    app.HtmlTable.Visible = 'on';
    app.HTMLPZ.Visible = 'on';
    app.SaveButton.Visible = 'on';
    app.ButtonGroup.Visible = 'on';
    app.ButtonGroup_2.Visible = 'on';
    app.nyquist_UIAxes2.Visible = "on";
    app.Bandwidth_UIAxes.Visible = "on";
    app.HTMLkeyparamTable_2.Visible = "on";
    app.HTMLNyquistInfo.Visible = "on";
    app.HTMLkeyparamTable.Visible = "on";
    app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel.Visible = "on";
    app.KPButton_2.Visible = "on";
    app.HTMLkeyparamTable_3.Visible = "on";
    app.Response_UIAxes_2.Visible = "on";

    % Perform various analyses
    plotnyquist(app);
    app.char_eq = get_characteristic_equation(app, app.G, app.K);
    controlloopstable(app);
    plot_TS_bandwidth(app, app.T, app.S);
    plot_bode_margins(app, app.L);
    calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);
    calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);
    PZchange(app, app.PZButton.SelectedObject);
    KPButton_2SelectionChanged(app, app.KPButton_2.SelectedObject);

    %display massage, when analysis is finished:
    msgbox('The analysis is completed! View the results!', 'Analysis Done', 'help');

            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
    % BUTTONGROUPSELECTIONCHANGED
    % --------------------------
    % Enhanced callback function triggered when a button in ButtonGroup is selected.
    % Performs validation before calculating and displaying the response.
    %
    % Parameters:
    % - app: Application handle with necessary components
    % - event: Event data structure containing information about the selection change
    
    try
        % Get the selected button
        selectedButton = app.ButtonGroup.SelectedObject;
        
        % Validate that a button is actually selected
        if isempty(selectedButton)
            disp('No button selected in ButtonGroup');
            return;
        end
        
        % Check if we have valid systems to analyze based on the selected button
        systemValid = true;
        warningMsg = '';
        
        % First, let's print some debug information to console
        disp('Checking for required transfer functions:');
        disp(['Has T property: ', num2str(isprop(app, 'T'))]);
        disp(['Has L property: ', num2str(isprop(app, 'L'))]);
        disp(['Has G property: ', num2str(isprop(app, 'G'))]);
        disp(['Has K property: ', num2str(isprop(app, 'K'))]);
        
        if selectedButton == app.StepButton || selectedButton == app.SetpointButton
            % For step response and setpoint, we only need T
            if ~isprop(app, 'T') || (isprop(app, 'T') && isempty(app.T))
                systemValid = false;
                warningMsg = 'Transfer function T is not defined. Please define it first.';
            end
        elseif selectedButton == app.r_d12Button
            % For setpoint and disturbances, we need L, G, and K
            if ~isprop(app, 'L') || (isprop(app, 'L') && isempty(app.L))
                systemValid = false;
                warningMsg = 'Open-loop transfer function L is not defined. Please define it first.';
            elseif ~isprop(app, 'G') || (isprop(app, 'G') && isempty(app.G))
                systemValid = false;
                warningMsg = 'Plant transfer function G is not defined. Please define it first.';
            elseif ~isprop(app, 'K') || (isprop(app, 'K') && isempty(app.K))
                systemValid = false;
                warningMsg = 'Controller transfer function K is not defined. Please define it first.';
            end
        end
        
        if ~systemValid
            % Format warning message with HTML
            htmlWarningMsg = [...
                '<html><head><style>', ...
                    '.warning { color: #E74C3C; font-weight: bold; text-align: center; font-family: Arial, sans-serif; }', ...
                '</style></head><body>', ...
                    '<p class="warning">', warningMsg, '</p>', ...
                '</body></html>'];
            
            % If we have an HTML component for messages, use it
            if isprop(app, 'HTMLMessageComponent') && ~isempty(app.HTMLMessageComponent)
                app.HTMLMessageComponent.HTMLSource = htmlWarningMsg;
            else
                % Otherwise just print to console
                disp(warningMsg);
            end
            return;
        end
        
        % Provide visual feedback that calculation is in progress
        % (if we have a status label component)
        if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel)
            originalText = app.StatusLabel.Text;
            app.StatusLabel.Text = 'Calculating response...';
            app.StatusLabel.FontColor = [0.0, 0.4, 0.7]; % Blue
            drawnow; % Update the display immediately
        end
        
        % Update UI to reflect current selection (optional)
        % This could be used to enable/disable other UI elements based on selection
        updateUIForSelection(app, selectedButton);
        
        % Calculate and display the response
        calculateAndDisplayResponse(app, selectedButton);
        
        % Reset status label if it exists
        if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel)
            app.StatusLabel.Text = 'Response calculated successfully';
            app.StatusLabel.FontColor = [0.0, 0.6, 0.0]; % Green color
            % Optionally reset after a delay
            timerObj = timer('StartDelay', 2, 'TimerFcn', @(~,~) resetStatusLabel(app, originalText));
            start(timerObj);
        end
        
    catch ME
        % Handle any errors that might occur
        disp(['Error in ButtonGroupSelectionChanged: ', ME.message]);
        
        % Update status label if it exists
        if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel)
            app.StatusLabel.Text = 'Error calculating response';
            app.StatusLabel.FontColor = [0.8, 0.0, 0.0]; % Red color
        end
    end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
    
            calculateAndDisplayErrorandOutput(app, selectedButton);

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveAppVariables(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    % LOADBUTTONPUSHED - Callback for the Load button
    %
    % This improved function loads app variables from a file and properly
    % displays all function representations, with enhanced handling of
    % piecewise function rendering.
     
    if loadAppVariables(app)
        % Display transfer functions
        if ~isempty(app.G)
            displayTransferFunctionInApp(app, app.G, 'G');
        else
            displayPlaceholder(app, 'G', 's');
        end
        
        if ~isempty(app.K)
            displayTransferFunctionInApp(app, app.K, 'K');
        else
            displayPlaceholder(app, 'K', 's');
        end
        
        % Display time functions using LaTeX strings if available
        % For reference signal r(t)
        if ~isempty(app.r)
            if isfield(app, 'r_latex_string') && ~isempty(app.r_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.r_latex_string, 'r');
                disp('Displayed r(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating r(t) LaTeX representation');
                displayTimeFunction(app, app.r, app.r_num_sections, 'r', app.r_start_time, app.r_end_time);
            end
        else
            displayPlaceholder(app, 'r', 't');
        end
        
        % For disturbance signal d_1(t)
        if ~isempty(app.d_1)
            if isfield(app, 'd1_latex_string') && ~isempty(app.d1_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.d1_latex_string, 'd_1');
                disp('Displayed d_1(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating d_1(t) LaTeX representation');
                displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1', app.d1_start_time, app.d1_end_time);
            end
        else
            displayPlaceholder(app, 'd_1', 't');
        end
        
        % For disturbance signal d_2(t)
        if ~isempty(app.d_2)
            if isfield(app, 'd2_latex_string') && ~isempty(app.d2_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.d2_latex_string, 'd_2');
                disp('Displayed d_2(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating d_2(t) LaTeX representation');
                displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2', app.d2_start_time, app.d2_end_time);
            end
        else
            displayPlaceholder(app, 'd_2', 't');
        end
        
        % Inform the user of successful loading
        msgbox('Successfully loaded all variables!', 'Load Successful', 'help');
    else
        % Optional: Inform the user that no file was selected
        uialert(app.UIFigure, 'No file selected!', 'Note');
    end
        end

        % Selection changed function: PZButton
        function PZButtonSelectionChanged(app, event)
            selectedButton = app.PZButton.SelectedObject;

            PZchange(app, selectedButton)
            
        end

        % Button pushed function: RunSensitivityButton
        function RunSensitivityButtonPushed(app, event)
            runSensitivityAnalysis(app);
        end

        % Button pushed function: RunMonteCarloButton
        function RunMonteCarloButtonPushed(app, event)
            runMonteCarloSimulation(app);
        end

        % Value changed function: KpDistDropdown
        function KpDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Kp');
            
        end

        % Value changed function: KdDistDropdown
        function KdDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Kd');
            
        end

        % Value changed function: KiDistDropdown
        function KiDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Ki');
            
        end

        % Button pushed function: CreateLinearStateSpaceButton
        function CreateLinearStateSpaceButtonPushed(app, event)
    try

        app.MIMOInputAndOutputSelectionLabel.Visible = 'off';
        app.InputIndexLabel.Visible = 'off';
        app.InputIndexInput.Visible = 'off';
        app.OutputIndexLabel.Visible = 'off';
        app.OutputIndexInput.Visible = 'off';
 
        % Update UI with processing status
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Processing state-space matrices...</div></html>';
        drawnow;
        
        % Get matrix values from the text inputs with validation
        A_str = app.LinearAInput.Value;
        B_str = app.LinearBInput.Value;
        C_str = app.LinearCInput.Value;
        D_str = app.LinearDInput.Value;
        
        % Validate inputs are not empty
        if isempty(A_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('A matrix must be provided.');
            return;
        end
        
        if isempty(B_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('B matrix must be provided.');
            return;
        end
        
        if isempty(C_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('C matrix must be provided.');
            return;
        end
        
        if isempty(D_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('D matrix must be provided.');
            return;
        end
        
        % Convert to matrices with detailed error handling
        try
            A = str2matrix(A_str);
            if isempty(A)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid A matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in A matrix: ' ME.message]);
            return;
        end
        
        try
            B = str2matrix(B_str);
            if isempty(B)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid B matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in B matrix: ' ME.message]);
            return;
        end
        
        try
            C = str2matrix(C_str);
            if isempty(C)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid C matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in C matrix: ' ME.message]);
            return;
        end
        
        try
            D = str2matrix(D_str);
            if isempty(D)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid D matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in D matrix: ' ME.message]);
            return;
        end
        
        % Validate matrix dimensions with clear error messages
        [n_A, m_A] = size(A);
        [n_B, m_B] = size(B);
        [n_C, m_C] = size(C);
        [n_D, m_D] = size(D);
        
        if n_A ~= m_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['A matrix must be square. Current dimensions: ' num2str(n_A) 'x' num2str(m_A)]);
            return;
        end
        
        if n_B ~= n_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['B matrix rows (' num2str(n_B) ') must match A matrix dimension (' num2str(n_A) ').']);
            return;
        end
        
        if m_C ~= n_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['C matrix columns (' num2str(m_C) ') must match A matrix dimension (' num2str(n_A) ').']);
            return;
        end
        
        if n_D ~= n_C || m_D ~= m_B
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['D matrix dimensions (' num2str(n_D) 'x' num2str(m_D) ') must match C rows (' num2str(n_C) ') x B columns (' num2str(m_B) ').']);
            return;
        end
        
        % Check for NaN or Inf values
        if any(isnan(A(:))) || any(isinf(A(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('A matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(B(:))) || any(isinf(B(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('B matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(C(:))) || any(isinf(C(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('C matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(D(:))) || any(isinf(D(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('D matrix contains NaN or Inf values.');
            return;
        end
        
        % Create state-space model
        try
            ss_model = ss(A, B, C, D);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating state-space model: ' ME.message]);
            return;
        end
        
        % Store the model
        app.StateSpaceModel = ss_model;
        app.IsNonlinearModel = false;
        app.LinearizedModel = ss_model; % Same for linear system
        
        % Verify the model was stored correctly
        if isempty(app.LinearizedModel) || ~isa(app.LinearizedModel, 'ss')
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Failed to store state-space model. Please try again.');
            return;
        end
        
        % Update system information display
        updateStateSpaceInfo(app, ss_model, false);
        
        % Add success message
        successHTML = '<html><div style="color: green; text-align: center; margin-top: 20px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successHTML = [successHTML, 'Linear state-space model created successfully!'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'System dimensions: ', num2str(n_A), ' states, ', num2str(m_B), ' inputs, ', num2str(n_C), ' outputs'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'You can now analyze this model or convert it to a transfer function G(s).'];
        successHTML = [successHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, successHTML];
        
    catch ME
        % Enhanced error handling with context
        errorMsg = ['Error creating state-space model: ' ME.message];
        disp(errorMsg);
        disp(getReport(ME, 'extended'));
        
        % Create error HTML with troubleshooting tips
        errorHTML = '<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">';
        errorHTML = [errorHTML, 'Error creating state-space model:<br>'];
        errorHTML = [errorHTML, ME.message];
        errorHTML = [errorHTML, '<br><br>'];
        errorHTML = [errorHTML, 'Troubleshooting tips:<br>'];
        errorHTML = [errorHTML, '• Ensure all matrices have correct dimensions<br>'];
        errorHTML = [errorHTML, '• Use spaces to separate elements in a row<br>'];
        errorHTML = [errorHTML, '• Use semicolons (;) to separate rows<br>'];
        errorHTML = [errorHTML, '• Example: "1 2; 3 4" creates a 2×2 matrix<br>'];
        errorHTML = [errorHTML, '• Check for typos and invalid characters'];
        errorHTML = [errorHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = errorHTML;
    end
        end

        % Button pushed function: LinearHelpButton
        function LinearHelpButtonPushed(app, event)
              % Callback function for the Linear Help Button
    msgbox({
        'Matrix Format Help:', ...
        '', ...
        '1. Enter matrices as space-separated values', ...
        '   Example: "1 2 3" for a row vector', ...
        '', ...
        '2. Use semicolons to separate rows', ...
        '   Example: "1 2; 3 4" for a 2x2 matrix', ...
        '', ...
        '3. For A matrix: nxn matrix where n is the number of states', ...
        '   Example for 2-state system: "-1 0.5; 0 -2"', ...
        '', ...
        '4. For B matrix: nxm matrix where n is the number of states', ...
        '   and m is the number of inputs', ...
        '   Example for 2-state, 1-input system: "1; 0"', ...
        '', ...
        '5. For C matrix: pxn matrix where p is the number of outputs', ...
        '   and n is the number of states', ...
        '   Example for 1-output, 2-state system: "1 0"', ...
        '', ...
        '6. For D matrix: pxm matrix where p is the number of outputs', ...
        '   and m is the number of inputs', ...
        '   Example for 1-output, 1-input system: "0"'
    }, 'Matrix Format Help', 'help');
        end

        % Button pushed function: CreateNonlinearStateSpaceButton
        function CreateNonlinearStateSpaceButtonPushed(app, event)
 % CREATENONLINEARSTATESPACEBUTTONPUSHED - Creates and linearizes a nonlinear state-space model
    %
    % This enhanced function creates and linearizes a nonlinear state-space model with
    % improved error handling, user feedback, and input validation.
    
    try
        % First check if Symbolic Math Toolbox is available
        if ~license('test', 'Symbolic_Toolbox')
            errorMsg = 'Symbolic Math Toolbox is required for nonlinear modeling but is not available.';
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            return;
        end
        
        % Update status to show progress
        progressMsg = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Creating nonlinear model...</div></html>';
        app.StateSpaceInfoHTML.HTMLSource = progressMsg;
        drawnow; % Force display update
        
        % Get symbolic variables and equations
        state_vars_str = app.NonlinearStateVarsInput.Value;
        input_vars_str = app.NonlinearInputVarsInput.Value;
        state_eqs_str = app.NonlinearStateEqInput.Value;
        output_eqs_str = app.NonlinearOutputEqInput.Value;
        linearization_point_str = app.LinearizationPointInput.Value;
        
        % Enhanced input validation
        if isempty(state_vars_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('State variables must be provided.');
            return;
        end
        
        if isempty(input_vars_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Input variables must be provided.');
            return;
        end
        
        if isempty(state_eqs_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('State equations must be provided.');
            return;
        end
        
        if isempty(output_eqs_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Output equations must be provided.');
            return;
        end
        
        % Parse variables and equations with improved error handling
        try
            % Parse state variables
            state_vars = parseVariableList(state_vars_str);
            if isempty(state_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid state variables. Please enter comma-separated list of valid variable names.');
                return;
            end
            
            % Parse input variables
            input_vars = parseVariableList(input_vars_str);
            if isempty(input_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid input variables. Please enter comma-separated list of valid variable names.');
                return;
            end
            
            % Parse state equations
            state_eqs = parseEquationList(state_eqs_str);
            if isempty(state_eqs) || length(state_eqs) ~= length(state_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid state equations. Number of equations (' num2str(length(state_eqs)) ') must match number of state variables (' num2str(length(state_vars)) ').']);
                return;
            end
            
            % Parse output equations
            output_eqs = parseEquationList(output_eqs_str);
            if isempty(output_eqs)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid output equations. Please enter semicolon-separated equations.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing inputs: ' ME.message]);
            return;
        end
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Creating symbolic variables...</div></html>';
        drawnow;
        
        % Try to create symbolic variables
        try
            % Create a test symbolic variable to verify toolbox
            syms test_var
            
            % Verify variable names are valid
            for i = 1:length(state_vars)
                if ~isvarname(state_vars{i})
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid state variable name: ' state_vars{i}]);
                    return;
                end
            end
            
            for i = 1:length(input_vars)
                if ~isvarname(input_vars{i})
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid input variable name: ' input_vars{i}]);
                    return;
                end
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating symbolic variables: ' ME.message]);
            return;
        end
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Converting equations to symbolic form...</div></html>';
        drawnow;
        
        % Test converting first state equation to ensure syntax is valid
        try
            % Create the syms statement for all variables
            syms_cmd = ['syms ' strjoin(state_vars, ' ') ' ' strjoin(input_vars, ' ') ' real'];
            eval(syms_cmd);
            
            % Test the first state equation with str2sym
            if ~isempty(state_eqs) && ~isempty(state_vars)
                test_eq = str2sym(state_eqs{1});
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in state equation syntax: ' ME.message]);
            return;
        end
        
        % Test converting first output equation to ensure syntax is valid
        try
            if ~isempty(output_eqs)
                test_out_eq = str2sym(output_eqs{1});
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in output equation syntax: ' ME.message]);
            return;
        end
        
        % Parse linearization point
        linearization_point = containers.Map();
        
        if isempty(linearization_point_str)
            % Use origin as default linearization point
            app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Using default linearization point at origin...</div></html>';
            drawnow;
            
            for i = 1:length(state_vars)
                linearization_point(state_vars{i}) = 0;
            end
            
            for i = 1:length(input_vars)
                linearization_point(input_vars{i}) = 0;
            end
        else
            try
                app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Parsing linearization point...</div></html>';
                drawnow;
                
                % Split by commas
                point_items = strsplit(linearization_point_str, ',');
                
                for i = 1:length(point_items)
                    item = strtrim(point_items{i});
                    if isempty(item)
                        continue;
                    end
                    
                    % Check for equals sign
                    if ~contains(item, '=')
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid linearization point format: ' item]);
                        return;
                    end
                    
                    % Split at equals sign
                    parts = strsplit(item, '=');
                    if length(parts) ~= 2
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid linearization point format: ' item]);
                        return;
                    end
                    
                    var_name = strtrim(parts{1});
                    var_value_str = strtrim(parts{2});
                    
                    % Validate variable name
                    isStateVar = any(strcmp(var_name, state_vars));
                    isInputVar = any(strcmp(var_name, input_vars));
                    
                    if ~isStateVar && ~isInputVar
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Unknown variable in linearization point: ' var_name]);
                        return;
                    end
                    
                    % Convert to numeric value
                    try
                        var_value = str2double(var_value_str);
                        if isnan(var_value)
                            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid numeric value: ' var_value_str]);
                            return;
                        end
                    catch
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing value for ' var_name]);
                        return;
                    end
                    
                    linearization_point(var_name) = var_value;
                end
                
                % Check if all variables have values
                missing_vars = {};
                for i = 1:length(state_vars)
                    if ~isKey(linearization_point, state_vars{i})
                        missing_vars{end+1} = state_vars{i};
                    end
                end
                
                for i = 1:length(input_vars)
                    if ~isKey(linearization_point, input_vars{i})
                        missing_vars{end+1} = input_vars{i};
                    end
                end
                
                if ~isempty(missing_vars)
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Missing linearization point values for: ' strjoin(missing_vars, ', ')]);
                    return;
                end
            catch ME
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing linearization point: ' ME.message]);
                return;
            end
        end
        
        % Store nonlinear model parameters
        app.NonlinearStateVars = state_vars;
        app.NonlinearInputVars = input_vars;
        app.NonlinearStateEqs = state_eqs;
        app.NonlinearOutputEqs = output_eqs;
        app.LinearizationPoint = linearization_point;
        app.IsNonlinearModel = true;
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Linearizing system...</div></html>';
        drawnow;
        
        % Call linearization function with better error handling
        try
            [A, B, C, D] = linearizeSystem(app, state_vars, input_vars, state_eqs, output_eqs, linearization_point);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Linearization failed: ' ME.message]);
            return;
        end
        
        % Create linearized state-space model
        try
            linearized_model = ss(A, B, C, D);
            app.LinearizedModel = linearized_model;
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating state-space model: ' ME.message]);
            return;
        end
        
        % Update system information display
        updateStateSpaceInfo(app, linearized_model, app.IsNonlinearModel);
        
        % Add success message
        successHTML = '<html><div style="color: green; text-align: center; margin-top: 20px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successHTML = [successHTML, 'Nonlinear state-space model created and linearized successfully!'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'You can now analyze this model or convert it to a transfer function G(s).'];
        successHTML = [successHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, successHTML];
        
    catch ME
        % Enhanced error handling with context details
        disp('*** ERROR IN NONLINEAR MODEL CREATION ***');
        disp(['Error message: ', ME.message]);
        disp('Stack trace:');
        disp(getReport(ME, 'extended'));
        
        % Create html error message with troubleshooting tips
        errorHTML = '<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">';
        errorHTML = [errorHTML, 'Error creating nonlinear model:<br>'];
        errorHTML = [errorHTML, ME.message];
        errorHTML = [errorHTML, '<br><br>'];
        errorHTML = [errorHTML, 'Troubleshooting tips:<br>'];
        
        % Add context-specific tips based on the error
        if contains(lower(ME.message), {'symbolic', 'symbol', 'sym'})
            errorHTML = [errorHTML, '• Make sure Symbolic Math Toolbox is installed<br>'];
            errorHTML = [errorHTML, '• Check variable names for valid MATLAB syntax<br>'];
        elseif contains(lower(ME.message), {'variable', 'undefined'})
            errorHTML = [errorHTML, '• Use valid MATLAB variable names (no spaces or special characters)<br>'];
            errorHTML = [errorHTML, '• Check for typographical errors in variable references<br>'];
        elseif contains(lower(ME.message), {'equation', 'parse', 'syntax'})
            errorHTML = [errorHTML, '• Use standard MATLAB operators (+, -, *, /, ^)<br>'];
            errorHTML = [errorHTML, '• Check equation syntax and balance of parentheses<br>'];
        elseif contains(lower(ME.message), 'lineariz')
            errorHTML = [errorHTML, '• Check linearization point format (var=value)<br>'];
            errorHTML = [errorHTML, '• Ensure all variables have valid linearization values<br>'];
        else
            errorHTML = [errorHTML, '• Check all inputs for valid MATLAB syntax<br>'];
            errorHTML = [errorHTML, '• Ensure system is properly defined (states, inputs, equations)<br>'];
        end
        
        errorHTML = [errorHTML, '• Try a simpler model first to verify functionality'];
        errorHTML = [errorHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = errorHTML;
    end
        end

        % Button pushed function: NonlinearHelpButton
        function NonlinearHelpButtonPushed(app, event)
              % Callback function for the Nonlinear Help Button
    msgbox({
        'Nonlinear Model Format Help:', ...
        '', ...
        '1. State Variables: List each state variable, separated by commas', ...
        '   Example: "x1, x2, x3"', ...
        '', ...
        '2. Input Variables: List each input variable, separated by commas', ...
        '   Example: "u1, u2" or just "u" for single input', ...
        '', ...
        '3. State Equations: Enter each dx/dt equation, separated by semicolons', ...
        '   Example for a 2-state system:', ...
        '   "-x1 + x2^2; -x2 - sin(x1) + u"', ...
        '', ...
        '4. Output Equations: Enter each output equation, separated by semicolons', ...
        '   Example: "x1 + x2; x1*x2" for two outputs', ...
        '', ...
        '5. Linearization Point: Enter the linearization point as comma-separated', ...
        '   variable assignments', ...
        '   Example: "x1=0, x2=1, u=0"', ...
        '   Leave empty to linearize around the origin', ...
        '', ...
        '6. Supported Functions:', ...
        '   - Basic operators: +, -, *, /, ^', ...
        '   - Trigonometric: sin, cos, tan', ...
        '   - Exponential/Logarithmic: exp, log, log10', ...
        '   - Hyperbolic: sinh, cosh, tanh', ...
        '   - Others: sqrt, abs'
    }, 'Nonlinear Model Format Help', 'help');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
    % RUNANALYSISBUTTONPUSHED - Callback for the Run Analysis Button
    
    try
        % Check for model existence
        if ~isprop(app, 'LinearizedModel') || isempty(app.LinearizedModel)
            % Try to recover using StateSpaceModel
            if isprop(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
                app.LinearizedModel = app.StateSpaceModel;
                updateStatusHTML(app, 'Warning: Using main state space model for analysis.', 'warning');
            else
                error('No state-space model available. Please create a linear or nonlinear model first.');
            end
        end
        
        % Get analysis type and parameters
        analysisType = app.AnalysisTypeDropdown.Value;
        paramStr = 't=0:0.1:100';
        
        % Reset visualization area to a consistent state
        resetStateSpaceAxes(app);
        
        % Update status to show analysis in progress - REPLACE, not append
        updateStatusHTML(app, ['Running ' analysisType ' analysis...'], 'info');
        drawnow; % Force UI update
        
        % Perform the selected analysis
        switch analysisType
            case 'Eigenvalues'
                disp('Performing eigenvalue analysis...');
                performEigenvalueAnalysis(app);
                
            case 'Phase Portrait'
                disp('Performing phase portrait analysis...');
                performPhasePortrait(app);
                
            case 'Controllability'
                disp('Performing controllability analysis...');
                performControllabilityAnalysis(app);
                
            case 'Observability'
                disp('Performing observability analysis...');
                performObservabilityAnalysis(app);
                
            otherwise
                error('Unknown analysis type: %s', analysisType);
        end
        
        % Update status with success message - REPLACE, not append
        updateStatusHTML(app, [analysisType ' analysis completed successfully.'], 'success');
        
    catch ME
        % Error handling with stack trace
        disp('*** ERROR IN RUN ANALYSIS ***');
        disp(['Error message: ', ME.message]);
        disp('Stack trace:');
        disp(getReport(ME, 'extended'));
        
        % Reset axes to consistent state
        resetStateSpaceAxes(app);
        
        % Display user-friendly error message - REPLACE, not append
        updateStatusHTML(app, ['Error performing analysis: ' ME.message], 'error');
        
        % Create error visualization
        text(app.StateSpaceAxes, 0.5, 0.6, 'Analysis Error', ...
            'HorizontalAlignment', 'center', 'FontSize', 14, 'FontWeight', 'bold', 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.5, ME.message, ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.4, 'Troubleshooting: Check if model exists and is valid', ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', [0.6 0.6 0.6], ...
            'Units', 'normalized');
    end
        end

        % Button pushed function: SaveStateSpaceButton
        function SaveStateSpaceButtonPushed(app, event)
    % Callback function for the Save Model Button
    try
        % Check if a model exists - improved validation
        hasModel = false;
        
        % First check if LinearizedModel exists
        if isprop(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
            hasModel = true;
        % Then check if StateSpaceModel exists and use it if available
        elseif isprop(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
            app.LinearizedModel = app.StateSpaceModel;
            hasModel = true;
        end
        
        % If no model found, check if we have a nonlinear model
        if ~hasModel && isprop(app, 'IsNonlinearModel') && app.IsNonlinearModel && ...
           isprop(app, 'NonlinearStateVars') && ~isempty(app.NonlinearStateVars)
            hasModel = true;
        end
        
        % If still no model, show error
        if ~hasModel
            error('No state-space model available to save. Please create a model first.');
        end
        
        % Create a structure to save
        saveStruct = struct();
        
        if isprop(app, 'IsNonlinearModel') && app.IsNonlinearModel && ...
           isprop(app, 'NonlinearStateVars') && ~isempty(app.NonlinearStateVars)
            % For nonlinear model, save the symbolic representation
            saveStruct.IsNonlinearModel = true;
            saveStruct.NonlinearStateVars = app.NonlinearStateVars;
            saveStruct.NonlinearInputVars = app.NonlinearInputVars;
            saveStruct.NonlinearStateEqs = app.NonlinearStateEqs;
            saveStruct.NonlinearOutputEqs = app.NonlinearOutputEqs;
            
            % Save linearization point if available
            if isprop(app, 'LinearizationPoint') && ~isempty(app.LinearizationPoint)
                saveStruct.LinearizationPoint = app.LinearizationPoint;
            else
                % Create a default linearization point at origin
                saveStruct.LinearizationPoint = containers.Map();
                for i = 1:length(app.NonlinearStateVars)
                    saveStruct.LinearizationPoint(app.NonlinearStateVars{i}) = 0;
                end
                for i = 1:length(app.NonlinearInputVars)
                    saveStruct.LinearizationPoint(app.NonlinearInputVars{i}) = 0;
                end
            end
        else
            % For linear model, save the matrices
            if isprop(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
                saveStruct.IsNonlinearModel = false;
                saveStruct.A = app.LinearizedModel.A;
                saveStruct.B = app.LinearizedModel.B;
                saveStruct.C = app.LinearizedModel.C;
                saveStruct.D = app.LinearizedModel.D;
                saveStruct.Ts = app.LinearizedModel.Ts;
            elseif isprop(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
                saveStruct.IsNonlinearModel = false;
                saveStruct.A = app.StateSpaceModel.A;
                saveStruct.B = app.StateSpaceModel.B;
                saveStruct.C = app.StateSpaceModel.C;
                saveStruct.D = app.StateSpaceModel.D;
                saveStruct.Ts = app.StateSpaceModel.Ts;
            else
                error('Cannot save: No valid state-space matrices found.');
            end
        end
        
        % Also save the linearized model if available
        if isprop(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
            saveStruct.LinearizedA = app.LinearizedModel.A;
            saveStruct.LinearizedB = app.LinearizedModel.B;
            saveStruct.LinearizedC = app.LinearizedModel.C;
            saveStruct.LinearizedD = app.LinearizedModel.D;
            saveStruct.LinearizedTs = app.LinearizedModel.Ts;
        end
        
        % Prompt user for filename
        [file, path] = uiputfile('*.mat', 'Save State-Space Model');
        
        if isequal(file, 0) || isequal(path, 0)
            % User cancelled
            return;
        end
        
        % Combine path and filename
        fullPath = fullfile(path, file);
        
        % Save the structure
        save(fullPath, '-struct', 'saveStruct');
        
        % Show success message
        successMsg = ['<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">', ...
                     'State-space model successfully saved to:<br>', ...
                     fullPath, ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors with more details
        disp(['Save model error: ' ME.message]);
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                   'Error saving state-space model:<br>', ...
                   ME.message, ...
                   '<br><br>Troubleshooting tips:<br>', ...
                   '• Make sure you have created a model first<br>', ...
                   '• Check that the model matrices are valid<br>', ...
                   '• Try creating a new model if this error persists', ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Button pushed function: LoadStateSpaceButton
        function LoadStateSpaceButtonPushed(app, event)
   % Callback function for the Load Model Button
    try
        % Prompt user for file
        [file, path] = uigetfile('*.mat', 'Load State-Space Model');
        
        if isequal(file, 0) || isequal(path, 0)
            % User cancelled
            return;
        end
        
        % Combine path and filename
        fullPath = fullfile(path, file);
        
        % Load the saved structure
        loadedData = load(fullPath);
        
        % Check type of model
        if isfield(loadedData, 'IsNonlinearModel') && loadedData.IsNonlinearModel
            % Nonlinear model
            app.IsNonlinearModel = true;
            
            % Restore nonlinear model parameters
            app.NonlinearStateVars = loadedData.NonlinearStateVars;
            app.NonlinearInputVars = loadedData.NonlinearInputVars;
            app.NonlinearStateEqs = loadedData.NonlinearStateEqs;
            app.NonlinearOutputEqs = loadedData.NonlinearOutputEqs;
            app.LinearizationPoint = loadedData.LinearizationPoint;
            
            % Update nonlinear model inputs
            app.NonlinearStateVarsInput.Value = strjoin(app.NonlinearStateVars, ', ');
            app.NonlinearInputVarsInput.Value = strjoin(app.NonlinearInputVars, ', ');
            
            % Convert symbolic state equations back to string
            stateEqsStr = '';
            for i = 1:length(app.NonlinearStateEqs)
                if i > 1
                    stateEqsStr = [stateEqsStr, '; '];
                end
                stateEqsStr = [stateEqsStr, char(app.NonlinearStateEqs{i})];
            end
            app.NonlinearStateEqInput.Value = stateEqsStr;
            
            % Convert symbolic output equations back to string
            outputEqsStr = '';
            for i = 1:length(app.NonlinearOutputEqs)
                if i > 1
                    outputEqsStr = [outputEqsStr, '; '];
                end
                outputEqsStr = [outputEqsStr, char(app.NonlinearOutputEqs{i})];
            end
            app.NonlinearOutputEqInput.Value = outputEqsStr;
            
            % Convert linearization point to string
            linearizationPointStr = '';
            keys = app.LinearizationPoint.keys;
            for i = 1:length(keys)
                if i > 1
                    linearizationPointStr = [linearizationPointStr, ', '];
                end
                key = keys{i};
                value = app.LinearizationPoint(key);
                linearizationPointStr = [linearizationPointStr, key, '=', num2str(value)];
            end
            app.LinearizationPointInput.Value = linearizationPointStr;
            
            % Set the Nonlinear Model tab as active
            app.StateSpaceTypeTabGroup.SelectedTab = app.NonlinearModelTab;
            
        else
            % Linear model
            app.IsNonlinearModel = false;
            
            % Restore matrices to inputs
            app.LinearAInput.Value = matrix2str(loadedData.A);
            app.LinearBInput.Value = matrix2str(loadedData.B);
            app.LinearCInput.Value = matrix2str(loadedData.C);
            app.LinearDInput.Value = matrix2str(loadedData.D);
            
            % Set the Linear Model tab as active
            app.StateSpaceTypeTabGroup.SelectedTab = app.LinearModelTab;
        end
        
        % Restore linearized model
        if isfield(loadedData, 'LinearizedA')
            app.LinearizedModel = ss(loadedData.LinearizedA, loadedData.LinearizedB, ...
                                     loadedData.LinearizedC, loadedData.LinearizedD, ...
                                     loadedData.LinearizedTs);
        elseif ~app.IsNonlinearModel
            % For linear models without saved linearized model
            app.LinearizedModel = ss(loadedData.A, loadedData.B, loadedData.C, loadedData.D, loadedData.Ts);
        end
        
        % Update system information display
        updateStateSpaceInfo(app, app.LinearizedModel, app.IsNonlinearModel);
        
        % Show success message
        successMsg = ['<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'State-space model successfully loaded from:<br>', ...
                     fullPath, ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error loading state-space model:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Button pushed function: ClearStateSpaceButton
        function ClearStateSpaceButtonPushed(app, event)
    % Callback function for the Clear Button
    try
        % Clear stored models
        if isfield(app, 'StateSpaceModel')
            app.StateSpaceModel = [];
        end
        
        if isfield(app, 'LinearizedModel')
            app.LinearizedModel = [];
        end
        
        app.IsNonlinearModel = false;
        
        % Clear nonlinear model fields
        if isfield(app, 'NonlinearStateVars')
            app.NonlinearStateVars = [];
        end
        
        if isfield(app, 'NonlinearInputVars')
            app.NonlinearInputVars = [];
        end
        
        if isfield(app, 'NonlinearStateEqs')
            app.NonlinearStateEqs = [];
        end
        
        if isfield(app, 'NonlinearOutputEqs')
            app.NonlinearOutputEqs = [];
        end
        
        if isfield(app, 'LinearizationPoint')
            app.LinearizationPoint = [];
        end
        
        % Reset UI elements
        app.StateSpaceInfoHTML.HTMLSource = '<html><body><p style="font-family: Arial; text-align: center; margin-top: 50px; color: #666;">System information will be displayed here.</p></body></html>';
        
        % Clear axes
        cla(app.StateSpaceAxes);
        title(app.StateSpaceAxes, 'System Visualization');
        xlabel(app.StateSpaceAxes, 'X-Axis');
        ylabel(app.StateSpaceAxes, 'Y-Axis');
        
        % Show success message
        successMsg = ['<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'State-space model and visualization cleared.', ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error clearing state-space model:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Value changed function: AnalysisTypeDropdown
        function AnalysisTypeDropdownValueChanged(app, event)
               % Update UI based on selected analysis type
               updateAnalysisDisplay(app, app.AnalysisTypeDropdown.Value);
            
        end

        % Button down function: StateSpaceTab
        function StateSpaceTabSelected(app, event)
             % Called when State Space tab is selected
            if isfield(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
                updateStateSpaceInfo(app, app.LinearizedModel, app.IsNonlinearModel);
            end
        end

        % Selection changed function: KPButton_2
        function KPButton_2SelectionChanged(app, event)
    % KPButton_2SelectionChanged
    % --------------------------
    % Callback function for KPButton_2 radio button group
    % Displays the step response of the selected system and its key parameters
    % Automatically adjusts the view to focus on the response curve
    %
    % Parameters:
    % - app: Application handle containing the UI components and system models
    % - event: Event data that contains the selected button information
    
    % Get the selected button
    selectedButton = app.KPButton_2.SelectedObject;
    
    % Clear the current plot
    cla(app.Response_UIAxes_2);
    
    % Set default simulation time if not defined
    if ~isfield(app, 'simulationTime') || isempty(app.simulationTime)
        app.simulationTime = 10;
    end
    
    % Generate time vector for step response
    t = linspace(0, app.simulationTime, 1000);
    
    % Determine which system to display based on selected button
    switch selectedButton
        case app.SsButton_2
            % S(s) - Sensitivity function
            systemName = 'Sensitivity S(s)';
            sys = app.S;
            
        case app.TsButton_2
            % T(s) - Complementary sensitivity function
            systemName = 'Complementary Sensitivity T(s)';
            sys = app.T;
            
        case app.LsButton_2
            % L(s) - Loop gain
            systemName = 'Loop Gain L(s)';
            sys = app.L;
            
        case app.GsButton_2
            % G(s) - Plant
            systemName = 'Plant G(s)';
            sys = app.G;
            
        case app.KsButton_2
            % K(s) - Controller
            systemName = 'Controller K(s)';
            sys = app.K;
            
        case app.GSsButton_2
            % GS(s) - Plant with sensitivity
            systemName = 'Plant with Sensitivity GS(s)';
            sys = app.G * app.S;
            
        case app.KSsButton_2
            % KS(s) - Controller with sensitivity
            systemName = 'Controller with Sensitivity KS(s)';
            sys = app.K * app.S;
            
        otherwise
            % Default case - should not occur
            warning('No valid button selected in KPButton_2');
            return;
    end
    
    try
        % Calculate step response for the selected system
        [y, t] = step(sys, t);
        
        % If the response is a matrix (MIMO system), use the first I/O pair
        if size(y, 2) > 1
            y = y(:, 1);
        end
        
        % Plot the step response
        plot(app.Response_UIAxes_2, t, y);
        
        % Configure the plot appearance
        grid(app.Response_UIAxes_2, 'on');
        title(app.Response_UIAxes_2, ['Step Response of ', systemName]);
        xlabel(app.Response_UIAxes_2, 'Time (s)');
        ylabel(app.Response_UIAxes_2, 'Amplitude');
        
        % Auto-adjust the view to focus on the response curve
        autoAdjustAxesView(app.Response_UIAxes_2, t, y);
        
        % Call displayTFKeyParams to show analysis in HTMLkeyparamTable_3
        displayTFKeyParams(app, y, t, 'step');
        
    catch ME
        % Handle errors and display a message on the plot
        cla(app.Response_UIAxes_2);
        text(app.Response_UIAxes_2, 0.5, 0.5, ...
            ['Error calculating step response: ', ME.message], ...
            'HorizontalAlignment', 'center', ...
            'FontSize', 10, 'Color', 'red');
        
        % Create a simple error HTML table
        errorTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #E74C3C; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 12px; text-align: center; font-size: 14px; }', ...
                '.error { color: #E74C3C; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Response Analysis Error</th></tr>', ...
                    '<tr><td class="error">Could not calculate step response</td></tr>', ...
                    '<tr><td>', strrep(ME.message, '<', '&lt;'), '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        app.HTMLkeyparamTable_3.HTMLSource = errorTableStr;
    end
            
        end

        % Button pushed function: ConvertToTransferFunctionButton_2
        function ConvertToTransferFunctionButton_2Pushed(app, event)
     try
        % Check if MIMO system and make UI elements visible if needed
        [~, n_inputs] = size(app.LinearizedModel.B);
        [n_outputs, ~] = size(app.LinearizedModel.C);
        
        % For MIMO systems, make sure the UI elements are visible but hide the update button
        if n_inputs > 1 || n_outputs > 1
            % Explicitly make all MIMO-related UI elements visible
            if isprop(app, 'MIMOInputAndOutputSelectionLabel')
                app.MIMOInputAndOutputSelectionLabel.Visible = 'on';
            end
            if isprop(app, 'InputIndexLabel')
                app.InputIndexLabel.Visible = 'on';
            end
            if isprop(app, 'InputIndexInput')
                app.InputIndexInput.Visible = 'on';
                app.InputIndexInput.Limits = [1, n_inputs];
                
            end
            if isprop(app, 'OutputIndexLabel')
                app.OutputIndexLabel.Visible = 'on';
            end
            if isprop(app, 'OutputIndexInput')
                app.OutputIndexInput.Visible = 'on';
                app.OutputIndexInput.Limits = [1, n_outputs];
                
            end
           
        end
        % First check if model exists
        if ~isprop(app, 'LinearizedModel') || isempty(app.LinearizedModel)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('No state-space model available. Please create a model first.');
            return;
        end
        
        % Get selected input and output indices
        inputIdx = app.InputIndexInput.Value;
        outputIdx = app.OutputIndexInput.Value;
        
        % Validate indices
        [~, n_inputs] = size(app.LinearizedModel.B);
        [n_outputs, ~] = size(app.LinearizedModel.C);
        
        if inputIdx > n_inputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Input index ' num2str(inputIdx) ' exceeds the number of inputs (' num2str(n_inputs) ').']);
            return;
        end
        
        if outputIdx > n_outputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Output index ' num2str(outputIdx) ' exceeds the number of outputs (' num2str(n_outputs) ').']);
            return;
        end
        
        % Store current HTML content
        currentHTML = app.StateSpaceInfoHTML.HTMLSource;
        
        % Update status
        app.StateSpaceInfoHTML.HTMLSource = [currentHTML, '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Converting to transfer function...</div></html>'];
        drawnow;
        
        % Hide MIMO selection controls initially
        if isprop(app, 'MIMOPanel')
            showMIMOControls(app, false);
        end
        
        % Check if we have a controller
        if ~isprop(app, 'StateFeedbackGain') || isempty(app.StateFeedbackGain)
            % No controller, just convert to transfer function
            [app.G, success, errorMsg] = convertSS2TF(app, app.LinearizedModel, inputIdx, outputIdx);
            if ~success
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Conversion error: ' errorMsg]);
                return;
            end
            
            % Set K to unity
            app.K = tf(1);
            
            % Display the transfer functions
            displayTransferFunctionInApp(app, app.G, 'G');
            displayTransferFunctionInApp(app, app.K, 'K');
            
            % Create success message
            successMsg = '<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
            successMsg = [successMsg, 'Converted to transfer function successfully!<br><br>'];
            
            % Add model information
            if app.LinearizedModel.Ts > 0
                successMsg = [successMsg, 'Discrete-time transfer function (Ts = ', num2str(app.LinearizedModel.Ts), ' s)<br>'];
            else
                successMsg = [successMsg, 'Continuous-time transfer function<br>'];
            end
            
            % Add channel information
            successMsg = [successMsg, '<br>Channel: Input ' num2str(inputIdx) ' to Output ' num2str(outputIdx) '<br>'];
            
            successMsg = [successMsg, '<br>Note: No controller available, K(s) = 1<br>'];
            successMsg = [successMsg, '<br>You can now analyze the system using the Analyze button.<br>'];
            successMsg = [successMsg, '</div></html>'];
            
            app.StateSpaceInfoHTML.HTMLSource = [currentHTML, successMsg];
            
            % Highlight the Analyze button
            app.AnalyzeButton.BackgroundColor = [0.4 0.8 0.4];  % Green highlight
        else
            % Convert with controller
            try
                [app.G, app.K, stable] = convertSingleChannelWithController(app, app.LinearizedModel, app.StateFeedbackGain, inputIdx, outputIdx);
                
                % Display the transfer functions
                displayTransferFunctionInApp(app, app.G, 'G');
                displayTransferFunctionInApp(app, app.K, 'K');
                
                % Create success message
                successMsg = '<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
                successMsg = [successMsg, 'Converted to transfer function with controller!<br><br>'];
                
                % Add model information
                if app.LinearizedModel.Ts > 0
                    successMsg = [successMsg, 'Discrete-time transfer function (Ts = ', num2str(app.LinearizedModel.Ts), ' s)<br>'];
                else
                    successMsg = [successMsg, 'Continuous-time transfer function<br>'];
                end
                
                % Add channel information
                successMsg = [successMsg, '<br>Channel: Input ' num2str(inputIdx) ' to Output ' num2str(outputIdx) '<br>'];
                
                % Add stability information
                if stable
                    successMsg = [successMsg, '<span style="color: green; font-weight: bold;">The closed-loop system is stable.</span><br>'];
                else
                    successMsg = [successMsg, '<span style="color: red; font-weight: bold;">Warning: The closed-loop system is unstable!</span><br>'];
                end
                
                % Add explanation
                successMsg = [successMsg, '<br>Note: G(s) represents the plant for the selected channel.<br>'];
                successMsg = [successMsg, 'K(s) represents the controller for this channel.<br>'];
                
                if n_inputs > 1 || n_outputs > 1
                    successMsg = [successMsg, 'You can select different input/output channels using the controls above.<br>'];
                end
                
                successMsg = [successMsg, '<br>You can now analyze the control system using the Analyze button.<br>'];
                successMsg = [successMsg, '</div></html>'];
                
                app.StateSpaceInfoHTML.HTMLSource = [currentHTML, successMsg];
                
                % Highlight the Analyze button
                app.AnalyzeButton.BackgroundColor = [0.4 0.8 0.4];  % Green highlight
            catch ME
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error converting to transfer function: ' ME.message]);
                disp(['Conversion error: ' ME.message]);
                disp(['Stack trace: ' getReport(ME, 'extended')]);
            end
        end
    catch ME
        % Handle errors
        disp(['Error in conversion: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                   'Error converting to transfer function:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.FunctionsHTML = uihtml(app.Panel);
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlLoopTab = uitab(app.TabGroup);
            app.ControlLoopTab.Title = 'Control Loop';
            app.Image = uiimage(app.ControlLoopTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standardregelkreis.png');
            app.controlloopstabilityLabel = uilabel(app.ControlLoopTab);
            app.controlloopstabilityLabel.Text = '';
            app.HtmlTable = uihtml(app.ControlLoopTab);
            app.HtmlTable.Visible = 'off';
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.Response_UIAxes = uiaxes(app.ResponseTab);
            title(app.Response_UIAxes, 'Title')
            xlabel(app.Response_UIAxes, 'X')
            ylabel(app.Response_UIAxes, 'Y')
            zlabel(app.Response_UIAxes, 'Z')
            app.Response_UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.ResponseTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.StepButton = uiradiobutton(app.ButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SetpointButton = uiradiobutton(app.ButtonGroup);
            app.SetpointButton.Text = 'Setpoint';
            app.r_d12Button = uiradiobutton(app.ButtonGroup);
            app.r_d12Button.Text = 'Setpoint and Disturbances';
            app.HTMLkeyparamTable = uihtml(app.ResponseTab);
            app.HTMLkeyparamTable.Visible = 'off';
            app.StationaryValuesTab = uitab(app.TabGroup);
            app.StationaryValuesTab.Title = 'Stationary Values';
            app.Controllanderrorplo = uiaxes(app.StationaryValuesTab);
            title(app.Controllanderrorplo, 'Title')
            xlabel(app.Controllanderrorplo, 'X')
            ylabel(app.Controllanderrorplo, 'Y')
            zlabel(app.Controllanderrorplo, 'Z')
            app.Controllanderrorplo.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.StationaryValuesTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.SetButton = uiradiobutton(app.ButtonGroup_2);
            app.SetButton.Text = 'Setpoint';
            app.SetButton.Value = true;
            app.OutputdisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.OutputdisturbanceButton.Text = 'Outputdisturbance';
            app.SetpointandDisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.SetpointandDisturbanceButton.Text = 'Setpoint and Outputdisturbance';
            app.JumpLabel = uilabel(app.StationaryValuesTab);
            app.JumpLabel.Visible = 'off';
            app.JumpLabel.Text = '';
            app.HTMLkeyparamTable_2 = uihtml(app.StationaryValuesTab);
            app.HTMLkeyparamTable_2.Visible = 'off';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space ';
            app.StateSpaceTab.ButtonDownFcn = createCallbackFcn(app, @StateSpaceTabSelected, true);
            app.StateSpaceTypeTabGroup = uitabgroup(app.StateSpaceTab);
            app.LinearModelTab = uitab(app.StateSpaceTypeTabGroup);
            app.LinearModelTab.Title = 'Linear Model';
            app.LinearALabel = uilabel(app.LinearModelTab);
            app.LinearALabel.Text = 'A (State Matrix):';
            app.LinearAInput = uitextarea(app.LinearModelTab);
            app.LinearAInput.Value = {'-1 0;0 -2'};
            app.LinearBLabel = uilabel(app.LinearModelTab);
            app.LinearBLabel.Text = 'B (Input Matrix):';
            app.LinearBInput = uitextarea(app.LinearModelTab);
            app.LinearBInput.Value = {'1; 0'};
            app.LinearCLabel = uilabel(app.LinearModelTab);
            app.LinearCLabel.Text = 'C (Output Matrix):';
            app.LinearCInput = uitextarea(app.LinearModelTab);
            app.LinearCInput.Value = {'1 1'};
            app.LinearDLabel = uilabel(app.LinearModelTab);
            app.LinearDLabel.Text = 'D (Feedthrough Matrix):';
            app.LinearDInput = uitextarea(app.LinearModelTab);
            app.LinearDInput.Value = {'0'};
            app.CreateLinearStateSpaceButton = uibutton(app.LinearModelTab, 'push');
            app.CreateLinearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLinearStateSpaceButtonPushed, true);
            app.CreateLinearStateSpaceButton.Text = 'Create State Space Model';
            app.LinearHelpButton = uibutton(app.LinearModelTab, 'push');
            app.LinearHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LinearHelpButtonPushed, true);
            app.LinearHelpButton.Text = 'Format Help';
            app.NonlinearModelTab = uitab(app.StateSpaceTypeTabGroup);
            app.NonlinearModelTab.Title = 'Nonlinear Model';
            app.NonlinearStateVarsLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearStateVarsLabel.Text = 'State Variables (comma separated):';
            app.NonlinearStateVarsInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearStateVarsInput.Value = 'x1, x2';
            app.NonlinearInputVarsLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearInputVarsLabel.Text = 'Input Variables (comma separated):';
            app.NonlinearInputVarsInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearInputVarsInput.Value = 'u';
            app.NonlinearOutputEqLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearOutputEqLabel.Text = 'Output Equations:';
            app.NonlinearOutputEqInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearOutputEqInput.Value = 'x1 + x2';
            app.NonlinearOutputEqLabel_2 = uilabel(app.NonlinearModelTab);
            app.NonlinearOutputEqLabel_2.Text = 'State Equations (dx/dt):';
            app.NonlinearStateEqInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearStateEqInput.Value = '-x1 + x2^2; -x2 - sin(x1) + u';
            app.LinearizationPointLabel = uilabel(app.NonlinearModelTab);
            app.LinearizationPointLabel.Text = 'Linearization Point (optional):';
            app.LinearizationPointInput = uieditfield(app.NonlinearModelTab, 'text');
            app.LinearizationPointInput.Value = 'x1=0, x2=0, u=0';
            app.CreateNonlinearStateSpaceButton = uibutton(app.NonlinearModelTab, 'push');
            app.CreateNonlinearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNonlinearStateSpaceButtonPushed, true);
            app.CreateNonlinearStateSpaceButton.Text = 'Create & Linearize';
            app.NonlinearHelpButton = uibutton(app.NonlinearModelTab, 'push');
            app.NonlinearHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NonlinearHelpButtonPushed, true);
            app.NonlinearHelpButton.Text = 'Format Help';
            app.StateSpaceInfoPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceInfoPanel.Title = 'System Information';
            app.StateSpaceInfoHTML = uihtml(app.StateSpaceInfoPanel);
            app.StateSpaceInfoHTML.HTMLSource = '<html><body><p style="font-family: Arial; text-align: center; margin-top: 50px; color: #666;">System information will be displayed here.</p></body></html>';
            app.StateSpaceVisualizationPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceVisualizationPanel.Title = 'Visualization';
            app.StateSpaceAxes = uiaxes(app.StateSpaceVisualizationPanel);
            title(app.StateSpaceAxes, 'System Visualization')
            xlabel(app.StateSpaceAxes, 'X-Axis')
            ylabel(app.StateSpaceAxes, 'Y-Axis')
            zlabel(app.StateSpaceAxes, 'Z')
            app.StateSpaceAxes.Visible = 'off';
            app.StateSpaceVisHTML = uihtml(app.StateSpaceVisualizationPanel);
            app.StateSpaceVisHTML.Visible = 'off';
            app.RunAnalysisButton = uibutton(app.StateSpaceVisualizationPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.AnalysisTypeLabel = uilabel(app.StateSpaceVisualizationPanel);
            app.AnalysisTypeLabel.Text = 'Analysis Type:';
            app.AnalysisTypeDropdown = uidropdown(app.StateSpaceVisualizationPanel);
            app.AnalysisTypeDropdown.Items = {'Eigenvalues', 'Phase Portrait', 'Controllability', 'Observability'};
            app.AnalysisTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropdownValueChanged, true);
            app.AnalysisTypeDropdown.Value = 'Eigenvalues';
            app.StateSpaceControlPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceControlPanel.Title = 'Controller Synthesis and Conversion';
            app.InputIndexInput = uieditfield(app.StateSpaceControlPanel, 'numeric');
            app.InputIndexInput.Visible = 'off';
            app.InputIndexInput.Value = 1;
            app.InputIndexLabel = uilabel(app.StateSpaceControlPanel);
            app.InputIndexLabel.Visible = 'off';
            app.InputIndexLabel.Text = 'Input:';
            app.OutputIndexLabel = uilabel(app.StateSpaceControlPanel);
            app.OutputIndexLabel.Visible = 'off';
            app.OutputIndexLabel.Text = 'Output:';
            app.OutputIndexInput = uieditfield(app.StateSpaceControlPanel, 'numeric');
            app.OutputIndexInput.Visible = 'off';
            app.OutputIndexInput.Value = 1;
            app.SaveStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.SaveStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateSpaceButtonPushed, true);
            app.SaveStateSpaceButton.Text = 'Save Model';
            app.LoadStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.LoadStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateSpaceButtonPushed, true);
            app.LoadStateSpaceButton.Text = 'Load Model';
            app.ClearStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.ClearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStateSpaceButtonPushed, true);
            app.ClearStateSpaceButton.Text = 'Clear';
            app.MIMOInputAndOutputSelectionLabel = uilabel(app.StateSpaceControlPanel);
            app.MIMOInputAndOutputSelectionLabel.Visible = 'off';
            app.MIMOInputAndOutputSelectionLabel.Text = 'MIMO Input and Output Selection:';
            app.ConvertToTransferFunctionButton_2 = uibutton(app.StateSpaceControlPanel, 'push');
            app.ConvertToTransferFunctionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConvertToTransferFunctionButton_2Pushed, true);
            app.ConvertToTransferFunctionButton_2.Text = 'Convert to TF';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.ZeroPoleTab = uitab(app.TabGroup_2);
            app.ZeroPoleTab.Title = 'Zero/Pole';
            app.pzplotUIAxes = uiaxes(app.ZeroPoleTab);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.PZButton = uibuttongroup(app.ZeroPoleTab);
            app.PZButton.SelectionChangedFcn = createCallbackFcn(app, @PZButtonSelectionChanged, true);
            app.PZButton.Visible = 'off';
            app.GsButton = uiradiobutton(app.PZButton);
            app.GsButton.Text = 'G(s)';
            app.GsButton.Value = true;
            app.KsButton = uiradiobutton(app.PZButton);
            app.KsButton.Text = 'K(s)';
            app.LsButton = uiradiobutton(app.PZButton);
            app.LsButton.Text = 'L(s)';
            app.GSsButton = uiradiobutton(app.PZButton);
            app.GSsButton.Text = 'GS(s)';
            app.KSsButton = uiradiobutton(app.PZButton);
            app.KSsButton.Text = 'KS(s)';
            app.TsButton = uiradiobutton(app.PZButton);
            app.TsButton.Text = 'T(s)';
            app.SsButton = uiradiobutton(app.PZButton);
            app.SsButton.Text = 'S(s)';
            app.HTMLPZ = uihtml(app.ZeroPoleTab);
            app.HTMLPZ.Visible = 'off';
            app.NyquistTab = uitab(app.TabGroup_2);
            app.NyquistTab.Title = 'Nyquist';
            app.nyquist_UIAxes2 = uiaxes(app.NyquistTab);
            title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point')
            xlabel(app.nyquist_UIAxes2, 'Real Axis')
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.HTMLNyquistInfo = uihtml(app.NyquistTab);
            app.HTMLNyquistInfo.Visible = 'off';
            app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel = uilabel(app.NyquistTab);
            app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel.Visible = 'off';
            app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel.Text = 'Move slider to visualize Nyquist diagram at different frequencies';
            app.BodeTab = uitab(app.TabGroup_2);
            app.BodeTab.Title = 'Bode';
            app.Bode_UIAxes = uiaxes(app.BodeTab);
            title(app.Bode_UIAxes, 'Bode Plot of Lss')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.BandwidthTab = uitab(app.TabGroup_2);
            app.BandwidthTab.Title = 'Bandwidth';
            app.Bandwidth_UIAxes = uiaxes(app.BandwidthTab);
            title(app.Bandwidth_UIAxes, 'Magnitude Response with Bandwidth')
            xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)')
            ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            app.StepResponsesTab = uitab(app.TabGroup_2);
            app.StepResponsesTab.Title = 'Step Responses';
            app.Response_UIAxes_2 = uiaxes(app.StepResponsesTab);
            title(app.Response_UIAxes_2, 'Title')
            xlabel(app.Response_UIAxes_2, 'X')
            ylabel(app.Response_UIAxes_2, 'Y')
            zlabel(app.Response_UIAxes_2, 'Z')
            app.Response_UIAxes_2.Visible = 'off';
            app.HTMLkeyparamTable_3 = uihtml(app.StepResponsesTab);
            app.HTMLkeyparamTable_3.Visible = 'off';
            app.KPButton_2 = uibuttongroup(app.StepResponsesTab);
            app.KPButton_2.SelectionChangedFcn = createCallbackFcn(app, @KPButton_2SelectionChanged, true);
            app.KPButton_2.Visible = 'off';
            app.GsButton_2 = uiradiobutton(app.KPButton_2);
            app.GsButton_2.Text = 'G(s)';
            app.GsButton_2.Value = true;
            app.KsButton_2 = uiradiobutton(app.KPButton_2);
            app.KsButton_2.Text = 'K(s)';
            app.LsButton_2 = uiradiobutton(app.KPButton_2);
            app.LsButton_2.Text = 'L(s)';
            app.GSsButton_2 = uiradiobutton(app.KPButton_2);
            app.GSsButton_2.Text = 'GS(s)';
            app.KSsButton_2 = uiradiobutton(app.KPButton_2);
            app.KSsButton_2.Text = 'KS(s)';
            app.TsButton_2 = uiradiobutton(app.KPButton_2);
            app.TsButton_2.Text = 'T(s)';
            app.SsButton_2 = uiradiobutton(app.KPButton_2);
            app.SsButton_2.Text = 'S(s)';
            app.RobustnessTab = uitab(app.TabGroup_2);
            app.RobustnessTab.Title = 'Robustness Analysis';
            app.RobustnessTabGroup = uitabgroup(app.RobustnessTab);
            app.SensitivityTab = uitab(app.RobustnessTabGroup);
            app.SensitivityTab.Title = 'Sensitivity Analysis';
            app.SensitivityUIAxes = uiaxes(app.SensitivityTab);
            title(app.SensitivityUIAxes, 'Parameter Sensitivity Analysis')
            xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)')
            ylabel(app.SensitivityUIAxes, 'Performance Metric')
            zlabel(app.SensitivityUIAxes, 'Z')
            app.SelectParameterLabel = uilabel(app.SensitivityTab);
            app.SelectParameterLabel.Text = 'Select Parameter:';
            app.SensitivityParamDropdown = uidropdown(app.SensitivityTab);
            app.SensitivityParamDropdown.Items = {'Kp (Controller Gain)', 'Ki (Integral Gain)', 'Kd (Derivative Gain)', 'Plant Parameter'};
            app.SensitivityParamDropdown.Value = 'Kp (Controller Gain)';
            app.VariationRangeLabel = uilabel(app.SensitivityTab);
            app.VariationRangeLabel.Text = 'Variation Range (%)';
            app.SensitivityRangeEdit = uieditfield(app.SensitivityTab, 'numeric');
            app.SensitivityRangeEdit.Value = 20;
            app.PerformanceMetricLabel = uilabel(app.SensitivityTab);
            app.PerformanceMetricLabel.Text = 'Performance Metric:';
            app.SensitivityMetricDropdown = uidropdown(app.SensitivityTab);
            app.SensitivityMetricDropdown.Items = {'Settling Time', 'Overshoot', 'Rise Time', 'Bandwidth', 'Phase Margin', 'Gain Margin'};
            app.SensitivityMetricDropdown.Value = 'Settling Time';
            app.RunSensitivityButton = uibutton(app.SensitivityTab, 'push');
            app.RunSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @RunSensitivityButtonPushed, true);
            app.RunSensitivityButton.Text = 'Run Sensitivity Analysis';
            app.SensitivityHTML = uihtml(app.SensitivityTab);
            app.SensitivityHTML.HTMLSource = '<html><body><div style="font-family: Arial; font-size: 12px; padding: 10px;">Sensitivity analysis results will appear here.</div></body></html>';
            app.NumberofPointsLabel = uilabel(app.SensitivityTab);
            app.NumberofPointsLabel.Text = 'Number of Points:';
            app.SensitivityPointsEdit = uieditfield(app.SensitivityTab, 'numeric');
            app.SensitivityPointsEdit.Value = 10;
            app.MonteCarloTab = uitab(app.RobustnessTabGroup);
            app.MonteCarloTab.Title = 'Monte Carlo Simulation';
            app.MonteCarloHistUIAxes = uiaxes(app.MonteCarloTab);
            title(app.MonteCarloHistUIAxes, 'Performance Metric Histogram')
            xlabel(app.MonteCarloHistUIAxes, 'Value')
            ylabel(app.MonteCarloHistUIAxes, 'Frequency')
            zlabel(app.MonteCarloHistUIAxes, 'Z')
            app.MonteCarloResponseUIAxes = uiaxes(app.MonteCarloTab);
            title(app.MonteCarloResponseUIAxes, 'System Responses')
            xlabel(app.MonteCarloResponseUIAxes, 'Time (s)')
            ylabel(app.MonteCarloResponseUIAxes, 'Output')
            zlabel(app.MonteCarloResponseUIAxes, 'Z')
            app.ControllerGainKpLabel = uilabel(app.MonteCarloTab);
            app.ControllerGainKpLabel.Text = 'Controller Gain (Kp):';
            app.DistributionLabel = uilabel(app.MonteCarloTab);
            app.DistributionLabel.Text = 'Distribution:';
            app.KpDistDropdown = uidropdown(app.MonteCarloTab);
            app.KpDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KpDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KpDistDropdownValueChanged, true);
            app.KpDistDropdown.Value = 'Normal';
            app.MeanMinLabel = uilabel(app.MonteCarloTab);
            app.MeanMinLabel.Text = 'Mean/Min:';
            app.KpParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KpParam1Edit.Value = 1;
            app.StdMaxLabel = uilabel(app.MonteCarloTab);
            app.StdMaxLabel.Text = 'Std/Max:';
            app.KpParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KpParam2Edit.Value = 0.1;
            app.IntegralGainKiLabel = uilabel(app.MonteCarloTab);
            app.IntegralGainKiLabel.Text = 'Integral Gain (Ki):';
            app.DistributionLabel_2 = uilabel(app.MonteCarloTab);
            app.DistributionLabel_2.Text = 'Distribution:';
            app.MeanMinLabel_2 = uilabel(app.MonteCarloTab);
            app.MeanMinLabel_2.Text = 'Mean/Min:';
            app.KiDistDropdown = uidropdown(app.MonteCarloTab);
            app.KiDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KiDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KiDistDropdownValueChanged, true);
            app.KiDistDropdown.Value = 'Normal';
            app.KiParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KiParam1Edit.Value = 0.5;
            app.StdMaxLabel_2 = uilabel(app.MonteCarloTab);
            app.StdMaxLabel_2.Text = 'Std/Max:';
            app.KiParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KiParam2Edit.Value = 0.05;
            app.DerivativeGainKdLabel = uilabel(app.MonteCarloTab);
            app.DerivativeGainKdLabel.Text = 'Derivative Gain (Kd):';
            app.DistributionLabel_3 = uilabel(app.MonteCarloTab);
            app.DistributionLabel_3.Text = 'Distribution:';
            app.MeanMinLabel_3 = uilabel(app.MonteCarloTab);
            app.MeanMinLabel_3.Text = 'Mean/Min:';
            app.KdDistDropdown = uidropdown(app.MonteCarloTab);
            app.KdDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KdDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KdDistDropdownValueChanged, true);
            app.KdDistDropdown.Value = 'Normal';
            app.KdParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KdParam1Edit.Value = 0.1;
            app.StdMaxLabel_3 = uilabel(app.MonteCarloTab);
            app.StdMaxLabel_3.Text = 'Std/Max:';
            app.KdParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KdParam2Edit.Value = 0.01;
            app.NumberofSimulationsLabel = uilabel(app.MonteCarloTab);
            app.NumberofSimulationsLabel.Text = 'Number of Simulations:';
            app.NumSimulationsEdit = uieditfield(app.MonteCarloTab, 'numeric');
            app.NumSimulationsEdit.Value = 100;
            app.PerformanceMetricLabel_2 = uilabel(app.MonteCarloTab);
            app.PerformanceMetricLabel_2.Text = 'Performance Metric:';
            app.MonteCarloMetricDropdown = uidropdown(app.MonteCarloTab);
            app.MonteCarloMetricDropdown.Items = {'Settling Time', 'Overshoot', 'Rise Time', 'Correlation Matrix'};
            app.MonteCarloMetricDropdown.Value = 'Settling Time';
            app.RunMonteCarloButton = uibutton(app.MonteCarloTab, 'push');
            app.RunMonteCarloButton.ButtonPushedFcn = createCallbackFcn(app, @RunMonteCarloButtonPushed, true);
            app.RunMonteCarloButton.Text = 'Run Monte Carlo Simulation';
            app.MonteCarloHTML = uihtml(app.MonteCarloTab);
            app.MonteCarloHTML.HTMLSource = '<html><body><div style="font-family: Arial; font-size: 12px; padding: 10px;">Monte Carlo simulation results will appear here.</div></body></html>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises_Kopie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end